classdef TimeFrequencyAnalysis_App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataentryMenu                   matlab.ui.container.Menu
        LoadtimeseriesMenu              matlab.ui.container.Menu
        NewwindowMenu                   matlab.ui.container.Menu
        PlottingMenu                    matlab.ui.container.Menu
        TimeseriesMenu                  matlab.ui.container.Menu
        TFrepresentationonlyMenu        matlab.ui.container.Menu
        TimeaverageonlyMenu             matlab.ui.container.Menu
        TFrepresentationandtimeaverageMenu  matlab.ui.container.Menu
        TSTFRAvMenu                     matlab.ui.container.Menu
        AverageofalltransformsMenu      matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        WTMenu                          matlab.ui.container.Menu
        AllWTMenu                       matlab.ui.container.Menu
        TimeSeriesMenu                  matlab.ui.container.Menu
        ParametersMenu                  matlab.ui.container.Menu
        TimeaveragedMenu                matlab.ui.container.Menu
        SaveallmatMenu                  matlab.ui.container.Menu
        EditField_3                     matlab.ui.control.NumericEditField
        TotalRelativepowerButton        matlab.ui.control.Button
        DeleteintervalsButton           matlab.ui.control.Button
        AverageplotsPanel               matlab.ui.container.Panel
        AvTransform                     matlab.ui.control.UIAxes
        SelectintervalsButton           matlab.ui.control.Button
        IntervalsEditField              matlab.ui.control.NumericEditField
        IntervalsEditFieldLabel         matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        WavelettransformparametersPanel  matlab.ui.container.Panel
        CalculatingtransfomsLabel       matlab.ui.control.Label
        PreprocessingDropDown           matlab.ui.control.DropDown
        PreprocessingDropDownLabel      matlab.ui.control.Label
        WavelettypeDropDown             matlab.ui.control.DropDown
        WavelettypeDropDownLabel        matlab.ui.control.Label
        WindowtypeDropDown              matlab.ui.control.DropDown
        WindowtypeDropDownLabel         matlab.ui.control.Label
        CutedgesDropDown                matlab.ui.control.DropDown
        CutedgesDropDownLabel           matlab.ui.control.Label
        TransformallButton              matlab.ui.control.Button
        TransformsingleButton           matlab.ui.control.StateButton
        TransformtypeSwitch             matlab.ui.control.Switch
        TransformtypeSwitchLabel        matlab.ui.control.Label
        PlottypeSwitch                  matlab.ui.control.Switch
        PlottypeSwitchLabel             matlab.ui.control.Label
        MinfrequencyEditField           matlab.ui.control.NumericEditField
        MinfrequencyEditFieldLabel      matlab.ui.control.Label
        MaxfrequencyEditField           matlab.ui.control.NumericEditField
        MaxfrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyresolutionEditField    matlab.ui.control.NumericEditField
        FrequencyresolutionEditFieldLabel  matlab.ui.control.Label
        StatisticaltestPanel            matlab.ui.container.Panel
        FouriertransformButton          matlab.ui.control.Button
        text54                          matlab.ui.control.Label
        ToEditField_2                   matlab.ui.control.NumericEditField
        ToEditField_2Label              matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        text53                          matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        alpha                           matlab.ui.control.EditField
        text56                          matlab.ui.control.Label
        testtype                        matlab.ui.control.DropDown
        replot                          matlab.ui.control.Button
        avgtype                         matlab.ui.control.DropDown
        text57                          matlab.ui.control.Label
        SignalLabel                     matlab.ui.control.Label
        SignalListBox                   matlab.ui.control.ListBox
        SignalListBoxLabel              matlab.ui.control.Label
        text55                          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FigurecolorschemeDropDown       matlab.ui.control.DropDown
        FigurecolorschemeDropDownLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data % Description
        path % Description
        file % Description
        Fs % Description
        time
        prepro
        WT % Description
        CE % Description
        TransformType % Description
        WaveletType % Description
        FreqRes % Description
        Fmin % Description
        Fmax % Description
        preprocessed % Description
        PlotType % Description
        freq % Description
        freq1 % Description
        Preprocess % Description
        WindowType % Description
        WFT % Description
        signal_number % Description
        A % time averaged signal
        ColVal % Description
        Group1 % Description
        Group2
        P % Description
        TestType % Description
        data1 % Description
        Allpressed % Description
        TestInput % Description
        AvgType % Description
        % Description
        G11 % Description
        G12
        G21
        G22
        A1 % Description
        forier
        f
        wavelet
        AllEverPressed % Description
        LowInt % Description
        HighInt
        Property43 % Description
        lines % Description
        line1
        line2
        Apow % Description
        IntervallsPushed % Description
        ListBoxClicked % Description
        StatTestOn % Description
        FirstGroup
        SeconsGroup
        PVals
        Allpressedlast % Description
        H1
        H2
        H3
        H4% Description
    end

    methods (Access = private)


        %Dmytro Iatsenko's codes below have been slightly modified (by Julian
        %Newman) so as no longer to filter negative frequencies; this involved
        %removing the "set to zero at negative frequencies" command.  Note, however,
        %that the filtering prior to padding (if chosen) still includes negative
        %frequencies.
        %
        %Version 1.01 stable
        %**************************************************************************
        %*************** Windowed (or Short-Time) Fourier Transform ***************
        %**************************************************************************
        %-------------------------------Copyright----------------------------------
        %
        % Author: Dmytro Iatsenko
        % Information about these codes (e.g. links to the Video Instructions),
        % as well as other MatLab programs and many more can be found at
        % http://www.physics.lancs.ac.uk/research/nbmphysics/diats/tfr
        %
        % Related articles:
        % [1] D. Iatsenko, A. Stefanovska and P.V.E. McClintock,
        % "Linear and synchrosqueezed time-frequency representations revisited.
        %  Part I: Overview, standards of use, related issues and algorithms."
        % {preprint:arXiv:1310.7215}
        % [2] D. Iatsenko, A. Stefanovska and P.V.E. McClintock,
        % "Linear and synchrosqueezed time-frequency representations revisited.
        %  Part II: Resolution, reconstruction and concentration."
        % {preprint:arXiv:1310.7274}
        %
        %------------------------------Documentation-------------------------------
        %
        % [WFT,freq,Optional:wopt]=wft(sig,fs,Optional:'PropertyName',PropertyValue)
        % - calculate windowed Fourier transform [WFT] of a signal [sig] sampled at [fs] Hz.
        %
        % INPUT:
        % sig - signal for which to calculate WFT
        % fs  - sampling frequency of the signal
        %
        % Properties: ({{...}} denotes default)
        % ################################ BASIC ##################################
        % 'fmin':value (default = 0)
        %            minimal frequency for which to calculate WFT
        % 'fmax':value (default = fs/2, i.e. the Nyquist frequency)
        %            maximal frequency for which to calculate WFT
        % 'fstep':{{'auto'}}|'auto-NB'|value
        %            frequency step, which determines frequency discretization,
        %            so that the next frequency equals previous one plus [fstep];
        %            when set to 'auto-NB' (e.g. 'auto-20') determines [fstep]
        %            automatically as described in [1], so that it equals
        %            1/NB of the frequency region containing 50% of the
        %            window function; the default 'auto' is equivalent to 'auto-10'
        % 'f0':value (default = 1/fmin)
        %            window resolution parameter, which determines the tradeoff
        %            between the time and frequency resolutions: the higher it is,
        %            the closer in frequency components can be resolved in WFT,
        %            but the slower time-variations, e.g. amplitude/frequency
        %            modulation, can be reliably represented; for the way it is
        %            introduced for each window see Appendix E in [1], while if the
        %            window is user-defined in terms of its function in frequency
        %            and/or time (see 'Window' property), then 'f0' obviously does
        %            not influence anything.
        % 'Padding':{{'predictive'}}|0|'symmetric'|'none'|'periodic'|value|{padleft,padright}
        %            what padding to use when computing transform (for a list of
        %            all paddings and their effects see [1]); when set to some
        %            numeric value, pads with that values; most useful are the
        %            zero-padding, for which boundary errors are well-determined,
        %            and 'predictive' padding (default), for which they are
        %            most reduced, while other choices are not very useful;
        %            alternatively, one can specify it as {padleft,padright}, where
        %            [padleft] and [padright] can be any of those (except 'none'),
        %            e.g. {0,'symmetric'} will pad signal to the left with zeros,
        %            and to the right by symmetric reflection; additionally,
        %            [padleft] and [padright] can be vectors to pad with (if there
        %            needed more values than padleft/padright lengths,
        %            pads additionally with zeros), e.g. if you want to consider
        %            only some part of the signal, from indexes n1 to n2, but do
        %            not introduce additional boundary effects to it, then use:
        %            wft(signal(n1:n2),fs,'Padding',{signal(1:n1-1),signal(n2+1:end)});
        %            the same applies if you can predict signal out of the time-limits
        % 'Plot':{{'off'}} - do not plot anything
        %          'amp'   - plots WFT amplitude (i.e. its absolute value) together
        %                    with line denoting the cone of influence
        %          'amp+'  - additionally shows time-averaged WFT amplitude
        %          'amp++' - additionally shows 95% range of WFT amplitude
        %          'pow','pow+','pow++' - the same but for the WFT power (i.e. its
        %                                 squared modulus)
        %           IMPORTANT: to avoid plotting huge data (in which case it might
        %           be very slow to render and modify the figure, and MatLab can
        %           even crash), the plotted WFT is resampled to have no more than
        %           few data points displayed per pixel (for the current screen
        %           resolution). Unless 'Display' is 'off', it will always notify if
        %           the WFT size exceeds the number of pixels on the plot and the
        %           resamling is therefore performed. Note, that in this case considerable
        %           zooming in of the parts of displayed plots might not show the full
        %           structure of the original WFT. If one wants to investigate the
        %           resultant plot in fine details (and not only see how it looks),
        %           then the original, full WFT might be displayed without resampling
        %           by adding '-wr' to the end of this option, e.g. 'amp-wr' or 'pow++-wr'.
        % 'Display':{{'on'}} - displays all relevant information about progress etc.
        %            'on-'   - more compact display
        %           'notify' - displays only information if something went wrong
        %            'off'   - does not display anything
        % 'CutEdges':{{'off'}}|'on'
        %           determine should WFT coefficients be set to NaNs out of the cone
        %           of influence (see [1]); set it to 'on' if you wish to analyze
        %           only WFT within the cone of influence (which is recommended if
        %           you want to estimate e.g. only the time-averaged quantities).
        % ############################## ADVANCED #################################
        % 'Window':{{'Gaussian'}}|'Hann'|'Blackman'|'Exp'|'Rect'|'Kaiser-a'|{@(xi)fwt(xi),[xi1,xi2],@(t)twf(t),[t1,t2]}
        %            window used in WFT calculation, for a list of all supported
        %            names and their properties see Appendix E in [1]. However, you
        %            can use any window by specifying its frequency domain form,
        %            i.e. window FT (defined by function [fwt], the argument of
        %            which is cyclic frequency) and/or time-domain form [twf] (the
        %            argument is time) together with corresponding full supports;
        %            only [fwt] or [twf] is enough, so if one of them is not known
        %            just put empty field [] for it and its support (but it is
        %            better to specify both [fwt] and [twf] when available). Thus,
        %            the Gaussian window with [f0=1] can be alternatively defined as
        %            {@(xi)exp(-(1/2)*(xi.^2)),[-Inf,Inf],@(t)(1/sqrt(2*pi))*exp(-(1/2)*(t.^2)),[-Inf,Inf]},
        %            while Hann window with [f0=1] -- as {[],[],@(t)(1+cos(2*pi*t/4.4))/2,[-2.2,2.2]}
        % 'Preprocess':{{'on'}}|'off'
        %            perform or not an initial signal preprocessing, which consists
        %            of subtracting 3rd order polynomial fit and then bandpassing
        %            the signal in the band of interest [fmin,fmax], for which WFT
        %            is calculated
        % 'RelTol':value (default = 0.01) (in [1] commonly referred as \epsilon)
        %            relative tolerance (e.g. 0.01 means 1%), which specifies cone
        %            of influence for WFT (i.e. range of WFT coefficients which are
        %            determined up to this accuracy in respect of boundary errors);
        %            determines also the minimal number of values to pad signal
        %            with, so that relative contribution of effects of implicit
        %            periodic signal continuation due to convolution in frequency
        %            domain is smaller [RelTol], see [1] for details.
        %
        %
        % OUTPUT:
        % WFT  - windowed Fourier transform of the signal [sig],
        %        with rows corresponding to frequencies and columns - to time;
        %        represents FNxL matrix, where FN is the number of frequencies
        %        and L is the length of the signal in samples
        % freq - frequencies corresponding to rows of WFT
        % wopt - structure with all parameters of the window and simulation
        %
        %-------------------------------Examples-----------------------------------
        %
        % [WFT,freq]=wft(sig,100,'fmin',0.1,'fmax',10,'f0',2)
        % given a signal [sig] sampled at 100 Hz, returns its WFT [WFT] based on a
        % Gaussian window (default) with [f0=2] and calculated for frequencies from
        % 0.1 to 10 Hz, returned in [freq].
        %
        %-----------------------Additional possibilities---------------------------
        %
        % One can also pass the structure with the properties as a third input
        % argument instead of specifying them by pairs, e.g.
        % opts=struct; opts.f0=2; opts.fmax=12; opts.Padding='predictive';
        % [WFT,freq]=wft(sig,fs,opts);
        % You can also add further parameters in the usual way, e.g.
        % [WFT,freq]=wft(sig,fs,opts,'Display','off');
        % If you add a parameter contained in [opts], it will be ovewritten, e.g.
        % [WFT,freq]=wft(sig,fs,opts,'fmax',10);
        % will change 'fmax' property from 12 specified in [opts.fmax] to 10.
        %
        % When one needs to calculate WFT using the same window and simulation
        % parameters, but for different signals, it is a good idea to do it like
        % [WFT1,freq1,wopt]=wft(sig1,fs1,...(parameters));
        % [WFT2,freq2]=wft(sig2,fs2,wopt); [WFT3,freq3]=wft(sig3,fs3,wopt); ...
        % This will also avoid recalculating window parameters each time, thus
        % slightly speeding up the computations.
        %
        % The same can be done if you calculated WFT of a signal but want to obtain
        % it using some different parameter (e.g. Padding), for example
        % [WFT1,freq1,wopt]=wft(sig,fs,'Padding',0,...(other parameters));
        % [WFT2,freq2]=wft(sig,fs,wopt,'Padding','predictive');
        % Unless you overwrite 'f0', 'Window' or 'RelTol' properties, the window
        % parameters will not be recalculated for the second time.
        %
        %------------------------------Changelog-----------------------------------
        %
        % v1.01:
        % - improved speed of predictive padding
        % - some minor changes (Optimization/Display)
        %
        %--------------------------------------------------------------------------


        function [WFT,freq,varargout] = wft(app, signal,fs,varargin)

            L=length(signal); signal=signal(:);
            if length(fs)>1 || ~isnumeric(fs) || isnan(fs) || ~isfinite(fs) || fs<=0
                error('Sampling frequency should be single positive finite numerical value.');
            end

            %Default parameters
            Window='Gaussian';

            f0=1;
            fmin=0;
            fmax=fs/2;
            fstep='auto';

            PadMode='predictive';
            RelTol=0.01;
            Preprocess='on';

            DispMode='on';
            PlotMode='off';
            CutEdges='off';

            %Update if user defined
            vst=1; recflag=1;
            if nargin>2 && isstruct(varargin{1})
                copt=varargin{1}; vst=2;
                if isfield(copt,'Display'), cvv=copt.Display; if ~isempty(cvv), DispMode=cvv; end, end
                if isfield(copt,'Window'), cvv=copt.Window; if ~isempty(cvv), Window=cvv; end, end
                if isfield(copt,'f0'), cvv=copt.f0; if ~isempty(cvv), f0=cvv; end, end
                if isfield(copt,'fmin'), cvv=copt.fmin; if ~isempty(cvv), fmin=cvv; end, end
                if isfield(copt,'fmax'), cvv=copt.fmax; if ~isempty(cvv), fmax=cvv; end, end
                if isfield(copt,'fstep'), cvv=copt.fstep; if ~isempty(cvv), fstep=cvv; end, end
                if isfield(copt,'Padding'), cvv=copt.Padding; if ~isempty(cvv), PadMode=cvv; end, end
                if isfield(copt,'RelTol'), cvv=copt.RelTol; if ~isempty(cvv), RelTol=cvv; end, end
                if isfield(copt,'Preprocess'), cvv=copt.Preprocess; if ~isempty(cvv), Preprocess=cvv; end, end
                if isfield(copt,'Plot'), cvv=copt.Plot; if ~isempty(cvv), PlotMode=cvv; end, end
                if isfield(copt,'CutEdges'), cvv=copt.CutEdges; if ~isempty(cvv), CutEdges=cvv; end, end
                if isfield(copt,'wp')
                    recflag=0;
                    for vn=vst:2:nargin-2
                        if strcmpi(varargin{vn},'Window') && ~isempty(varargin{vn+1}), recflag=1; end
                        if strcmpi(varargin{vn},'f0') && ~isempty(varargin{vn+1}), recflag=1; end
                        if strcmpi(varargin{vn},'RelTol') && ~isempty(varargin{vn+1}), recflag=1; end
                    end
                    wp=copt.wp;
                end
                if isfield(copt,'fstepsim') && recflag==1, cvv=copt.fstepsim; if ~isempty(cvv), fstep=cvv; end, end
            end

            for vn=vst:2:nargin-3
                if strcmpi(varargin{vn},'Display'), if ~isempty(varargin{vn+1}), DispMode=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Window'), if ~isempty(varargin{vn+1}), Window=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'f0'), if ~isempty(varargin{vn+1}), f0=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'fmin'), if ~isempty(varargin{vn+1}), fmin=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'fmax'), if ~isempty(varargin{vn+1}), fmax=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'fstep'), if ~isempty(varargin{vn+1}), fstep=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Padding'), if ~isempty(varargin{vn+1}), PadMode=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'RelTol'), if ~isempty(varargin{vn+1}), RelTol=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Preprocess'), if ~isempty(varargin{vn+1}), Preprocess=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Plot'), if ~isempty(varargin{vn+1}), PlotMode=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'CutEdges'), if ~isempty(varargin{vn+1}), CutEdges=varargin{vn+1}; end
                else error(['There is no Property ''',varargin{vn},'''']);
                end
            end

            % Check whether the resolution "fr" was left blank; if so, set it to the
            % correct default.
            frIsBlank = true;
            for vn=vst:2:nargin-3
                if strcmpi(varargin{vn},"f0")
                    frIsBlank = false;
                end
            end

            % When this code is called from Python, varargin is a cell
            % array containing one struct. Check if 'f0' is supplied to fix
            % bug where Python version uses default value of 'f0' instead
            % of that supplied from Python.
            if isstruct(varargin{1})
                if isfield(varargin{1}, "f0"), frIsBlank = false; end
            end

            if frIsBlank
                fr = 1; % Default value of 'fr' when left blank.
                f0 = fr / fmin;
            end

            %===========================Window function================================
            %[fwt] and [twf] - window function in frequency and time
            %wp - structure with window parameters containing fields:
            %     xi1,xi2 - window full support in the frequency domain;
            %     ompeak,tpeak - window peak frequency and peak time
            %     t1,t2 - window full support in the time domain;
            %     fwtmax,twfmax - maximum value of abs(fwt) and abs(twf);
            %     C,omg - coefficients needed for reconstruction
            %--------------------------------------------------------------------------
            fwt=[]; twf=[];
            if recflag==1
                wp=struct;
                wp.fwtmax=[]; wp.twfmax=[]; wp.C=[]; wp.omg=[];
                wp.xi1=-Inf; wp.xi2=Inf; wp.ompeak=[];
                wp.t1=-Inf; wp.t2=Inf; wp.tpeak=[];
            end
            if iscell(Window)
                fwt=Window{1};
                if length(Window{2})==2, wp.xi1=Window{2}(1); wp.xi2=Window{2}(2); end
                twf=Window{3};
                if length(Window{4})==2, wp.t1=Window{4}(1); wp.t2=Window{4}(2); end
            elseif strcmpi(Window,'Gaussian')
                fwt=@(xi)exp(-(f0^2/2)*xi.^2);
                twf=@(t)(1/sqrt(2*pi)/f0)*exp(-t.^2/(2*f0^2));
                wp.ompeak=0; wp.C=pi*twf(0); wp.omg=0; wp.tpeak=0;
            elseif strcmpi(Window,'Hann')
                q=4.4*f0;
                twf=@(t)(1+cos(2*pi*t/q))/2; wp.t1=-q/2; wp.t2=q/2;
                fwt=@(xi)(-(2*pi/q)^2)*sin(xi*q/2)./(xi.*(xi.^2-(2*pi/q).^2));
                wp.ompeak=0; wp.C=pi*twf(0); wp.omg=0; wp.tpeak=0;
            elseif strcmpi(Window,'Blackman')
                q=5.6*f0; alpha=0.16;
                twf=@(t)(1+cos(2*pi*t/q))/2-alpha*(1+cos(4*pi*t/q))/2; wp.t1=-q/2; wp.t2=q/2;
                fwt=@(xi)((-(2*pi/q)^2)*sin(xi*q/2)./xi).*(1./(xi.^2-(2*pi/q).^2)-4*alpha./(xi.^2-(4*pi/q).^2));
                wp.ompeak=0; wp.C=pi*twf(0); wp.omg=0; wp.tpeak=0;
            elseif strcmpi(Window,'Exp')
                q=6.5*f0;
                twf=@(t)exp(-abs(t)/q);
                fwt=@(xi)2*q./(1+(q^2)*xi.^2);
                wp.ompeak=0; wp.C=pi*twf(0); wp.omg=0; wp.tpeak=0;
            elseif strcmpi(Window,'Rect')
                q=10*f0;
                twf=@(t)ones(size(t)); wp.t1=-q/2; wp.t2=q/2;
                fwt=@(xi)2*sin(q*xi/2)./xi;
                wp.ompeak=0; wp.C=pi*twf(0); wp.omg=0; wp.tpeak=0;
            elseif ~isempty(strfind(lower(Window),'kaiser'))
                a=3; if length(Window)>6, a=str2double(Window(8:length(Window))); end
                q=3*sqrt(1+abs(a-1/a))*f0;
                B=besseli(0,pi*a);
                twf=@(t)besseli(0,pi*a*sqrt(1-(2*t/q).^2))/B; wp.t1=-q/2; wp.t2=q/2;
                wp.C=pi*twf(0); wp.omg=0; wp.tpeak=0;
            else
                error('Invalid window name');
            end
            %==========================================================================


            %Determine parameters of the window function (and frequency bin width if needed)
            if recflag==1
                if strcmpi(DispMode,'on'), fprintf('Estimating window parameters...\n'); end
                parcalc(RelTol); %calculate/update window parameters
            end
            coib1=ceil(abs(wp.t1e*fs)); coib2=ceil(abs(wp.t2e*fs)); %cone-of-influence edges


            %Produce warning if there are no WFT in the cone-of-influence
            if wp.t2e-wp.t1e>L/fs
                if ~strcmpi(DispMode,'off')
                    fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                    wstr='';
                    wstr=[wstr,sprintf('For used window function parameters and signal time-length there are no WFT coefficients determined with specified accuracy %1.2e.\n',RelTol)];
                    wstr=[wstr,sprintf('Transform is inaccurate under the specified precision. Either allow lower accuracy by increasing ''RelTol'', or consider changing window resolution parameter(s).\n')];
                    if strcmpi(CutEdges,'on')
                        wstr=[wstr,sprintf('Continuing without cone-of-influence.\n')];
                    end
                    fprintf(wstr);
                    fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                end
                if strcmpi(CutEdges,'on'), CutEdges='off'; end
            end


            %Define frequencies
            fstepsim=fstep; wp.fstep=fstep;
            if ~isempty(strfind(fstep,'auto')) %determine frequency step [fstep] if needed
                Nb=10; if length(fstep)>4, Nb=str2double(fstep(6:length(fstep))); end
                wp.fstep=(wp.xi2h-wp.xi1h)/(2*pi*Nb);
                c10=floor(log10(wp.fstep)); fdig=floor(wp.fstep/(10^c10));
                fstep=floor(wp.fstep/(10^c10))*(10^c10);
                if strcmpi(DispMode,'on')
                    fprintf('Optimal frequency bin width was determined to be %f Hz (rounded to %d x 10^%d)\n',wp.fstep,fdig,c10);
                end
            end
            freq=(ceil(fmin/fstep)*fstep:fstep:floor(fmax/fstep)*fstep)'; % frequencies (determined solely by fstep, which by default depends only on window parameters)
            SN=length(freq); % number of frequencies


            %======== Signal preprocessing: detrending, filtering and padding =========
            %[dflag] determines to do detrending and filtering before or after padding
            dflag=0;
            if ~iscell(PadMode)
                if ~ischar(PadMode) && ~isempty(PadMode(PadMode~=0)), dflag=1; end
                if strcmpi(PadMode,'predictive') && fmin<5*fs/L, dflag=1; end
            else
                if ~ischar(PadMode{1}) && ~isempty(PadMode{1}(PadMode{1}~=0)), dflag=1; end
                if ~ischar(PadMode{2}) && ~isempty(PadMode{2}(PadMode{2}~=0)), dflag=1; end
                if strcmpi(PadMode{1},'predictive') && fmin<5*fs/L, dflag=1; end
                if strcmpi(PadMode{2},'predictive') && fmin<5*fs/L, dflag=1; end
            end
            %Detrend (subtract third-order polynomial fit) and filter first for usual padding
            if strcmpi(Preprocess,'on') && dflag==0
                %Detrending
                X=(1:length(signal))'/fs; XM=ones(length(X),4); for pn=1:3, CX=X.^pn; XM(:,pn+1)=(CX-mean(CX))/std(CX); end
                w=warning('off','all'); signal=signal-XM*(pinv(XM)*signal); warning(w);
                %Filtering
                fx=fft(signal,L); % Fourier transform of a signal
                Nq=ceil((L+1)/2); ff=[(0:Nq-1),-fliplr(1:L-Nq)]*fs/L; ff=ff(:); % frequencies in Fourier transform
                fx(abs(ff)<=max([fmin,fs/L]) | abs(ff)>=fmax)=0; % filter signal in a chosen frequency domain
                signal=ifft(fx);
            end
            %Padding
            NL=2^nextpow2(L+coib1(1)+coib2(1));
            if coib1(1)==0 && coib2(1)==0, n1=floor((NL-L)/2); n2=ceil((NL-L)/2);
            else
                n1=floor((NL-L)*coib1(1)/(coib1(1)+coib2(1)));
                n2=ceil((NL-L)*coib2(1)/(coib1(1)+coib2(1)));
            end
            if strcmpi(DispMode,'on')
                if strcmpi(Preprocess,'on')
                    fprintf('Signal preprocessing (detrending, then filtering) and padding (%d values to the left and %d to the right)...\n',n1,n2);
                else
                    fprintf('Padding (%d values to the left and %d to the right)...\n',n1,n2);
                end
            end
            if iscell(PadMode)
                p1=PadMode{1}; p2=PadMode{2};
                %Padding to the left
                if strcmpi(p1,'predictive') || strcmpi(p2,'predictive')
                    if strcmpi(DispMode,'on'), fprintf('Applying predictive padding: '); end
                    dflag=1;
                end
                if ~ischar(p1) && length(p1)>1
                    p1=p1(:); PL1=length(p1);
                    padleft=[zeros(n1-PL1,1);p1(PL1-min([n1,PL1])+1:PL1)];
                elseif strcmpi(p1,'predictive')
                    if strcmpi(DispMode,'on'), fprintf('\b to the left - '); end
                    w=2.^(-(L/fs-(1:L)/fs)/(wp.t2h-wp.t1h));
                    padleft=fcast(app,flipud(signal),fs,n1,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode); padleft=flipud(padleft);
                    if strcmpi(DispMode,'on'), fprintf(';\n'); end
                elseif isnumeric(p1), padleft=p1*ones(n1,1);
                elseif strcmpi(p1,'symmetric'), padleft=[zeros(n1-L,1);flipud(signal(1:min([n1,L])))];
                elseif strcmpi(p1,'periodic'), padleft=[zeros(n1-L,1);signal(L-min([n1,L])+1:L)];
                else error('Bad ''Padding'' property');
                end
                %Padding to the right
                if ~ischar(p2) && length(p2)>1
                    p2=p2(:); PL2=length(p2);
                    padright=[p2(1:min([n2,PL2]));zeros(n2-PL2,1)];
                elseif strcmpi(p2,'predictive')
                    if strcmpi(DispMode,'on'), fprintf('\b to the right - '); end
                    w=2.^(-(L/fs-(1:L)/fs)/(wp.t2h-wp.t1h));
                    padright=fcast(app,signal,fs,n2,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode);
                    if strcmpi(DispMode,'on'), fprintf(';\n'); end
                elseif isnumeric(p2), padright=p2*ones(n2,1);
                elseif strcmpi(p2,'symmetric'), padright=[flipud(signal(L-min([n2,L])+1:L));zeros(n2-L,1)];
                elseif strcmpi(p2,'periodic'), padright=[signal(1:min([n2,L]));zeros(n2-L,1)];
                else error('Bad ''Padding'' property');
                end
            elseif strcmpi(PadMode,'predictive')
                if strcmpi(DispMode,'on'), fprintf('Applying predictive padding: '); end
                w=2.^(-(L/fs-(1:L)/fs)/(wp.t2h-wp.t1h));
                if strcmpi(DispMode,'on'), fprintf('\b to the left - '); end
                padleft=fcast(app,flipud(signal),fs,n1,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode); padleft=flipud(padleft);
                if strcmpi(DispMode,'on'), fprintf('; to the right - '); end
                padright=fcast(app,signal,fs,n2,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode);
                if strcmpi(DispMode,'on'), fprintf(';\n'); end
                dflag=1; %to detrend one more time
            elseif isnumeric(PadMode)
                padleft=PadMode*ones(n1,1); padright=PadMode*ones(n2,1); %padding with predefined values (by default with zeros)
            elseif strcmpi(PadMode,'symmetric'),
                padleft=[zeros(n1-L,1);flipud(signal(1:min([n1,L])))]; padright=[flipud(signal(L-min([n2,L])+1:L));zeros(n2-L,1)]; %symmetric padding
            elseif strcmpi(PadMode,'periodic'),
                padleft=[zeros(n1-L,1);signal(L-min([n1,L])+1:L)]; padright=[signal(1:min([n2,L]));zeros(n2-L,1)]; %periodic padding
            elseif strcmpi(PadMode,'none')
                NL=L; n1=0; n2=0; padleft=[]; padright=[]; %no padding (quite equivalent to periodic padding)
            else
                error('Bad ''Padding'' property');
            end
            signal=[padleft;signal;padright];
            %Detrend (subtract third-order polynomial fit) after padding for special cases
            if strcmpi(Preprocess,'on') && dflag==1
                X=(1:length(signal))'/fs; XM=ones(length(X),4); for pn=1:3, CX=X.^pn; XM(:,pn+1)=(CX-mean(CX))/std(CX); end
                w=warning('off','all'); signal=signal-XM*(pinv(XM)*signal); warning(w);
            end
            %Filtering of the padded signal
            Nq=ceil((NL+1)/2); ff=[(0:Nq-1),-fliplr(1:NL-Nq)]*fs/NL; ff=ff(:); % frequencies in Fourier transform
            fx=fft(signal,NL); % Fourier transform of a signal (DON'T set to zero at negative frequencies)
            if strcmpi(Preprocess,'on')
                fx(ff<=fmin | ff>=fmax)=0; % filter signal in a chosen frequency domain
            end
            %--------------------------------------------------------------------------


            %Windowed Fourier transform by itself
            WFT=zeros(SN,L)*NaN; ouflag=0; if wp.t2e-wp.t1e>L/fs, coib1=0; coib2=0; end
            if ~isempty(strfind(lower(DispMode),'on')), pos=0; fprintf('Calculating Windowed Fourier Transform (%d frequencies from %0.3f to %0.3f): ',SN,freq(1),freq(end)); end
            for sn=1:SN
                freqwf=freq(sn)-ff; %frequencies for the window function
                ii=find(freqwf>wp.xi1/2/pi & freqwf<wp.xi2/2/pi); %take into account only frequencies within the window support
                if ~isempty(fwt)
                    fw=fwt(2*pi*freqwf(ii)); nid=find(isnan(fw) | ~isfinite(fw));
                    if ~isempty(nid) %to avoid NaNs due to numerics, e.g. sin(0)/0
                        fw(nid)=fwt(2*pi*freqwf(ii(nid))+10^(-14));
                        nid=find(isnan(fw) | ~isfinite(fw)); fw(nid)=0;
                        if ~isempty(nid), ouflag=1; ouval=2*pi*freqwf(nid(1)); end
                    end
                else
                    timewf=(1/fs)*[-(1:ceil((NL-1)/2))+1,NL+1-(ceil((NL-1)/2)+1:NL)]';
                    jj=find(timewf>wp.t1 & timewf<wp.t2); tw=zeros(NL,1); %take into account only times within the window support
                    tw(jj)=twf(timewf(jj)).*exp(-1i*2*pi*freq(sn)*timewf(jj)); nid=find(isnan(tw) | ~isfinite(tw));
                    if ~isempty(nid) %to avoid NaNs due to numerics, e.g. sin(0)/0
                        tw(nid)=twf(timewf(nid)+10^(-14));
                        nid=find(isnan(tw) | ~isfinite(tw)); tw(nid)=0;
                        if ~isempty(nid), ouflag=1; ouval=timewf(nid(1)); end
                    end
                    fw=(1/fs)*fft(tw); fw=fw(ii);
                end
                cc=zeros(NL,1); cc(ii)=fx(ii).*fw(:); %convolution in the frequency domain
                out=ifft(cc,NL); % calculate WFT at each time
                WFT(sn,1:L)=out(1+n1:NL-n2);

                if ~isempty(strfind(lower(DispMode),'on')) && floor(100*sn/SN)>floor(100*(sn-1)/SN)
                    cstr=num2str(floor(100*sn/SN)); fprintf([repmat('\b',1,pos),cstr,'%%']); pos=length(cstr)+1;
                end
            end
            if ~isempty(strfind(lower(DispMode),'on')), fprintf('\n'); end
            if ouflag==1
                if ~isempty(fwt)
                    fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                    fprintf('Possibly overflow/underflow (e.g. Inf/Inf): specified frequency-domain form of window function\n');
                    fprintf('returns NaN or Inf, e.g. when its argument is %e. In all such cases it is set to zero.\n',ouval);
                    fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                else
                    fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                    fprintf('Possibly overflow/underflow (e.g. Inf/Inf): specified time-domain form of window function\n');
                    fprintf('returns NaN or Inf, e.g. when its argument is %e. In all such cases it is set to zero.\n',ouval);
                    fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                end
            end

            %Set to NaN all WFT coefficients outside the cone of influence if specified
            if strcmpi(CutEdges,'on'), WFT(:,1:coib1)=NaN; WFT(:,1+L-coib2:L)=NaN; end

            %Plotting WFT if specified
            if ~strcmpi(PlotMode,'off')
                scrsz=get(0,'ScreenSize');
                %figure('Position',[scrsz(3)/4,scrsz(4)/8,scrsz(3)/2,6*scrsz(4)/8]);
                %     axes('Position',[0.15,0.1,0.8,0.5333],'Layer','top','Box','on','FontSize',16);
                %     hold all;

                YY=freq; XX=(0:(L-1))/fs; ZZ=abs(WFT); ZZname='WFT amplitude';
                if ~isempty(strfind(lower(PlotMode),'pow')), ZZ=ZZ.^2; ZZname='WFT power'; end
                MYL=round(scrsz(3)); MXL=round(scrsz(4)); %maximum number of points seen in plots
                if isempty(strfind(lower(PlotMode),'wr')) && (size(ZZ,1)>MYL || size(ZZ,2)>MXL)
                    if strcmpi(DispMode,'on')
                        fprintf('Plotting: WFT contains more data points (%d x %d) than pixels in the plot, so for a\n',size(ZZ,1),size(ZZ,2));
                        fprintf('          better performance its resampled version (%d x %d) will be displayed instead.\n',min([MYL,size(ZZ,1)]),min([MXL,size(ZZ,2)]));
                    end
                    if size(ZZ,1)>MYL, YY=linspace(freq(1),freq(end),MYL); end
                    if size(ZZ,2)>MXL, XX=linspace(0,(L-1)/fs,MXL); end
                    ZZ=aminterp(app,(0:(L-1))/fs,freq,ZZ,XX,YY,'max'); XX=XX(:); YY=YY(:);
                end
                TL=length(XX); FL=length(YY);
                ZZ = transpose(ZZ);
                pc=pcolor(app.UIAxes3, XX,YY,ZZ'); title(ZZname);
                %     colorbar
                %     xlabel('Frequency (Hz)'); ylabel('Time (s)');
                ylim([0,(L-1)/fs]); xlim([freq(1),freq(end)]); set(pc,'EdgeColor','none');

                coib1(coib1==0)=NaN; coib2(coib2==0)=NaN;
                plot([freq(1);freq(end)],[coib1;coib1]/fs,'-k','LineWidth',2); plot( [freq(1);freq(end)],[L-coib2+1;L-coib2+1]/fs,'-k','LineWidth',2);
                coib1(isnan(coib1))=0; coib2(isnan(coib2))=0; if strcmpi(CutEdges,'off'), coib1(:)=0; coib2(:)=0; end

                if ~isempty(strfind(lower(PlotMode),'+'))
                    axes('Position',[0.15,0.7,0.8,0.25],'Layer','top','XLim',[freq(1),freq(end)],'XTickLabel',{},'Box','on','FontSize',16);
                    hold all;
                    ni1=find(~isnan(ZZ(1,:)),1,'first'); ni2=find(~isnan(ZZ(1,:)),1,'last'); mx=mean(ZZ(:,ni1:ni2),2);
                    mline=plot( YY,mx,'-k','LineWidth',2); ylabel({'Time-averaged',ZZname});
                    if max(mx)>0, ylim([0,1.1*max(mx)]); end

                    if ~isempty(strfind(lower(PlotMode),'++'))
                        sZZ=sort(ZZ(:,ni1:ni2),2); ZL=size(sZZ,2);
                        lx=sZZ(:,max([1,round(0.025*ZL)])); ux=sZZ(:,round(0.975*ZL));
                        idnn=find(~isnan(lx)); % not-NaN indices
                        hold all; fill([YY(idnn);flipud(YY(idnn))],[lx(idnn);flipud(ux(idnn))],[1,1,0],'FaceAlpha',0.5);
                        if max(ux)>0, ylim([0,1.1*max(ux)]); end
                        uistack(mline,'top');
                    end
                end
            end

            if nargout>2
                wopt=struct; %simulation parameters
                wopt.wp=wp; %parameters of the window
                wopt.TFRname='WFT'; wopt.fs=fs;
                wopt.Window=Window;
                wopt.f0=f0;
                wopt.fmin=fmin;
                wopt.fmax=fmax;
                wopt.fstep=fstep; wopt.fstepsim=fstepsim;
                wopt.Padding=PadMode; wopt.PadLR={padleft,padright};
                wopt.RelTol=RelTol;
                wopt.Preprocess=Preprocess;
                wopt.Plot=PlotMode;
                wopt.Display=DispMode;
                wopt.CutEdges=CutEdges;

                varargout{1}=wopt;
            end


            %----------------------------------------------------------------------------------------------------------------------
            %======================================================================================================================
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %======================================================================================================================
            %----------------------------------------------------------------------------------------------------------------------
            % Based on window function in time [twf] and frequency [fwt],
            % determines window parameters such as constant [Cg],
            % peaks in time [tpeak] and frequency [ompeak],
            % epsilon-support in frequency [xi1e,xi2e] and in time [t1e,t2e],
            % 50%-support in frequency [xi1h,xi2h] and time [t1h,t2h],
            % time-frequency resolution [tfres] (inverse of multiplication of the latter),
            % and frequency step [fstep] (if 'auto') for specified relative accuracy [racc] (=epsilon);
            % assigns all these values into the window parameters structure [wp].
            function parcalc(racc)
                racc=min(racc,0.5-10^(-10)); %current \epsilon
                ctol=max([racc/1000,10^(-12)]); %parameter of numerical accuracy
                MIC=max([10000,10*L]); %maximum interval count for one-time calculations

                %Times and frequencies for testing (with 4 times higher time duration and sampling frequency than the signal)
                nt=(1/(4*fs))*(-8*L+1:8*L-1)'; nt=nt(nt>wp.t1 & nt<wp.t2);
                nxi=(2*pi*(4*fs)/(16*L-1))*(-8*L+1:8*L-1)'; nxi=nxi(nxi>wp.xi1 & nxi<wp.xi2);

                %==================================================================
                %Determine values for known frequency and/or time-domain forms
                if ~isempty(fwt) %if the frequency-domain form is known
                    wp.fwt=fwt;
                    if isempty(wp.ompeak) %peak frequency
                        ipeak=find(abs(fwt(nxi))==max(abs(fwt(nxi)))); wp.ompeak=mean(nxi(ipeak));
                        wp.ompeak=fminsearch(@(x)-abs(fwt(x)),wp.ompeak,optimset('TolX',10^(-14),'Display','off'));
                    end
                    if isempty(wp.fwtmax)
                        wp.fwtmax=fwt(wp.ompeak);
                        if isnan(wp.fwtmax), wp.fwtmax=fwt(wp.ompeak+10^(-14)); end
                    end
                    if abs(wp.ompeak)>10^(-12) %center around the peak if needed
                        if ~strcmpi(DispMode,'off') %����������������������
                            fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                            fprintf('The Fourier transform (FT) of the specified window function is not centered around its maximum, which occurs at %f.\n',wp.ompeak)
                            fprintf('Therefore, we consider its modified version shifted in frequency so that the window FT amplitude is now peaked at zero.\n');
                            fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                        end
                        fwt=@(xi)fwt(xi+wp.ompeak);
                        if ~isempty(twf)
                            twf=@(t)twf(t).*exp(-1i*wp.ompeak*t);
                        end
                        wp.xi1=wp.xi1-wp.ompeak; wp.xi2=wp.xi2-wp.ompeak;
                        wp.fwt=fwt; wp.ompeak=0;
                    end
                    vfun=@(u)fwt(u); xp=wp.ompeak; lim1=wp.xi1; lim2=wp.xi2;

                    [QQ,wflag,xx,ss]=sqeps(app, vfun,xp,[lim1,lim2],racc,MIC,...
                        [-8*(2*pi*fs),8*(2*pi*fs)]); %�����������������������������
                    wp.xi1e=ss(1,1); wp.xi2e=ss(1,2); wp.xi1h=ss(2,1); wp.xi2h=ss(2,2);
                    if isempty(wp.C)
                        if exist('twf','var') && ~isempty(twf)
                            wp.C=pi*twf(0);
                            if isnan(wp.C), wp.C=pi*twf(10^(-14)); end
                        else
                            wp.C=(QQ(1,1)+QQ(1,2))/2;
                        end
                    end
                    if wflag==1 && ~strcmpi(DispMode,'off') %����������������������
                        fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                        fprintf('The frequency-domain window function is not well-behaved (e.g. decays very slowly as frequency tends\n');
                        fprintf('to +/- infinity). The integration might be not accurate (and therefore e.g. the calculated frequency \n');
                        fprintf('step ''fstep'', if set to ''auto'', so frequency discretization might be also not appropriate).\n');
                        fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                    end

                    if isempty(wp.omg) %�������������������������������������������
                        wstate=warning('off','all');
                        px1=min([wp.ompeak-xx(1,1),xx(1,2)-wp.ompeak]); px2=min([wp.ompeak-xx(4,1),xx(4,2)-wp.ompeak]);
                        [Y1,errY1]=quadgk(@(u)(u.*fwt(wp.ompeak+u)-u.*fwt(wp.ompeak-u)),0,px1,'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        [Y2,errY2]=quadgk(@(u)(u.*fwt(wp.ompeak+u)-u.*fwt(wp.ompeak-u)),px1,px2,'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        [Y3,errY3]=quadgk(@(u)-u.*fwt(wp.ompeak-u),px2,wp.ompeak-xx(4,1),'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        [Y4,errY4]=quadgk(@(u)u.*fwt(wp.ompeak+u),px2,xx(4,2)-wp.ompeak,'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        if abs((errY1+errY2+errY3+errY4)/(Y1+Y2+Y3+Y4))<10^(-4), wp.omg=wp.ompeak+(Y1+Y2+Y3+Y4)/(2*wp.C); else wp.omg=Inf; end
                        warning(wstate);
                    end


                    if isempty(twf) %if time domain form is not known
                        [PP,wflag,xx,ss]=sqeps(app,@(x)abs(fwt(x)).^2,wp.ompeak,[wp.xi1,wp.xi2],racc,MIC,...
                            [-8*(2*pi*fs),8*(2*pi*fs)]); %�������������������������
                        Etot=sum(PP(1,:))/2/pi;

                        CL=2^nextpow2(MIC/8); CT=CL/(2*abs(ss(1,2)-ss(1,1)));
                        CNq=ceil((CL+1)/2); cxi=(2*pi/CT)*(CNq-CL:CNq-1)'; idm=find(cxi<=wp.xi1); idc=find(cxi>wp.xi1 & cxi<wp.xi2); idp=find(cxi>=wp.xi2);
                        Cfwt=[zeros(length(idm),1);fwt(cxi(idc));zeros(length(idp),1)]; idnan=find(isnan(Cfwt));
                        if ~isempty(idnan), idnorm=find(~isnan(Cfwt)); Cfwt(idnan)=interp1(idnorm,Cfwt(idnorm),idnan,'spline','extrap'); end
                        Ctwf=ifft((CL/CT)*Cfwt([CL-CNq+1:CL,1:CL-CNq])); Ctwf=Ctwf([CNq+1:CL,1:CNq]);
                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(CT/CL)*sum(Etwf); perr=Inf;
                        while (abs(Etot-Eest)+Iest1+Iest2)/Etot<=perr
                            CT=CT/2; perr=(abs(Etot-Eest)+Iest1+Iest2)/Etot;
                            CNq=ceil((CL+1)/2); cxi=(2*pi/CT)*(CNq-CL:CNq-1)'; idm=find(cxi<=wp.xi1); idc=find(cxi>wp.xi1 & cxi<wp.xi2); idp=find(cxi>=wp.xi2);
                            Cfwt=[zeros(length(idm),1);fwt(cxi(idc));zeros(length(idp),1)]; idnan=find(isnan(Cfwt));
                            if ~isempty(idnan), idnorm=find(~isnan(Cfwt)); Cfwt(idnan)=interp1(idnorm,Cfwt(idnorm),idnan,'spline','extrap'); end
                            Ctwf=ifft((CL/CT)*Cfwt([CL-CNq+1:CL,1:CL-CNq])); Ctwf=Ctwf([CNq+1:CL,1:CNq]);
                            Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                            Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                            Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                            Eest=(CT/CL)*sum(Etwf);
                        end
                        CL=16*CL; CT=CT*2;
                        CNq=ceil((CL+1)/2); cxi=(2*pi/CT)*(CNq-CL:CNq-1)'; idm=find(cxi<=wp.xi1); idc=find(cxi>wp.xi1 & cxi<wp.xi2); idp=find(cxi>=wp.xi2);
                        Cfwt=[zeros(length(idm),1);fwt(cxi(idc));zeros(length(idp),1)]; idnan=find(isnan(Cfwt));
                        if ~isempty(idnan), idnorm=find(~isnan(Cfwt)); Cfwt(idnan)=interp1(idnorm,Cfwt(idnorm),idnan,'spline','extrap'); end
                        Ctwf=ifft((CL/CT)*Cfwt([CL-CNq+1:CL,1:CL-CNq])); Ctwf=Ctwf([CNq+1:CL,1:CNq]);
                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(CT/CL)*sum(Etwf);

                        if (abs(Etot-Eest)+Iest1+Iest2)/Etot>0.01 && ~strcmpi(DispMode,'off') %�������������������������������������������������
                            fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                            fprintf(['Cannot accurately invert the specified frequency-domain form of the window function to find its\n',...
                                'time domain form and corresponding characteristics (e.g. cone-of-influence borders).\n',...
                                'This might be because the window function decays too slowly in time or frequency.\n']);
                            fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                        end


                        Ctwf=Ctwf(1:2*CNq-3); ct=(CT/CL)*(-(CNq-2):CNq-2)'; %make symmetric
                        wp.twf={Ctwf,ct};

                        %Estimate general parameters
                        if isempty(wp.tpeak) %peak time
                            ipeak=find(abs(Ctwf)==max(abs(Ctwf)));
                            if length(ipeak)==1
                                a1=abs(Ctwf(ipeak-1)); a2=abs(Ctwf(ipeak)); a3=abs(Ctwf(ipeak+1));
                                wp.tpeak=ct(ipeak);
                                if abs(a1-2*a2+a3)>2*eps, %use queadratic interpolation to find exact peak location
                                    wp.tpeak=wp.tpeak+(1/2)*(a1-a3)/(a1-2*a2+a3)*(CT/CL);
                                end
                            else
                                wp.tpeak=mean(ct(ipeak));
                            end
                        end
                        if isempty(wp.twfmax)
                            [~,ipeak]=min(abs(ct-wp.tpeak));
                            wp.twfmax=interp1(ct(ipeak-1:ipeak+1),abs(Ctwf(ipeak-1:ipeak+1)),wp.tpeak,'spline');
                        end

                        %Calculate the cumulative integrals
                        ct=[ct-CT/CL/2;ct(end)+CT/CL/2]; %to use midpoint rule
                        CS=(CT/CL)*cumsum(Ctwf); CS=[0;CS(:)]/CS(end); CS=abs(CS);
                        ICS=(CT/CL)*cumsum(Ctwf(end:-1:1)); ICS=ICS(end:-1:1); ICS=[ICS(:);0]/ICS(1); ICS=abs(ICS);

                        %Estimate epsilon-supports
                        xid=find(CS(1:end-1)<racc/2 & CS(2:end)>=racc/2,1,'first');
                        if isempty(xid), wp.t1e=ct(1);
                        else
                            a1=CS(xid)-racc/2; a2=CS(xid+1)-racc/2;
                            wp.t1e=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end
                        xid=find(ICS(1:end-1)>=racc/2 & ICS(2:end)<racc/2,1,'last');
                        if isempty(xid), wp.t2e=ct(end);
                        else
                            a1=ICS(xid)-racc/2; a2=ICS(xid+1)-racc/2;
                            wp.t2e=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end
                        xid=find(CS(1:end-1)<0.25 & CS(2:end)>=0.25,1,'first');
                        if isempty(xid), wp.t1h=ct(1);
                        else
                            a1=CS(xid)-0.25; a2=CS(xid+1)-0.25;
                            wp.t1h=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end
                        xid=find(ICS(1:end-1)>=0.25 & ICS(2:end)<0.25,1,'last');
                        if isempty(xid), wp.t2h=ct(end);
                        else
                            a1=ICS(xid)-0.25; a2=ICS(xid+1)-0.25;
                            wp.t2h=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end

                    end

                end
                %------------------------------------------------------------------
                if ~isempty(twf) %if the time-domain form is known
                    wp.twf=twf;
                    if isempty(wp.tpeak) %peak time
                        ipeak=find(abs(twf(nt))==max(abs(twf(nt)))); wp.tpeak=mean(nt(ipeak));
                        wp.tpeak=fminsearch(@(x)-abs(twf(x)),wp.tpeak,optimset('TolX',10^(-14),'Display','off'));
                    end

                    %Calculate the frequency domain characteristics first, if not
                    %known (will be needed afterwards, mainly [wp.ompeak])
                    if isempty(fwt) %if frequency domain form is not known
                        [PP,wflag,xx,ss]=sqeps(app,@(x)abs(twf(x)).^2,wp.tpeak,[wp.t1,wp.t2],racc,MIC,...
                            [-8*L/fs,8*L/fs]); %�����������������������������������
                        Etot=sum(PP(1,:));

                        CL=2^nextpow2(MIC/8); CT=2*abs(ss(1,2)-ss(1,1));
                        CNq=ceil((CL+1)/2); ct=(CT/CL)*(CNq-CL:CNq-1)'; idm=find(ct<=wp.t1); idc=find(ct>wp.t1 & ct<wp.t2); idp=find(ct>=wp.t2);
                        Ctwf=[zeros(length(idm),1);twf(ct(idc));zeros(length(idp),1)]; idnan=find(isnan(Ctwf));
                        if ~isempty(idnan), idnorm=find(~isnan(Ctwf)); Ctwf(idnan)=interp1(idnorm,Ctwf(idnorm),idnan,'spline','extrap'); end
                        Cfwt=(CT/CL)*fft(Ctwf([CL-CNq+1:CL,1:CL-CNq])); Cfwt=Cfwt([CNq+1:CL,1:CNq]);
                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(1/CT)*sum(Efwt); perr=Inf;
                        while (abs(Etot-Eest)+Iest1+Iest2)/Etot<=perr
                            CT=CT*2; perr=(abs(Etot-Eest)+Iest1+Iest2)/Etot;
                            CNq=ceil((CL+1)/2); ct=(CT/CL)*(CNq-CL:CNq-1)'; idm=find(ct<=wp.t1); idc=find(ct>wp.t1 & ct<wp.t2); idp=find(ct>=wp.t2);
                            Ctwf=[zeros(length(idm),1);twf(ct(idc));zeros(length(idp),1)]; idnan=find(isnan(Ctwf));
                            if ~isempty(idnan), idnorm=find(~isnan(Ctwf)); Ctwf(idnan)=interp1(idnorm,Ctwf(idnorm),idnan,'spline','extrap'); end
                            Cfwt=(CT/CL)*fft(Ctwf([CL-CNq+1:CL,1:CL-CNq])); Cfwt=Cfwt([CNq+1:CL,1:CNq]);
                            Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                            Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                            Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                            Eest=(1/CT)*sum(Efwt);
                        end
                        CL=16*CL; CT=CT*2;
                        CNq=ceil((CL+1)/2); ct=(CT/CL)*(CNq-CL:CNq-1)'; idm=find(ct<=wp.t1); idc=find(ct>wp.t1 & ct<wp.t2); idp=find(ct>=wp.t2);
                        Ctwf=[zeros(length(idm),1);twf(ct(idc));zeros(length(idp),1)]; idnan=find(isnan(Ctwf));
                        if ~isempty(idnan), idnorm=find(~isnan(Ctwf)); Ctwf(idnan)=interp1(idnorm,Ctwf(idnorm),idnan,'spline','extrap'); end
                        Cfwt=(CT/CL)*fft(Ctwf([CL-CNq+1:CL,1:CL-CNq])); Cfwt=Cfwt([CNq+1:CL,1:CNq]);
                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(1/CT)*sum(Efwt);

                        if (abs(Etot-Eest)+Iest1+Iest2)/Etot>0.01 && ~strcmpi(DispMode,'off') %�������������������������������������������������
                            fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                            fprintf(['Cannot accurately invert the specified time-domain form of the window function to find its\n',...
                                'frequency-domain form and corresponding characteristics (e.g. optimal frequency step ''fstep'').\n',...
                                'This might be because the window function decays too slowly in time or frequency.\n']);
                            fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                        end

                        Cfwt=Cfwt(1:2*CNq-3); cxi=(2*pi/CT)*(-(CNq-2):CNq-2)'; %make symmetric
                        wp.fwt={Cfwt,cxi};

                        %Estimate general parameters
                        if isempty(wp.ompeak) %peak frequency
                            ipeak=find(abs(Cfwt)==max(abs(Cfwt)));
                            if length(ipeak)==1
                                a1=abs(Cfwt(ipeak-1)); a2=abs(Cfwt(ipeak)); a3=abs(Cfwt(ipeak+1));
                                wp.ompeak=cxi(ipeak);
                                if abs(a1-2*a2+a3)>2*eps, %use queadratic interpolation to find exact peak location
                                    wp.ompeak=wp.ompeak+(1/2)*(a1-a3)/(a1-2*a2+a3)*(2*pi/CT);
                                end
                            else
                                wp.ompeak=mean(cxi(ipeak));
                            end
                        end
                        if isempty(wp.fwtmax)
                            [~,ipeak]=min(abs(cxi-wp.ompeak));
                            wp.fwtmax=interp1(cxi(ipeak-1:ipeak+1),abs(Cfwt(ipeak-1:ipeak+1)),wp.ompeak,'spline');
                        end
                        if isempty(wp.C) %�����������������������������������������
                            wp.C=pi*twf(0);
                            if isnan(wp.C), wp.C=pi*twf(10^(-14)); end
                        end
                        if isempty(wp.omg) %���������������������������������������
                            wp.omg=sum((2*pi/CT)*cxi.*Cfwt)/(2*wp.C);
                        end

                        %Calculate the cumulative integrals
                        cxi=[cxi-pi/CT;cxi(end)+pi/CT]; %to use midpoint rule
                        CS=(2*pi/CT)*cumsum(Cfwt); CS=[0;CS(:)]/CS(end); CS=abs(CS);
                        ICS=(2*pi/CT)*cumsum(Cfwt(end:-1:1)); ICS=ICS(end:-1:1); ICS=[ICS(:);0]/ICS(1); ICS=abs(ICS);

                        %Estimate epsilon-supports
                        xid=find(CS(1:end-1)<racc/2 & CS(2:end)>=racc/2,1,'first');
                        if isempty(xid), wp.xi1e=cxi(1);
                        else
                            a1=CS(xid)-racc/2; a2=CS(xid+1)-racc/2;
                            wp.xi1e=cxi(xid)-a1*(cxi(xid+1)-cxi(xid))/(a2-a1);
                        end
                        xid=find(ICS(1:end-1)>=racc/2 & ICS(2:end)<racc/2,1,'last');
                        if isempty(xid), wp.xi2e=cxi(end);
                        else
                            a1=ICS(xid)-racc/2; a2=ICS(xid+1)-racc/2;
                            wp.xi2e=cxi(xid)-a1*(cxi(xid+1)-cxi(xid))/(a2-a1);
                        end
                        xid=find(CS(1:end-1)<0.25 & CS(2:end)>=0.25,1,'first');
                        if isempty(xid), wp.xi1h=cxi(1);
                        else
                            a1=CS(xid)-0.25; a2=CS(xid+1)-0.25;
                            wp.xi1h=cxi(xid)-a1*(cxi(xid+1)-cxi(xid))/(a2-a1);
                        end
                        xid=find(ICS(1:end-1)>=0.25 & ICS(2:end)<0.25,1,'last');
                        if isempty(xid), wp.xi2h=cxi(end);
                        else
                            a1=ICS(xid)-0.25; a2=ICS(xid+1)-0.25;
                            wp.xi2h=cxi(xid)-a1*(cxi(xid+1)-cxi(xid))/(a2-a1);
                        end


                        if abs(wp.ompeak)>10^(-12) %center around the peak
                            if ~strcmpi(DispMode,'off') %����������������������
                                fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                                fprintf('The Fourier transform (FT) of the specified window function is not centered around its maximum, which occurs at %f.\n',wp.ompeak)
                                fprintf('Therefore, we consider its modified version shifted in frequency so that the window FT amplitude is now peaked at zero.\n');
                                fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                            end
                            wp.xi1=wp.xi1-wp.ompeak; wp.xi2=wp.xi2-wp.ompeak;
                            wp.xi1e=wp.xi1e-wp.ompeak; wp.xi2e=wp.xi2e-wp.ompeak;
                            wp.xi1h=wp.xi1h-wp.ompeak; wp.xi2h=wp.xi2h-wp.ompeak;
                            wp.omg=wp.omg-wp.ompeak;

                            twf=@(t)twf(t).*exp(-1i*wp.ompeak*t); wp.twf=twf;
                            Ctwf=[zeros(length(idm),1);twf(ct(idc));zeros(length(idp),1)]; idnan=find(isnan(Ctwf));
                            if ~isempty(idnan), idnorm=find(~isnan(Ctwf)); Ctwf(idnan)=interp1(idnorm,Ctwf(idnorm),idnan,'spline','extrap'); end
                            Cfwt=(CT/CL)*fft(Ctwf([CL-CNq+1:CL,1:CL-CNq])); Cfwt=Cfwt([CNq+1:CL,1:CNq]);
                            Cfwt=Cfwt(1:2*CNq-3); cxi=(2*pi/CT)*(-(CNq-2):CNq-2)'; %make symmetric

                            wp.fwt={Cfwt,cxi}; wp.ompeak=0;
                        end

                    end

                    if isempty(wp.twfmax)
                        wp.twfmax=twf(wp.tpeak);
                        if isnan(wp.twfmax), wp.twfmax=twf(wp.tpeak+10^(-14)); end
                    end
                    vfun=@(u)twf(u); xp=wp.tpeak; lim1=wp.t1; lim2=wp.t2;

                    [QQ,wflag,xx,ss]=sqeps(app,vfun,xp,[lim1,lim2],racc,MIC,...
                        [-8*L/fs,8*L/fs]); %���������������������������������������
                    wp.t1e=ss(1,1); wp.t2e=ss(1,2); wp.t1h=ss(2,1); wp.t2h=ss(2,2);
                    if wflag==1 && ~strcmpi(DispMode,'off') %����������������������
                        fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                        fprintf('The time-domain window function is not well-behaved (e.g. decays very slowly as time tends to +/- infinity).\n');
                        fprintf('The integration might be not accurate (and therefore e.g. cone-of-influence borders).\n');
                        fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                    end

                end

            end
            %======================================================================================================================


        end


        %============ Function for accurate integration and estimation ============
        %================= of epsilon-supports (needed in parcalc) ================
        %INPUT:
        %[vfun] - function @(x) to be integrated
        %[xp] - position of the peak
        %[lims]=[lim1,lim2] - limits where [vfun] is defined
        %[racc] - accuracy (i.e. epsilon)
        %[MIC] - maximum number of intervals in quadgk(...)
        %[nlims] - the maximum limits dictated by the signal
        %OUTPUT:
        %[QQ]=[Q1,Q2;q1m,q2m] - integrals of the function:
        %     [Q1,Q2] - over [lim1,xp] and [xp,lim2]
        %     [q1m,q2m] - over [x1m,xp] and [xp,x2m]
        %[wflag]=0|1 - was integration fully successful (0) or not (1)
        %[xx]=[x1h,x2h;x1e,x2e;x1m,x2m;x1n,x2n] - different important points:
        %     [x1e,x2e] - interval where [vfun] is larger than 10^(-8) of maximum
        %     [x1h,x2h] - where [vfun] first drops below 0.5 of its maximum
        %     [x1m,x2m] - interval over which integration with quadgk is precise
        %                 (just =[x1e,x2e] if all is precise, i.e. wflag=0)
        %     [x1n,x2n] - limits of overflow (i.e. when function returns NaN due to
        %                 numerics, as for exp(-x^2)*exp(x) for large x); if no
        %                 overflow, then [x1n,x2n]=[lim1,lim2]
        %optional:
        %[ss]=[s1h,s2h;s1e,s2e] - supports of the function over its variable:
        %     [s1e,s2e] - epsilon-support
        %     [s1h,s2h] - 0.5-support
        function [QQ,wflag,xx,ss]=sqeps(app,vfun,xp,lims,racc,MIC,nlims)

            wflag=0; %indicates are there any problems with integration
            ctol=max([racc/1000,10^(-12)]); %numerical accuracy
            lim1=lims(1); lim2=lims(2);
            nlim1=nlims(1); nlim2=nlims(2);

            kk=1; shp=0; %check for function to be well-defined on the peak, otherwise introduce some shift [shp]
            while (~isfinite(vfun(xp+shp)) || isnan(vfun(xp+shp))), shp=kk*10^(-14); kk=kk*(-2); end
            vmax=vfun(xp+shp);

            %determine x after for which function first crosses 0.5 of its maximal value (if such point exists)
            if isfinite(lim1), tx1=lim1-0.01*(lim1-xp); qv1=abs(vfun(tx1)/vmax); else qv1=NaN; end
            if qv1<0.5, x1h=fzero(@(u)abs(vfun(u)/vmax)-0.5,[xp+shp,tx1],optimset('Display','off'));
            elseif isnan(qv1), x1h=fzero(@(u)abs(vfun(xp-abs(u))/vmax)-0.5,shp,optimset('Display','off')); x1h=xp-abs(x1h);
            else x1h=xp+(lim1-xp)/100;
            end
            if isfinite(lim2), tx2=lim2-0.01*(lim2-xp); qv2=abs(vfun(tx2)/vmax); else qv2=NaN; end
            if qv2<0.5, x2h=fzero(@(u)abs(vfun(u)/vmax)-0.5,[xp+shp,tx2],optimset('Display','off'));
            elseif isnan(qv2), x2h=fzero(@(u)abs(vfun(xp+abs(u))/vmax)-0.5,shp,optimset('Display','off')); x2h=xp+abs(x2h);
            else x2h=xp+(lim2-xp)/100;
            end
            if isnan(x1h)
                x1h=fminsearch(@(u)abs(abs(vfun(xp-abs(u))/vmax)-0.5),shp,optimset('TolFun',10^(-12)));
                x1h=xp-abs(x1h)/100;
            end
            if isnan(x2h)
                x2h=fminsearch(@(u)abs(abs(vfun(xp+abs(u))/vmax)-0.5),shp,optimset('TolFun',10^(-12)));
                x2h=xp+abs(x2h)/100;
            end


            %determine x after which function is always below 10^(-8) of its maximal value (if such point exists), and not only the first crossing
            if isfinite(lim1), tx1=lim1-0.01*(lim1-xp); qv1=(abs(vfun(tx1))+abs(vfun((tx1+lim1)/2))+abs(vfun((tx1+3*lim1)/4)))/abs(vmax); else qv1=NaN; end
            if qv1<10^(-8)/3
                x1e=fzero(@(u)abs(vfun(u)/vmax)+abs(vfun((u+lim1)/2)/vmax)+abs(vfun((u+3*lim1)/4)/vmax)-10^(-8),[xp+shp,tx1],optimset('Display','off'));
            elseif isnan(qv1)
                x1e=fzero(@(u)abs(vfun(xp-abs(u))/vmax)+abs(vfun(xp-sqrt(3)*abs(u))/vmax)+...
                    abs(vfun(xp-sqrt(5)*abs(u))/vmax)-10^(-8),shp,optimset('Display','off')); %to avoid selecting first minima for oscillating functions
                x1e=xp-abs(x1e);
            else x1e=xp+(lim1-xp)/2;
            end
            if isfinite(lim2), tx2=lim2-0.01*(lim2-xp); qv2=(abs(vfun(tx2))+abs(vfun((tx2+lim2)/2))+abs(vfun((tx2+3*lim2)/4)))/abs(vmax); else qv2=NaN; end
            if qv2<10^(-8)
                x2e=fzero(@(u)abs(vfun(u)/vmax)+abs(vfun((u+lim2)/2)/vmax)+abs(vfun((u+3*lim2)/4)/vmax)-10^(-8),[xp+shp,tx2],optimset('Display','off'));
            elseif isnan(qv2)
                x2e=fzero(@(u)abs(vfun(xp+abs(u))/vmax)+abs(vfun(xp+sqrt(3)*abs(u))/vmax)+...
                    abs(vfun(xp+sqrt(5)*abs(u))/vmax)-10^(-8),shp,optimset('Display','off'));
                x2e=xp+abs(x2e);
            else x2e=xp+(lim2-xp)/2;
            end
            if isnan(x1e)
                x1e=fminsearch(@(u)abs(abs(vfun(x1h-abs(u))/vmax)-10^(-8)),0,optimset('TolFun',10^(-12)));
                x1e=x1h-abs(x1e); lim1=x1e; wflag=1;
            end
            if isnan(x2e)
                x2e=fminsearch(@(u)abs(abs(vfun(x2h+abs(u))/vmax)-10^(-8)),0,optimset('TolFun',10^(-12)));
                x2e=x2h+abs(x2e); lim2=x2e; wflag=1;
            end


            wstate=warning('off','all'); %disable warnings


            %=============== Integrate given function to find Q1 and Q2 ===============
            Q1=0; Q2=0;
            %--------------------------------------------------------------------------
            [qv,eb]=quadgk(@(u)vfun(u),xp,x1e,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
            [qv,eb]=quadgk(@(u)vfun(u),xp,x1e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
            x1m=x1e; q1m=qv;
            if abs(eb/(Q1+qv))>ctol
                wflag=1;
                [qv,eb]=quadgk(@(u)vfun(u),xp,x1h,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
                [qv,eb]=quadgk(@(u)vfun(u),xp,x1h,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
                x1m=x1h; q1m=qv;
                if abs(eb/(Q1+qv))<=ctol
                    while 1==1
                        [qv,eb]=quadgk(@(u)vfun(u),xp,max([xp+(x1m-xp)*2,x1e]),'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
                        [qv,eb]=quadgk(@(u)vfun(u),xp,max([xp+(x1m-xp)*2,x1e]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
                        if abs(eb/(Q1+qv))>ctol, break; end
                        x1m=max([xp+(x1m-xp)*2,x1e]); q1m=qv;
                    end
                else
                    while abs(eb/(Q1+qv))>ctol
                        x1m=xp+(x1m-xp)/2;
                        [qv,eb]=quadgk(@(u)vfun(u),xp,x1m,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
                        [qv,eb]=quadgk(@(u)vfun(u),xp,x1m,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
                        q1m=qv;
                    end
                end
                [qv,eb]=quadgk(@(u)vfun(u),x1m,x1e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
                if abs(eb)<0.5*abs(qv), Q1=Q1+qv; end
            end
            Q1=Q1+q1m;
            if wflag==0
                [qv,eb]=quadgk(@(u)vfun(u),x1e,lim1,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q1),'RelTol',0);
                if ~isfinite(lim1) && isnan(qv) %to avoid overflow
                    lim1=x1e; while ~isnan(vfun(2*lim1)), lim1=2*lim1; end
                    [qv,eb]=quadgk(@(u)vfun(u),x1e,lim1,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q1),'RelTol',0);
                end
                if abs(eb/Q1)>ctol
                    wflag=1;
                    [qv,eb]=quadgk(@(u)vfun(u),x1e,max([min([8*x1e,nlim1]),lim1]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q1),'RelTol',0);
                end
                if abs(eb)<0.5*abs(qv), Q1=Q1+qv; end
            end
            Q1=-Q1;
            %--------------------------------------------------------------------------
            [qv,eb]=quadgk(@(u)vfun(u),xp,x2e,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
            [qv,eb]=quadgk(@(u)vfun(u),xp,x2e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
            x2m=x2e; q2m=qv;
            if abs(eb/(Q2+qv))>ctol
                wflag=1;
                [qv,eb]=quadgk(@(u)vfun(u),xp,x2h,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
                [qv,eb]=quadgk(@(u)vfun(u),xp,x2h,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
                x2m=x2h; q2m=qv;
                if abs(eb/(Q2+qv))<=ctol
                    while 1==1
                        [qv,eb]=quadgk(@(u)vfun(u),xp,min([xp+(x2m-xp)*2,x2e]),'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
                        [qv,eb]=quadgk(@(u)vfun(u),xp,min([xp+(x2m-xp)*2,x2e]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
                        if abs(eb/(Q2+qv))>ctol, break; end
                        x2m=min([xp+(x2m-xp)*2,x2e]); q2m=qv;
                    end
                else
                    while abs(eb/(Q2+qv))>ctol
                        x2m=xp+(x2m-xp)/2;
                        [qv,eb]=quadgk(@(u)vfun(u),xp,x2m,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
                        [qv,eb]=quadgk(@(u)vfun(u),xp,x2m,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
                        q2m=qv;
                    end
                end
                [qv,eb]=quadgk(@(u)vfun(u),x2m,x2e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
                if abs(eb)<0.5*abs(qv), Q2=Q2+qv; end
            end
            Q2=Q2+q2m;
            if wflag==0
                [qv,eb]=quadgk(@(u)vfun(u),x2e,lim2,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q2),'RelTol',0);
                if ~isfinite(lim2) && isnan(qv) %to avoid overflow
                    lim2=x2e; while ~isnan(vfun(2*lim2)), lim2=2*lim2; end
                    [qv,eb]=quadgk(@(u)vfun(u),x2e,lim2,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q2),'RelTol',0);
                end
                if abs(eb/Q2)>ctol
                    wflag=1;
                    [qv,eb]=quadgk(@(u)vfun(u),x2e,min([max([8*x2e,nlim2]),lim2]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q2),'RelTol',0);
                end
                if abs(eb)<0.5*abs(qv), Q2=Q2+qv; end
            end

            QQ=[Q1,Q2;-q1m,q2m]; xx=[x1e,x2e;x1h,x2h;x1m,x2m;lim1,lim2];
            %--------------------------------------------------------------------------

            %======== Find 0.5- and \epsilon-supports of the specified function =======
            if nargout>3
                Q=Q1+Q2;
                s1e=fz(racc/2); s2e=fz(1-racc/2); s1h=fz(0.5/2); s2h=fz(1-0.5/2);
                ss=[s1e,s2e;s1h,s2h];
            end
            %--------------------------------------------------------------------------

            warning(wstate); %restore the warning settings


            %function for finding the epsilon-supports
            function x0=fz(zv)
                if zv<abs(Q1/Q), cx1=x1m; cq1=Q1+q1m; ra=exp(-1/2); rb=exp(1/2);
                else cx1=x2m; cq1=Q1+q2m; ra=exp(1/2); rb=exp(-1/2); end
                if abs(1-abs((Q-cq1)/Q))<zv
                    while 1==1
                        nx=xp+ra*(cx1-xp);
                        if nx<lim1, nx=(cx1+lim1)/2; end
                        if nx>lim2, nx=(cx1+lim2)/2; end
                        [pv,~]=quadgk(@(u)vfun(u),cx1,nx,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q),'RelTol',0);
                        if abs(1-abs((Q-cq1-pv)/Q))>=zv, cx2=nx; cq2=cq1+pv; break; end
                        cx1=nx; cq1=cq1+pv;
                    end
                else
                    while 1==1
                        nx=xp+rb*(cx1-xp);
                        if nx<lim1, nx=(cx1+lim1)/2; end
                        if nx>lim2, nx=(cx1+lim2)/2; end
                        [pv,~]=quadgk(@(u)vfun(u),cx1,nx,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q),'RelTol',0);
                        if abs(1-abs((Q-cq1-pv)/Q))<zv, cx2=nx; cq2=cq1+pv; break; end
                        cx1=nx; cq1=cq1+pv;
                    end
                end
                [pv,~]=quadgk(@(u)vfun(u),cx1,(cx1+cx2)/2,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q),'RelTol',0);
                qfun=@(x)1-abs((Q-(cq1+pv+quadgk(@(u)vfun(u),(cx1+cx2)/2,x,'MaxIntervalCount',round(MIC/10),'AbsTol',0.5*abs(ctol*Q),'RelTol',0)))/Q);
                x0=fzero(@(x)abs(qfun(x))-zv,[cx1,cx2],optimset('TolX',10^(-14)));
            end


        end


        %================== Predictive padding function ===========================

        % fsig=fcast(sig,fs,NP,fint,Optional:MaxOrder,w)
        % - given signal [sig] sampled at [fs] Hz, uses DFT and weighted least
        % squares (with weights specified by [w], which should be of the same
        % length as [sig]), finds main sinusoidal components present in the signal
        % and uses them to predict signal for NP consequtive time-steps, which is
        % returned in [fsig]; [fint]=[fmin,fmax] specifies the allowable frequency
        % range for sinusoids: tones with frequencies out of it are not continued
        % for prediction. The number of sinusoids is determined using Bayesian
        % (Schwarz) information criterion, but it cannot exceed [MaxOrder].

        function fsig = fcast(app,sig,fs,NP,fint,varargin)

            MaxOrder=length(sig); if nargin>3 && ~isempty(varargin{1}), MaxOrder=varargin{1}; end
            w=[]; if nargin>4 && ~isempty(varargin{2}), w=varargin{2}(:); end, rw=sqrt(w);
            DispMode='off'; if nargin>5 && ~isempty(varargin{3}), DispMode=varargin{3}; end

            WTol=10^(-8); %tolerance for cutting the weighting
            Y=sig(:); if ~isempty(rw), Y=rw.*Y; end
            L=find(flipud(rw)/max(rw)>=WTol,1,'last'); T=L/fs; t=(0:(L-1))/fs;
            w=w(end-L+1:end); rw=rw(end-L+1:end); Y=Y(end-L+1:end);
            MaxOrder=min([MaxOrder,floor(L/3)]);

            FTol=1/T/100; %accuracy of frequencies determination
            rr=(1+sqrt(5))/2; %multiplier to easily use golden section search afterwards
            Nq=ceil((L+1)/2); ftfr=[(0:Nq-1),-fliplr(1:L-Nq)]*fs/L; %FT frequencies
            orstd=std(Y); %original signal's standard deviation

            v=zeros(L,1); ic=v; frq=v; amp=v; phi=v; itn=0;
            if strcmpi(DispMode,'on'), pos=2; fprintf('0%%'); end
            while itn<MaxOrder
                itn=itn+1;
                aftsig=abs(fft(Y)); [~,imax]=max(aftsig(2:Nq-1)); imax=imax+1;

                %========================= Forward search =============================
                %Find two points between which the minimum occurs
                nf=ftfr(imax);
                FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
                if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                nb=FM\Y; nerr=std(Y-FM*nb);

                df=FTol; perr=Inf; %starting frequency step and 'previous error'
                while nerr<perr
                    if abs(nf-fs/2+FTol)<eps, break; end
                    pf=nf; perr=nerr; pb=nb;
                    nf=min([pf+df,fs/2-FTol]);
                    FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
                    if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                    nb=FM\Y; nerr=std(Y-FM*nb);
                    df=df*rr;
                end

                %Use golden section search to find exact minimum
                if nerr<perr
                    cf=[nf,nf,nf]; cerr=[nerr,nerr,nerr]; cb=[nb(:),nb(:),nb(:)];
                elseif abs(nf-pf-FTol)<eps
                    cf=[pf,pf,pf]; cerr=[perr,perr,perr]; cb=[pb(:),pb(:),pb(:)];
                else
                    cf=[0,pf,nf]; cerr=[0,perr,nerr]; cb=[zeros(length(pb),1),pb(:),nb(:)];
                    cf(1)=pf-df/rr/rr;
                    FM=[ones(L,1),cos(2*pi*cf(1)*t(:)),sin(2*pi*cf(1)*t(:))];
                    if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                    cb(:,1)=FM\Y; cerr(1)=std(Y-FM*cb(:,1));
                end
                while (cf(2)-cf(1)>FTol && cf(3)-cf(2)>FTol)
                    tf=cf(1)+cf(3)-cf(2);
                    FM=[ones(L,1),cos(2*pi*tf*t(:)),sin(2*pi*tf*t(:))];
                    if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                    tb=FM\Y; terr=std(Y-FM*tb);
                    if terr<cerr(2) && tf<cf(2), cf=[cf(1),tf,cf(2)]; cerr=[cerr(1),terr,cerr(2)]; cb=[cb(:,1),tb(:),cb(:,2)]; end
                    if terr<cerr(2) && tf>cf(2), cf=[cf(2),tf,cf(3)]; cerr=[cerr(2),terr,cerr(3)]; cb=[cb(:,2),tb(:),cb(:,3)]; end
                    if terr>cerr(2) && tf<cf(2), cf=[tf,cf(2),cf(3)]; cerr=[terr,cerr(2),cerr(3)]; cb=[tb(:),cb(:,2),cb(:,3)]; end
                    if terr>cerr(2) && tf>cf(2), cf=[cf(1),cf(2),tf]; cerr=[cerr(1),cerr(2),terr]; cb=[cb(:,1),cb(:,2),tb(:)]; end
                end
                fcf=cf(2); fcb=cb(:,2); fcerr=cerr(2); %forward values

                %========================= Backward search ============================
                %Find two points between which minima occurs
                nf=ftfr(imax);
                FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
                if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                nb=FM\Y; nerr=std(Y-FM*nb);

                df=FTol; perr=Inf; %starting frequency step and 'previous error'
                while nerr<perr
                    if abs(nf-FTol)<eps, break; end
                    pf=nf; perr=nerr; pb=nb;
                    nf=max([pf-df,FTol]);
                    FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
                    if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                    nb=FM\Y; nerr=std(Y-FM*nb);
                    df=df*rr;
                end

                %Use golden section search to find exact minimum
                if nerr<perr
                    cf=[nf,nf,nf]; cerr=[nerr,nerr,nerr]; cb=[nb(:),nb(:),nb(:)];
                elseif abs(nf-pf-FTol)<eps
                    cf=[pf,pf,pf]; cerr=[perr,perr,perr]; cb=[pb(:),pb(:),pb(:)];
                else
                    cf=[nf,pf,0]; cerr=[nerr,perr,0]; cb=[nb(:),pb(:),zeros(length(pb),1)];
                    cf(3)=pf+df/rr/rr;
                    FM=[ones(L,1),cos(2*pi*cf(1)*t(:)),sin(2*pi*cf(1)*t(:))];
                    if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                    cb(:,3)=FM\Y; cerr(3)=std(Y-FM*cb(:,3));
                end
                while (cf(2)-cf(1)>FTol && cf(3)-cf(2)>FTol)
                    tf=cf(1)+cf(3)-cf(2);
                    FM=[ones(L,1),cos(2*pi*tf*t(:)),sin(2*pi*tf*t(:))];
                    if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                    tb=FM\Y; terr=std(Y-FM*tb);
                    if terr<cerr(2) && tf<cf(2), cf=[cf(1),tf,cf(2)]; cerr=[cerr(1),terr,cerr(2)]; cb=[cb(:,1),tb(:),cb(:,2)]; end
                    if terr<cerr(2) && tf>cf(2), cf=[cf(2),tf,cf(3)]; cerr=[cerr(2),terr,cerr(3)]; cb=[cb(:,2),tb(:),cb(:,3)]; end
                    if terr>cerr(2) && tf<cf(2), cf=[tf,cf(2),cf(3)]; cerr=[terr,cerr(2),cerr(3)]; cb=[tb(:),cb(:,2),cb(:,3)]; end
                    if terr>cerr(2) && tf>cf(2), cf=[cf(1),cf(2),tf]; cerr=[cerr(1),cerr(2),terr]; cb=[cb(:,1),cb(:,2),tb(:)]; end
                end
                bcf=cf(2); bcb=cb(:,2); bcerr=cerr(2); %backward values

                %================ Assigning values and subtracting ====================
                if fcerr<bcerr, cf=fcf; cb=fcb; cerr=fcerr;
                else cf=bcf; cb=bcb; cerr=bcerr; end

                frq(itn+1)=cf; amp(itn+1)=sqrt(cb(2)^2+cb(3)^2); phi(itn+1)=atan2(-cb(3),cb(2));
                amp(1)=amp(1)+cb(1); v(itn)=cerr;

                FM=[ones(L,1),cos(2*pi*cf*t(:)),sin(2*pi*cf*t(:))];
                if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
                Y=Y-FM*cb;

                %===================== Information criterion ==========================
                CK=3*itn+1;
                cic=L*log(cerr)+CK*log(L); %Bayesian (Schwarz) IC (the best)
                %cic=L*log(cerr)+2*CK*L/(L-CK-1); %Akaike IC (corrected)
                %cic=cerr*(L+CK)/(L-CK); %Forward Prediction Error (FPE)

                ic(itn)=cic;
                if v(itn)/orstd<2*eps, break; end
                if itn>2 && cic>ic(itn-1) && ic(itn-1)>ic(itn-2), break; end

                if strcmpi(DispMode,'on') && floor(100*itn/MaxOrder)>floor(100*(itn-1)/MaxOrder)
                    cstr=num2str(floor(100*itn/MaxOrder)); fprintf([repmat('\b',1,pos),cstr,'%%']); pos=length(cstr)+1;
                end
            end
            if strcmpi(DispMode,'on'), fprintf([repmat('\b',1,pos),'100%%']); end

            frq=frq(1:itn+1); amp=amp(1:itn+1); phi=phi(1:itn+1); v=v(1:itn); ic=ic(1:itn);
            fsig=zeros(NP,1); nt=(T:1/fs:T+(NP-1)/fs)';
            if size(sig,2)>size(sig,1), fsig=fsig'; nt=nt'; end
            for k=1:length(frq)
                if frq(k)>fint(1) && frq(k)<fint(2)
                    fsig=fsig+amp(k)*cos(2*pi*frq(k)*nt+phi(k));
                else
                    fsig=fsig+amp(k)*cos(2*pi*frq(k)*(T-1/fs)+phi(k));
                end
            end

        end


        %=============== Interpolation function for plotting ======================

        % ZI=aminterp(X,Y,Z,XI,YI,method)
        % - the same as interp2, but uses different interpolation types,
        % maximum-based ([method]='max') or average-based ([method]='avg'), where
        % [ZI] at each point [XI,YI] represents maximum or average among values of
        % [Z] corresponding to the respective quadrant in [X,Y]-space which
        % includes point [XI,YI];
        % X,Y,XI,YI are all linearly spaced vectors, and the lengths of XI,YI
        % should be the same or smaller than that of X,Y; Z should be real,
        % ideally positive; X and Y correspond to the 2 and 1 dimension of Z, as
        % always.

        function ZI = aminterp(app,X,Y,Z,XI,YI,method)

            %Interpolation over X
            ZI=zeros(size(Z,1),length(XI))*NaN;
            xstep=mean(diff(XI));
            xind=1+floor((1/2)+(X-XI(1))/xstep); xind=xind(:);
            xpnt=[0;find(xind(2:end)>xind(1:end-1));length(xind)];
            if strcmpi(method,'max')
                for xn=1:length(xpnt)-1
                    xid1=xpnt(xn)+1; xid2=xpnt(xn+1);
                    ZI(:,xind(xid1))=max(Z(:,xid1:xid2),[],2);
                end
            else
                for xn=1:length(xpnt)-1
                    xid1=xpnt(xn)+1; xid2=xpnt(xn+1);
                    ZI(:,xind(xid1))=mean(Z(:,xid1:xid2),2);
                end
            end

            Z=ZI;

            %Interpolation over Y
            ZI=zeros(length(YI),size(Z,2))*NaN;
            ystep=mean(diff(YI));
            yind=1+floor((1/2)+(Y-YI(1))/ystep); yind=yind(:);
            ypnt=[0;find(yind(2:end)>yind(1:end-1));length(yind)];
            if strcmpi(method,'max')
                for yn=1:length(ypnt)-1
                    yid1=ypnt(yn)+1; yid2=ypnt(yn+1);
                    ZI(yind(yid1),:)=max(Z(yid1:yid2,:),[],1);
                end
            else
                for yn=1:length(ypnt)-1
                    yid1=ypnt(yn)+1; yid2=ypnt(yn+1);
                    ZI(yind(yid1),:)=mean(Z(yid1:yid2,:),1);
                end
            end

        end


        %Dmytro Iatsenko's codes below have been slightly modified (by Julian
        %Newman) so as no longer to filter negative frequencies when preprocessing is off; this involved
        %removing the "set to zero at negative frequencies" command. Note, however,
        %that the filtering prior to padding (if chosen) still includes negative
        %frequencies.
        %The Morlet wavelet here is defined over the whole frequency
        %scale, as in the main body of the 2015 paper rather than the supplementary
        %material, and therefore does not need changing. I notice that the default
        %wavelet has been changed to Morlet (despite comments still saying that it
        %is Lognormal).
        %
        %Version 1.01 stable
        %**************************************************************************
        %*************************** Wavelet Transform ****************************
        %**************************************************************************
        %-------------------------------Copyright----------------------------------
        %
        % Author: Dmytro Iatsenko
        % Information about these codes (e.g. links to the Video Instructions),
        % as well as other MatLab programs and many more can be found at
        % http://www.physics.lancs.ac.uk/research/nbmphysics/diats/tfr
        %
        % Related articles:
        % [1] D. Iatsenko, A. Stefanovska and P.V.E. McClintock,
        % "Linear and synchrosqueezed time-frequency representations revisited.
        %  Part I: Overview, standards of use, related issues and algorithms."
        % {preprint:arXiv:1310.7215}
        % [2] D. Iatsenko, A. Stefanovska and P.V.E. McClintock,
        % "Linear and synchrosqueezed time-frequency representations revisited.
        %  Part II: Resolution, reconstruction and concentration."
        % {preprint:arXiv:1310.7274}
        %
        %------------------------------Documentation-------------------------------
        %
        % [WT,freq,Optional:wopt]=wt(sig,fs,Optional:'PropertyName',PropertyValue)
        % - calculate wavelet transform [WT] of a signal [sig] sampled at [fs] Hz.
        %
        % INPUT:
        % sig - signal for which to calculate WT
        % fs  - sampling frequency of the signal
        %
        % Properties: ({{...}} denotes default)
        % ################################ BASIC ##################################
        % 'fmin':value (default is the minimal frequency for which at least one WT
        %               coefficient is determined up to a specified relative
        %               accuracy [RelTol] with respect to boundary errors)
        %            minimal frequency for which to calculate WT
        % 'fmax':value (default = fs/2, i.e. the Nyquist frequency)
        %            maximal frequency for which to calculate WT
        % 'nv':{{'auto'}}|'auto-NB'|value
        %            "number of voices", which determines frequency discretization,
        %            so that the next frequency equals previous one multiplied on
        %            [2^(1/nv)]; when set to 'auto-NB' (e.g. 'auto-20') determines
        %            [nv] automatically as described in [1], so that 1/nv equals
        %            1/NB of the logarithmic frequency region containing 50% of the
        %            wavelet function; the default 'auto' is equivalent to 'auto-10'
        % 'f0':value (default = 1)
        %            wavelet resolution parameter, which determines the tradeoff
        %            between the time and frequency resolutions: the higher it is,
        %            the closer in frequency components can be resolved in WT,
        %            but the slower time-variations, e.g. amplitude/frequency
        %            modulation, can be reliably represented; for the way it is
        %            introduced for each wavelet see Appendix E in [1], while if the
        %            wavelet is user-defined in terms of its function in frequency
        %            and/or time (see 'Wavelet' property), then 'f0' obviously does
        %            not influence anything.
        % 'Padding':{{'predictive'}}|0|'symmetric'|'none'|'periodic'|value|{padleft,padright}
        %            what padding to use when computing transform (for a list of
        %            all paddings and their effects see [1]); when set to some
        %            numeric value, pads with that values; most useful are the
        %            zero-padding, for which boundary errors are well-determined,
        %            and 'predictive' padding (default), for which they are
        %            most reduced, while other choices are not very useful;
        %            alternatively, one can specify it as {padleft,padright}, where
        %            [padleft] and [padright] can be any of those (except 'none'),
        %            e.g. {0,'symmetric'} will pad signal to the left with zeros,
        %            and to the right by symmetric reflection; additionally,
        %            [padleft] and [padright] can be vectors to pad with (if there
        %            needed more values than padleft/padright lengths,
        %            pads additionally with zeros), e.g. if you want to consider
        %            only some part of the signal, from indexes n1 to n2, but do
        %            not introduce additional boundary effects to it, then use:
        %            wt(signal(n1:n2),fs,'Padding',{signal(1:n1-1),signal(n2+1:end)});
        %            the same applies if you can predict signal out of the time-limits
        % 'Plot':  {{'off'}} - do not plot anything
        %          'amp'   - plots WT amplitude (i.e. its absolute value) together
        %                    with line denoting the cone of influence
        %          'amp+'  - additionally shows time-averaged WT amplitude
        %          'amp++' - additionally shows 95% range of WT amplitude
        %          'pow','pow+','pow++' - the same but for the WT power (i.e. its
        %                                 squared modulus)
        %           IMPORTANT: to avoid plotting huge data (in which case it might
        %           be very slow to render and modify the figure, and MatLab can
        %           even crash), the plotted WT is resampled to have no more than
        %           few data points displayed per pixel (for the current screen
        %           resolution). Unless 'Display' is 'off', it will always notify if
        %           the WT size exceeds the number of pixels on the plot and the
        %           resamling is therefore performed. Note, that in this case considerable
        %           zooming in of the parts of displayed plots might not show the full
        %           structure of the original WT. If one wants to investigate the
        %           resultant plot in fine details (and not only see how it looks),
        %           then the original, full WT might be displayed without resampling
        %           by adding '-wr' to the end of this option, e.g. 'amp-wr' or 'pow++-wr'.
        % 'Display':{{'on'}} - displays all relevant information about progress etc.
        %            'on-'   - more compact display
        %           'notify' - displays only information if something went wrong
        %            'off'   - does not display anything
        % 'CutEdges':{{'off'}}|'on'
        %           determine should WT coefficients be set to NaNs out of the cone
        %           of influence (see [1]); set it to 'on' if you wish to analyze
        %           only WT within the cone of influence (which is recommended if
        %           you want to estimate e.g. only the time-averaged quantities).
        % ############################## ADVANCED #################################
        % 'Wavelet':{{'Lognorm'}}|'Morlet'|'Bump'|'Morse-a'|{@(xi)fwt(xi),[xi1,xi2],@(t)twf(t),[t1,t2]}
        %            wavelet used in WT calculation, for a list of all supported
        %            names and their properties see Appendix E in [1]. However, you
        %            can use any wavelet by specifying its frequency domain form,
        %            i.e. wavelet FT (defined by function [fwt], the argument of
        %            which is cyclic frequency) and/or time-domain form [twf] (the
        %            argument is time) together with corresponding full supports;
        %            only [fwt] or [twf] is enough, so if one of them is not known
        %            just put empty field [] for it and its support (but it is
        %            better to specify both [fwt] and [twf] when available). Thus,
        %            the Lognormal wavelet with [f0=1] can be alternatively
        %            defined as {@(xi)exp(-(6^2/2)*(log(xi)).^2),[0,Inf],[],[]}.
        % 'Preprocess':{{'on'}}|'off'
        %            perform or not an initial signal preprocessing, which consists
        %            of subtracting 3rd order polynomial fit and then bandpassing
        %            the signal in the band of interest [fmin,fmax], for which WT
        %            is calculated
        % 'RelTol':value (default = 0.01) (in [1] commonly referred as \epsilon)
        %            relative tolerance (e.g. 0.01 means 1%), which specifies cone
        %            of influence for WT (i.e. range of WT coefficients which are
        %            determined up to this accuracy in respect of boundary errors);
        %            determines also the minimal number of values to pad signal
        %            with, so that relative contribution of effects of implicit
        %            periodic signal continuation due to convolution in frequency
        %            domain is smaller [RelTol], see [1] for details.
        %
        %
        % OUTPUT:
        % WT   - wavelet transform of the signal [sig],
        %        with rows corresponding to frequencies and columns - to time;
        %        represents FNxL matrix, where FN is the number of frequencies
        %        and L is the length of the signal in samples
        % freq - frequencies corresponding to rows of WT
        % wopt - structure with all parameters of the wavelet and simulation
        %
        %-------------------------------Examples-----------------------------------
        %
        % [WT,freq]=wt(sig,100,'fmin',0.1,'fmax',10,'f0',2)
        % given a signal [sig] sampled at 100 Hz, returns its WT [WT] based on a
        % Lognormal wavelet (default) with [f0=2], calculated for frequencies from
        % 0.1 to 10 Hz, returned in [freq].
        %
        %-----------------------Additional possibilities---------------------------
        %
        % One can also pass the structure with the properties as a third input
        % argument instead of specifying them by pairs, e.g.
        % opts=struct; opts.f0=2; opts.fmax=12; opts.Padding='predictive';
        % [WT,freq]=wt(sig,fs,opts);
        % You can also add further parameters in the usual way, e.g.
        % [WT,freq]=wt(sig,fs,opts,'Display','off');
        % If you add a parameter contained in [opts], it will be ovewritten, e.g.
        % [WT,freq]=wt(sig,fs,opts,'fmax',10);
        % will change 'fmax' property from 12 specified in [opts.fmax] to 10.
        %
        % When one needs to calculate WT using the same wavelet and simulation
        % parameters, but for different signals, it is a good idea to do it like
        % [WT1,freq1,wopt]=wt(sig1,fs1,...(parameters));
        % [WT2,freq2]=wt(sig2,fs2,wopt); [WT3,freq3]=wt(sig3,fs3,wopt); ...
        % This will also avoid recalculating wavelet parameters each time, thus
        % slightly speeding up the computations.
        %
        % The same can be done if you calculated WT of a signal but want to obtain
        % it using some different parameter (e.g. Padding), for example
        % [WT1,freq1,wopt]=wt(sig,fs,'Padding',0,...(other parameters));
        % [WT2,freq2]=wt(sig,fs,wopt,'Padding','predictive');
        % Unless you overwrite 'f0', 'Wavelet' or 'RelTol' properties, the wavelet
        % parameters will not be recalculated for the second time.
        %
        %------------------------------Changelog-----------------------------------
        %
        % v1.01:
        % - improved speed of predictive padding
        % - some minor changes (Optimization/Display)
        %
        %--------------------------------------------------------------------------


        function [WT,freq,varargout] = wt(app, signal,fs,varargin)

            p=1; %WT normalization

            if length(fs)>1 || ~isnumeric(fs) || isnan(fs) || ~isfinite(fs) || fs<=0
                error('Sampling frequency should be single positive finite numerical value.');
            end

            %Default parameters
            Wavelet='Lognorm'; f0=1;
            fmin=[]; fmax=fs/2;
            nv='auto';
            PadMode='predictive';
            RelTol=0.01;
            Preprocess='on';
            DispMode='on';
            PlotMode='off';
            CutEdges='on';
            python=false;

            %Update if user defined
            vst=1; recflag=1;
            if nargin>3 && isstruct(varargin{1})
                copt=varargin{1}; vst=2;
                if isfield(copt,'Display'), cvv=copt.Display; if ~isempty(cvv), DispMode=cvv; end, end
                if isfield(copt,'Wavelet'), cvv=copt.Wavelet; if ~isempty(cvv), Wavelet=cvv; end, end
                if isfield(copt,'f0'), cvv=copt.f0; if ~isempty(cvv), f0=cvv; end, end
                if isfield(copt,'fmin'), cvv=copt.fmin; if ~isempty(cvv), fmin=cvv; end, end
                if isfield(copt,'fmax'), cvv=copt.fmax; if ~isempty(cvv), fmax=cvv; end, end
                if isfield(copt,'nv'), cvv=copt.nv; if ~isempty(cvv), nv=cvv; end, end
                if isfield(copt,'Padding'), cvv=copt.Padding; if ~isempty(cvv), PadMode=cvv; end, end
                if isfield(copt,'RelTol'), cvv=copt.RelTol; if ~isempty(cvv), RelTol=cvv; end, end
                if isfield(copt,'Preprocess'), cvv=copt.Preprocess; if ~isempty(cvv), Preprocess=cvv; end, end
                if isfield(copt,'Plot'), cvv=copt.Plot; if ~isempty(cvv), PlotMode=cvv; end, end
                if isfield(copt,'CutEdges'), cvv=copt.CutEdges; if ~isempty(cvv), CutEdges=cvv; end, end
                if isfield(copt,'wp')
                    recflag=0;
                    for vn=vst:2:nargin-2
                        if strcmpi(varargin{vn},'Window') && ~isempty(varargin{vn+1}), recflag=1; end
                        if strcmpi(varargin{vn},'f0') && ~isempty(varargin{vn+1}), recflag=1; end
                        if strcmpi(varargin{vn},'RelTol') && ~isempty(varargin{vn+1}), recflag=1; end
                    end
                    wp=copt.wp;
                end
                if isfield(copt,'nvsim') && recflag==1, cvv=copt.nvsim; if ~isempty(cvv), nv=cvv; end, end
                if isfield(copt,'python'), cvv=copt.python; if ~isempty(cvv), python=cvv; end, end
            end

            for vn=vst:2:nargin-3
                if strcmpi(varargin{vn},'Display'), if ~isempty(varargin{vn+1}), DispMode=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Wavelet'), if ~isempty(varargin{vn+1}), Wavelet=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'f0'), if ~isempty(varargin{vn+1}), f0=varargin{vn+1}; end


                elseif strcmpi(varargin{vn},'fmin'), if ~isempty(varargin{vn+1}), fmin=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'fmax'), if ~isempty(varargin{vn+1}), fmax=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'nv'), if ~isempty(varargin{vn+1}), nv=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Padding'), if ~isempty(varargin{vn+1}), PadMode=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'RelTol'), if ~isempty(varargin{vn+1}), RelTol=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Preprocess'), if ~isempty(varargin{vn+1}), Preprocess=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'Plot'), if ~isempty(varargin{vn+1}), PlotMode=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'CutEdges'), if ~isempty(varargin{vn+1}), CutEdges=varargin{vn+1}; end
                elseif strcmpi(varargin{vn},'python'), if ~isempty(varargin{vn+1}), python=varargin{vn+1}; end
                else
                    try
                        error(['There is no Property ''',varargin{vn},'''']);
                    catch
                    end
                end
            end

            if isnan(fmin)
                fmin = [];
            end
            if isnan(fmax)
                fmax = fs / 2;
            end

            if isnan(f0)
                f0 = 1;
            end

            L=length(signal);
            signal=signal(:);%converts the matrix into a line

            %========================= Wavelet function ===============================
            %[fwt] and [twf] - wavelet function in frequency and time
            %wp - structure with wavelet parameters containing fields:
            %     xi1,xi2 - wavelet full support in the frequency domain;
            %     ompeak,tpeak - wavelet peak frequency and peak time
            %     t1,t2 - wavelet full support in the time domain;
            %     fwtmax,twfmax - maximum value of abs(fwt) and abs(twf);
            %     C,D - coefficients needed for reconstruction
            %--------------------------------------------------------------------------
            fwt=[]; twf=[];
            if recflag==1
                wp=struct;
                wp.fwtmax=[]; wp.twfmax=[]; wp.C=[]; wp.D=[];
                wp.xi1=-Inf; wp.xi2=Inf; wp.ompeak=[];
                wp.t1=-Inf; wp.t2=Inf; wp.tpeak=[];
            end
            if iscell(Wavelet)
                fwt=Wavelet{1};
                if length(Wavelet{2})==2, wp.xi1=Wavelet{2}(1); wp.xi2=Wavelet{2}(2); end
                twf=Wavelet{3};
                if length(Wavelet{4})==2, wp.t1=Wavelet{4}(1); wp.t2=Wavelet{4}(2); end
            elseif strcmpi(Wavelet,'Lognorm')
                q=2*pi*f0;
                fwt=@(xi)exp(-(q^2/2)*(log(xi).^2));
                wp.xi1=0; wp.ompeak=1;
                wp.C=sqrt(pi/2)/q; wp.D=wp.C*exp(1/(2*q^2));
            elseif strcmpi(Wavelet,'Morlet')
                om0=2*pi*f0; %just for convenience denote circular central frequency
                fwt=@(xi)(exp(-(1/2)*(om0-xi).^2) -exp(-(1/2)*(om0^2+xi.^2)));
                if f0>=1, twf=@(t)(1/sqrt(2*pi))*(exp(1i*om0*t)-exp(-om0^2/2)).*exp(-t.^2/2); end
                wp.D=Inf;
            elseif strcmpi(Wavelet,'Bump')
                q=2.5*f0;
                if q<1, error('For Bump wavelet f0 cannot be lower than 0.4'); end
                fwt=@(xi)exp(1-abs(1./(1-(q^2)*(1-xi).^2)));
                wp.xi1=max([0,1-1/q]); wp.xi2=1+1/q;
                wp.ompeak=1;
            elseif ~isempty(strfind(lower(Wavelet),'morse'))
                a=3; if length(Wavelet)>5, a=str2double(Wavelet(7:length(Wavelet))); end
                q=30*f0^2/a;
                B=(exp(1)*a/q).^(q/a);
                fwt=@(xi)exp(-xi.^a+q*(log(xi)+(1/a)*log(exp(1)*a/q))); wp.xi1=0;
                wp.ompeak=(q/a)^(1/a);
                wp.fwtmax=fwt(wp.ompeak); wp.C=(1/2)*(B/a)*gamma(q/a);
                wp.D=Inf; if q>1, wp.D=(wp.ompeak/2)*(B/a)*gamma((q-1)/a); end
            else
                error('Invalid wavelet name');
            end
            %==========================================================================

            %Determine parameters of the wavelet function (and nv if needed)
            if recflag==1
                if strcmpi(DispMode,'on'), fprintf('Estimating wavelet parameters...\n'); end
                parcalc(RelTol); %calculate/update wavelet parameters
            end
            if isempty(fmin), fmin=(wp.ompeak/2/pi)*(wp.t2e-wp.t1e)*fs/L; end %if not specified, determine minimum possible WT frequency for accuracy RelTol
            if fmin>fmax, error('Minimal frequency %1.2e, either specified by you or determined automatically from the cone-of-influence, exceeds maximal frequency %1.2e!',fmin,fmax); end


            %Produce warning if there are no WT in the cone-of-influence
            if (wp.t2e-wp.t1e)*wp.ompeak/(2*pi*fmax)>L/fs
                if ~strcmpi(DispMode,'off')
                    fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                    wstr='';
                    wstr=[wstr,sprintf('For used wavelet function parameters and signal time-length there are no WT coefficients determined with specified accuracy %1.2e.\n',RelTol)];
                    wstr=[wstr,sprintf('Transform is inaccurate under the specified precision. Either allow lower accuracy by increasing ''RelTol'', or consider changing wavelet resolution parameter(s).\n')];
                    if strcmpi(CutEdges,'on')
                        wstr=[wstr,sprintf('Continuing without cone-of-influence.\n')];
                    end
                    fprintf(wstr);
                    fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                end
                if strcmpi(CutEdges,'on'), CutEdges='off'; end
            elseif (wp.t2e-wp.t1e)*wp.ompeak/(2*pi*fmin)/(L/fs)>1+2*eps
                if ~strcmpi(DispMode,'off')
                    fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                    wstr='';
                    wstr=[wstr,sprintf('At lowest frequency %1.2e there are no WT coefficients determined with specified accuracy %1.2e.\n',fmin,RelTol)];
                    wstr=[wstr,sprintf('There is nothing crucial in this, but WT computation might be slower due to using more padding.\n')];
                    wstr=[wstr,sprintf('If you want to use only WT from the cone of influence (i.e. determined with specified precision),\n')];
                    wstr=[wstr,sprintf('then better increase the minimal frequency to at least %1.2e or change wavelet resolution parameter(s).\n',(wp.t2e-wp.t1e)*(wp.ompeak/2/pi)/(L/fs))];
                    fprintf(wstr);
                    fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                end
            end


            %Define frequencies
            nvsim=nv; wp.nv=nv;
            if ~isempty(strfind(nv,'auto')) %determine number-of-voices [nv] if needed
                Nb=10; if length(nv)>4, Nb=str2double(nv(6:length(nv))); end
                wp.nv=Nb*log(2)/log(wp.xi2h/wp.xi1h);
                nv=ceil(wp.nv);
                if strcmpi(DispMode,'on')
                    fprintf('Optimal nv ("number-of-voices" needed for frequency binning) was determined to be %0.2f (rounded to %d)\n',wp.nv,ceil(wp.nv));
                end
            end
            freq=2.^((ceil(nv*log2(fmin)):floor(nv*log2(fmax)))'/nv); % frequencies (exact values depend only on [nv])
            SN=length(freq); %number of frequencies
            coib1=ceil(abs(wp.t1e*fs*(wp.ompeak./(2*pi*freq)))); coib2=ceil(abs(wp.t2e*fs*(wp.ompeak./(2*pi*freq)))); %cone of influence edges


            %======== Signal preprocessing: detrending, filtering and padding =========
            %[dflag] determines to do detrending and filtering before or after padding
            dflag=0;
            if ~iscell(PadMode)
                if ~ischar(PadMode) && ~isempty(PadMode(PadMode~=0)), dflag=1; end
                if strcmpi(PadMode,'predictive') && fmin<5*fs/L, dflag=1; end
            else
                if ~ischar(PadMode{1}) && ~isempty(PadMode{1}(PadMode{1}~=0)), dflag=1; end
                if ~ischar(PadMode{2}) && ~isempty(PadMode{2}(PadMode{2}~=0)), dflag=1; end
                if strcmpi(PadMode{1},'predictive') && fmin<5*fs/L, dflag=1; end
                if strcmpi(PadMode{2},'predictive') && fmin<5*fs/L, dflag=1; end
            end
            %Detrend (subtract third-order polynomial fit) and filter first for usual padding
            if strcmpi(Preprocess,'on') && dflag==0
                %Detrending
                X=(1:length(signal))'/fs;
                XM=ones(length(X),4);
                for pn=1:3,
                    CX=X.^pn;
                    XM(:,pn+1)=(CX-mean(CX))/std(CX);
                end
                w=warning('off','all');

                signal=signal-XM*(pinv(XM)*signal);
                warning(w);

                %Filtering
                fx=fft(signal,L); % Fourier transform of a signal
                Nq=ceil((L+1)/2);
                ff=[(0:Nq-1),-fliplr(1:L-Nq)]*fs/L;
                ff=ff(:); % frequencies in Fourier transform
                fx(abs(ff)<=max([fmin,fs/L]) | abs(ff)>=fmax)=0; % filter signal in a chosen frequency domain

                signal=ifft(fx);
            end
            %Padding
            NL=2^nextpow2(L+coib1(1)+coib2(1));
            if coib1(1)==0 && coib2(1)==0, n1=floor((NL-L)/2); n2=ceil((NL-L)/2);
            else
                n1=floor((NL-L)*coib1(1)/(coib1(1)+coib2(1)));
                n2=ceil((NL-L)*coib2(1)/(coib1(1)+coib2(1)));
            end
            if strcmpi(DispMode,'on')
                if strcmpi(Preprocess,'on')
                    fprintf('Signal preprocessing (detrending, then filtering) and padding (%d values to the left and %d to the right)...\n',n1,n2);
                else
                    fprintf('Padding (%d values to the left and %d to the right)...\n',n1,n2);
                end
            end
            if iscell(PadMode)
                p1=PadMode{1}; p2=PadMode{2};
                %Padding to the left
                if strcmpi(p1,'predictive') || strcmpi(p2,'predictive')
                    if strcmpi(DispMode,'on'), fprintf('Applying predictive padding: '); end
                    dflag=1;
                end
                if ~ischar(p1) && length(p1)>1
                    p1=p1(:); PL1=length(p1);
                    padleft=[zeros(n1-PL1,1);p1(PL1-min([n1,PL1])+1:PL1)];
                elseif strcmpi(p1,'predictive')
                    if strcmpi(DispMode,'on'), fprintf('\b to the left - '); end
                    w=2.^(-(L/fs-(1:L)/fs)/(wp.t2h-wp.t1h));
                    padleft=fcast(app,flipud(signal),fs,n1,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode); padleft=flipud(padleft);
                    if strcmpi(DispMode,'on'), fprintf(';\n'); end
                elseif isnumeric(p1), padleft=p1*ones(n1,1);
                elseif strcmpi(p1,'symmetric'), padleft=[zeros(n1-L,1);flipud(signal(1:min([n1,L])))];
                elseif strcmpi(p1,'periodic'), padleft=[zeros(n1-L,1);signal(L-min([n1,L])+1:L)];
                else error('Bad ''Padding'' property');
                end
                %Padding to the right
                if ~ischar(p2) && length(p2)>1
                    p2=p2(:); PL2=length(p2);
                    padright=[p2(1:min([n2,PL2]));zeros(n2-PL2,1)];
                elseif strcmpi(p2,'predictive')
                    if strcmpi(DispMode,'on'), fprintf('\b to the right - '); end
                    w=2.^(-(L/fs-(1:L)/fs)/(wp.t2h-wp.t1h));
                    padright=fcast(app,signal,fs,n2,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode);
                    if strcmpi(DispMode,'on'), fprintf(';\n'); end
                elseif isnumeric(p2), padright=p2*ones(n2,1);
                elseif strcmpi(p2,'symmetric'), padright=[flipud(signal(L-min([n2,L])+1:L));zeros(n2-L,1)];
                elseif strcmpi(p2,'periodic'), padright=[signal(1:min([n2,L]));zeros(n2-L,1)];
                else error('Bad ''Padding'' property');
                end
            elseif strcmpi(PadMode,'predictive')
                if strcmpi(DispMode,'on'), fprintf('Applying predictive padding: '); end
                w=2.^(-(L/fs-(1:L)/fs)/(wp.t2h-wp.t1h));
                if strcmpi(DispMode,'on'), fprintf('\b to the left - '); end
                padleft=fcast(app,flipud(signal),fs,n1,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode); padleft=flipud(padleft);
                if strcmpi(DispMode,'on'), fprintf('; to the right - '); end
                padright=fcast(app,signal,fs,n2,[max([fmin,fs/L]),fmax],min([ceil(SN/2)+5,round(L/3)]),w,DispMode);
                if strcmpi(DispMode,'on'), fprintf(';\n'); end
                dflag=1; %to detrend one more time
            elseif isnumeric(PadMode)
                padleft=PadMode*ones(n1,1); padright=PadMode*ones(n2,1); %padding with predefined values (by default with zeros)
            elseif strcmpi(PadMode,'symmetric'),
                padleft=[zeros(n1-L,1);flipud(signal(1:min([n1,L])))]; padright=[flipud(signal(L-min([n2,L])+1:L));zeros(n2-L,1)]; %symmetric padding
            elseif strcmpi(PadMode,'periodic'),
                padleft=[zeros(n1-L,1);signal(L-min([n1,L])+1:L)]; padright=[signal(1:min([n2,L]));zeros(n2-L,1)]; %periodic padding
            elseif strcmpi(PadMode,'none')
                NL=L; n1=0; n2=0; padleft=[]; padright=[]; %no padding (quite equivalent to periodic padding)
            else
                error('Bad ''Padding'' property');
            end
            signal=[padleft;signal;padright];
            %Detrend (subtract third-order polynomial fit) after padding for special cases
            if strcmpi(Preprocess,'on') && dflag==1
                X=(1:length(signal))'/fs; XM=ones(length(X),4); for pn=1:3, CX=X.^pn; XM(:,pn+1)=(CX-mean(CX))/std(CX); end
                w=warning('off','all'); signal=signal-XM*(pinv(XM)*signal); warning(w);
            end
            %Filtering of the padded signal
            Nq=ceil((NL+1)/2);

            ff=[(0:Nq-1),-fliplr(1:NL-Nq)]*fs/NL;
            ff=ff(:); % frequencies in Fourier transform

            fx=fft(signal,NL); % Fourier transform of a signal (DON'T set to zero at negative frequencies)
            if strcmpi(Preprocess,'on')
                fx(ff<=max([fmin,fs/L]) | ff>=fmax)=0; % filter signal in a chosen frequency domain
            end
            %--------------------------------------------------------------------------


            %Wavelet transform by itself
            WT=zeros(SN,L)*NaN; ouflag=0; if (wp.t2e-wp.t1e)*wp.ompeak/(2*pi*fmax)>L/fs, coib1(:)=0; coib2(:)=0; end
            if ~isempty(strfind(lower(DispMode),'on')), pos=0; fprintf('Calculating Wavelet Transform (%d frequencies from %0.3f to %0.3f): ',SN,freq(1),freq(end)); end

            for sn=1:SN
                freqwf=ff*wp.ompeak/(2*pi*freq(sn)); %frequencies for the wavelet function
                ii=find(freqwf>wp.xi1/2/pi & freqwf<wp.xi2/2/pi); %take into account only frequencies within the wavelet support
                if ~isempty(fwt)
                    fw=conj(fwt(2*pi*freqwf(ii))); nid=find(isnan(fw) | ~isfinite(fw));
                    if ~isempty(nid) %to avoid NaNs due to numerics, e.g. sin(0)/0
                        fw(nid)=conj(fwt(2*pi*freqwf(ii(nid))+10^(-14)));
                        nid=find(isnan(fw) | ~isfinite(fw)); fw(nid)=0;
                        if ~isempty(nid), ouflag=1; ouval=2*pi*freqwf(nid(1)); end
                    end
                else
                    timewf=(2*pi*freq(sn)/wp.ompeak)*(1/fs)*[-(1:ceil((NL-1)/2))+1,NL+1-(ceil((NL-1)/2)+1:NL)]';
                    jj=find(timewf>wp.t1 & timewf<wp.t2); tw=zeros(NL,1); %take into account only times within the wavelet support
                    tw(jj)=conj(twf(timewf(jj))); nid=find(isnan(tw) | ~isfinite(tw));
                    if ~isempty(nid) %to avoid NaNs due to numerics, e.g. sin(0)/0
                        tw(nid)=conj(twf(timewf(nid)+10^(-14)));
                        nid=find(isnan(tw) | ~isfinite(tw)); tw(nid)=0;
                        if ~isempty(nid), ouflag=1; ouval=timewf(nid(1)); end
                    end
                    fw=(1/fs)*fft(tw); fw=fw(ii);
                end
                cc=zeros(NL,1);
                cc(ii)=fx(ii).*fw(:); %convolution in the frequency domain

                out=((wp.ompeak/(2*pi*freq(sn)))^(1-p))*ifft(cc,NL); % calculate WT at each time
                WT(sn,1:L)=out(1+n1:NL-n2);

                if ~isempty(strfind(lower(DispMode),'on')) && floor(100*sn/SN)>floor(100*(sn-1)/SN)
                    cstr=num2str(floor(100*sn/SN)); fprintf([repmat('\b',1,pos),cstr,'%%']); pos=length(cstr)+1;
                end

            end
            if ~isempty(strfind(lower(DispMode),'on')), fprintf('\n'); end
            if ouflag==1
                if ~isempty(fwt)
                    fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                    fprintf('Possibly overflow/underflow (e.g. Inf/Inf): specified frequency-domain form of wavelet function\n');
                    fprintf('returns NaN or Inf, e.g. when its argument is %e. In all such cases it is set to zero.\n',ouval);
                    fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                else
                    fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                    fprintf('Possibly overflow/underflow (e.g. Inf/Inf): specified time-domain form of wavelet function\n');
                    fprintf('returns NaN or Inf, e.g. when its argument is %e. In all such cases it is set to zero.\n',ouval);
                    fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                end
            end

            %Set to NaN all WT coefficients outside the cone of influence if specified
            if strcmpi(CutEdges,'on')
                icoib=find(L-coib1-coib2<=0); WT(icoib,:)=NaN;
                ovL=ceil(sum(coib1+coib2)-L*length(icoib)); frn=zeros(ovL,1)*NaN; ttn=zeros(ovL,1)*NaN; qn=0;
                for fn=1:SN, cL=coib1(fn)+coib2(fn);
                    if cL>0 && cL<L, frn(1+qn:qn+cL)=fn; ttn(1+qn:qn+cL)=[1:coib1(fn),(L-coib2(fn)+1):L]'; qn=qn+cL; end
                end
                frn=frn(1:qn); ttn=ttn(1:qn);
                lid=sub2ind([SN,L],frn,ttn); WT(lid)=NaN;
            end

            %Plotting WT if specified
            if ~strcmpi(PlotMode,'off')
                scrsz=get(0,'ScreenSize');
                % figure('Position',[scrsz(3)/4,scrsz(4)/8,scrsz(3)/2,6*scrsz(4)/8]);
                %     axes('Position',[0.15,0.1,0.8,0.5333],'Layer','top','XScale','log','Box','on','FontSize',16);
                %     hold all;

                YY=freq; XX=(0:(L-1))/fs; ZZ=abs(WT); ZZname='WT amplitude';
                if ~isempty(strfind(lower(PlotMode),'pow')), ZZ=ZZ.^2; ZZname='WT power'; end
                MYL=round(scrsz(3)); MXL=round(scrsz(4)); %maximum number of points seen in plots
                if isempty(strfind(lower(PlotMode),'wr')) && (size(ZZ,1)>MYL || size(ZZ,2)>MXL)
                    if strcmpi(DispMode,'on')
                        fprintf('Plotting: WT contains more data points (%d x %d) than pixels in the plot, so for a\n',size(ZZ,1),size(ZZ,2));
                        fprintf('          better performance its resampled version (%d x %d) will be displayed instead.\n',min([MYL,size(ZZ,1)]),min([MXL,size(ZZ,2)]));
                    end
                    if size(ZZ,1)>MYL, YY=exp(linspace(log(freq(1)),log(freq(end)),MYL)); end
                    if size(ZZ,2)>MXL, XX=linspace(0,(L-1)/fs,MXL); end
                    ZZ=aminterp(app,(0:(L-1))/fs,log(freq),ZZ,XX,log(YY),'max'); XX=XX(:); YY=YY(:);
                    ZZ = transpose(ZZ);
                end
                TL=length(XX);
                FL=length(YY);
                pc=pcolor(app.UIAxes3,XX,YY,ZZ');

                set(pc,'EdgeColor','none'); %title(ZZname);
                %     set(app.UIAxes3,'YScale','log');
                %
                %     xlabel('Frequency (Hz)'); ylabel('Time (s)');
                ylim([0,(L-1)/fs]); xlim([freq(1),freq(end)]);

                coib1(coib1==0)=NaN; coib2(coib2==0)=NaN;
                ib=find(L-coib1-coib2>0,1,'first'); if isempty(ib), ib=1; end
                plot( freq(end:-1:ib),coib1(end:-1:ib)/fs,'-k','LineWidth',2);
                plot(freq(ib:end),(L-coib2(ib:end)+1)/fs,'-k','LineWidth',2);
                plot( [freq(ib),freq(ib)],[coib1(ib)/fs,(L-coib2(ib)+1)/fs],'-k','LineWidth',2);
                coib1(isnan(coib1))=0; coib2(isnan(coib2))=0; if strcmpi(CutEdges,'off'), coib1(:)=0; coib2(:)=0; end

                if ~isempty(strfind(lower(PlotMode),'+'))
                    axes('Position',[0.15,0.7,0.8,0.25],'Layer','top','XLim',[freq(1),freq(end)],'XScale','log','XTickLabel',{},'Box','on','FontSize',16);
                    hold all;
                    mx=zeros(FL,1); for fn=1:FL, mx(fn)=mean(ZZ(fn,~isnan(ZZ(fn,:))),2); end
                    mline=plot( YY,mx,'-k','LineWidth',2); ylabel({'Time-averaged',ZZname});
                    if max(mx)>0, ylim([0,1.1*max(mx)]); end

                    if ~isempty(strfind(lower(PlotMode),'++'))
                        sZZ=sort(ZZ,2); ZL=zeros(FL,1)*NaN;
                        for fn=1:FL, uid=find(~isnan(sZZ(fn,:)),1,'last'); if ~isempty(uid), ZL(fn)=uid; end, end
                        lx=zeros(FL,1)*NaN; ux=zeros(FL,1)*NaN;
                        for fn=1:FL
                            if ~isnan(ZL(fn))
                                lx(fn)=sZZ(fn,max([1,round(0.025*ZL(fn))]));
                                ux(fn)=sZZ(fn,round(0.975*ZL(fn)));
                            end
                        end
                        idnn=find(~isnan(lx)); % not-NaN indices
                        hold all; fill([YY(idnn);flipud(YY(idnn))],[lx(idnn);flipud(ux(idnn))],[1,1,0],'FaceAlpha',0.5);
                        if max(ux)>0, ylim([0,1.1*max(ux)]); end
                        uistack(mline,'top');
                    end
                end
            end

            if nargout>2
                wopt=struct; %simulation parameters

                % Saving these values messes up the conversion back to Python data types,
                % so avoid if called from Python.
                if ~python
                    wopt.wp=wp; %parameters of the wavelet
                    wopt.PadLR={padleft,padright};
                else
                    wp.fwt = func2str(wp.fwt);
                    varargout{2} = wp;
                end

                wopt.TFRname='WT';
                wopt.fs=fs;
                wopt.Wavelet=Wavelet;
                wopt.f0=f0;
                wopt.fmin=fmin;
                wopt.fmax=fmax;
                wopt.nv=nv; wopt.nvsim=nvsim;
                wopt.Padding=PadMode;
                wopt.RelTol=RelTol;
                wopt.Preprocess=Preprocess;
                wopt.Plot=PlotMode;
                wopt.Display=DispMode;
                wopt.CutEdges=CutEdges;

                varargout{1}=wopt;
            end


            %----------------------------------------------------------------------------------------------------------------------
            %======================================================================================================================
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %======================================================================================================================
            %----------------------------------------------------------------------------------------------------------------------
            % Based on wavelet function in time [twf] and frequency [fwt],
            % determines wavelet parameters such as constant [Cpsi],
            % peaks in time [tpeak] and frequency [ompeak],
            % epsilon-support in frequency [xi1e,xi2e] and in time [t1e,t2e],
            % 50%-support in frequency [xi1h,xi2h] and time [t1h,t2h],
            % time-frequency resolution [tfres] (inverse of multiplication of the latter),
            % and number of voices [nv] (if 'auto') for specified relative accuracy [racc] (=epsilon);
            % assigns all these values into the wavelet parameters structure [wp].
            function parcalc(racc)
                racc=min(racc,1-10^(-6)); %current \epsilon
                ctol=max([racc/1000,10^(-12)]); %parameter of numerical accuracy
                MIC=max([10000,10*L]); %maximum interval count for one-time calculations

                %==================================================================
                %Determine values for known frequency and/or time-domain forms
                if ~isempty(fwt) %if the frequency-domain form is known
                    wp.fwt=fwt;
                    if isempty(wp.ompeak) %peak frequency
                        wp.ompeak=1;
                        if strcmpi(Wavelet,'Morlet'), wp.ompeak=2*pi*f0; end

                        if wp.xi1>0 && isfinite(wp.xi2),
                            wp.ompeak=sqrt(wp.xi1*wp.xi2);
                        elseif isfinite(wp.xi2),
                            wp.ompeak=wp.xi2/2;
                        end

                        if fwt(wp.ompeak)==0 || isnan(fwt(wp.ompeak)) || ~isfinite(fwt(wp.ompeak))
                            cp1=wp.ompeak*exp(-10^(-14)); cp2=wp.ompeak*exp(10^(-14)); kk=1;
                            while kk<10^(28)
                                cv1=abs(fwt(cp1));
                                cv2=abs(fwt(cp2));
                                kk=kk*2;

                                if isfinite(cv1) && cv1>0, wp.ompeak=cp1; break; end
                                if isfinite(cv2) && cv2>0, wp.ompeak=cp2; break; end

                                cp1=cp1*exp(-kk*10^(-14));
                                if cp1<=max([wp.xi1,0]), cp1=(cp1*exp(kk*10^(-14))+max([wp.xi1,0]))/2; end

                                cp2=cp2*exp(kk*10^(-14));
                                if cp2>=wp.xi2, cp2=(cp2*exp(-kk*10^(-14))+wp.xi2)/2; end
                            end
                            cv=abs(fwt(wp.ompeak));
                            while isnan(cv) || cv==0 %if search failed
                                if isfinite(wp.xi2), pp=max([wp.xi1,0])+(wp.xi2-max([wp.xi1,0]))*rand(MIC,1);
                                else pp=exp(atan(pi*(rand(MIC,1)-1/2))); end
                                [cv,ipeak]=max(abs(fwt(pp))); wp.ompeak=pp(ipeak);
                            end
                        end
                        wp.ompeak=fminsearch(@(x)-abs(fwt(exp(x))),log(wp.ompeak),optimset('TolX',10^(-14),'Display','off'));
                        wp.ompeak=exp(wp.ompeak);
                    end
                    if isempty(wp.fwtmax)
                        wp.fwtmax=fwt(wp.ompeak);
                        if isnan(wp.fwtmax), wp.fwtmax=fwt(wp.ompeak+10^(-14)); end
                    end
                    vfun=@(u)conj(fwt(exp(u))); xp=log(wp.ompeak); lim1=log(max([wp.xi1,0])); lim2=log(wp.xi2);

                    %Test admissibility %������������������������������������������
                    wstate=warning('off','all');
                    if wp.xi1<=0, AC=fwt(0); else AC=0; end
                    if isnan(AC)
                        cx0=10^(-14);
                        while fwt(cx0)>10^(-14), cx0=cx0/2; end
                        while isnan(fwt(cx0)), cx0=cx0*2; end
                        AC=fwt(cx0);
                    end
                    if AC>10^(-12) && ~strcmpi(DispMode,'off') %����������������������
                        fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                        fprintf(2,'Wavelet does not seem to be admissible (its Fourier transform does not vanish at zero frequency)!\n');
                        fprintf(2,'Parameters estimated from its frequency domain form, e.g. integration constant Cpsi (which is \n');
                        fprintf(2,'infinite for non-admissible wavelets), cannot be estimated appropriately (the same concerns the \n');
                        fprintf(2,'number-of-voices ''nv'', when set to ''auto'', so frequency discretization might be also not appropriate).\n');
                        fprintf(2,'It is recommended to use only admissible wavelets.\n');
                        fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                    end
                    warning(wstate);

                    [QQ,wflag,xx,ss]=sqeps(app,vfun,xp,[lim1,lim2],racc,MIC,...
                        [log((wp.ompeak/fmax)*fs/L/8),log(8*(wp.ompeak/(fs/L))*fs)]); %��������
                    wp.xi1e=exp(ss(1,1)); wp.xi2e=exp(ss(1,2)); wp.xi1h=exp(ss(2,1)); wp.xi2h=exp(ss(2,2));
                    if isempty(wp.C), wp.C=(QQ(1,1)+QQ(1,2))/2; end %��������������

                    if isempty(wp.D) %���������������������������������������������
                        wstate=warning('off','all');
                        [D1,errD1]=quadgk(@(u)conj(fwt(1./u)),1/wp.ompeak,exp(-xx(1,1)),'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        [D2,errD2]=quadgk(@(u)-conj(fwt(1./u)),1/wp.ompeak,exp(-xx(1,2)),'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        [D3,errD3]=quadgk(@(u)conj(fwt(1./u)),exp(-xx(1,1)),exp(-xx(4,1)),'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        [D4,errD4]=quadgk(@(u)-conj(fwt(1./u)),exp(-xx(1,2)),exp(-xx(4,2)),'MaxIntervalCount',2*MIC,'AbsTol',0,'RelTol',10^(-12));
                        if abs((errD1+errD2+errD3+errD4)/(D1+D2+D3+D4))<10^(-4), wp.D=(wp.ompeak/2)*(D1+D2+D3+D4); else wp.D=Inf; end
                        warning(wstate);
                    end %����������������������������������������������������������
                    if wflag==1 && ~strcmpi(DispMode,'off') %����������������������
                        fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                        fprintf('The frequency-domain wavelet function is not well-behaved (e.g. decays very slowly as frequency tends to zero\n');
                        fprintf('or infinity). The integration might be not accurate (and therefore e.g. the calculated number-of-voices ''nv'',\n');
                        fprintf('if set to ''auto'', so frequency discretization might be also not appropriate).\n');
                        fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                    end

                    if isempty(twf) %if time domain form is not known
                        [PP,wflag,xx,ss]=sqeps(app,@(x)abs(fwt(x)).^2,wp.ompeak,[max([wp.xi1,0]),wp.xi2],racc,MIC,...
                            [0,8*(wp.ompeak/(fs/L))*fs]); %�������������������������
                        Etot=sum(PP(1,:))/2/pi;

                        CL=2^nextpow2(MIC/8); CT=CL/(2*abs(ss(1,2)-ss(1,1)));
                        CNq=ceil((CL+1)/2); cxi=(2*pi/CT)*(CNq-CL:CNq-1)'; idm=find(cxi<=max([wp.xi1,0])); idc=find(cxi>max([wp.xi1,0]) & cxi<wp.xi2); idp=find(cxi>=wp.xi2);
                        Cfwt=[zeros(length(idm),1);fwt(cxi(idc));zeros(length(idp),1)];
                        idnan=find(isnan(Cfwt));
                        if ~isempty(idnan), idnorm=find(~isnan(Cfwt)); Cfwt(idnan)=interp1(idnorm,Cfwt(idnorm),idnan,'spline','extrap'); end
                        Ctwf=ifft((CL/CT)*Cfwt([CL-CNq+1:CL,1:CL-CNq]));
                        Ctwf=Ctwf([CNq+1:CL,1:CNq]);

                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(CT/CL)*sum(Etwf); perr=Inf;
                        while (abs(Etot-Eest)+Iest1+Iest2)/Etot<=perr
                            CT=CT/2; perr=(abs(Etot-Eest)+Iest1+Iest2)/Etot;
                            CNq=ceil((CL+1)/2); cxi=(2*pi/CT)*(CNq-CL:CNq-1)'; idm=find(cxi<=max([wp.xi1,0])); idc=find(cxi>max([wp.xi1,0]) & cxi<wp.xi2); idp=find(cxi>=wp.xi2);
                            Cfwt=[zeros(length(idm),1);fwt(cxi(idc));zeros(length(idp),1)]; idnan=find(isnan(Cfwt));
                            if ~isempty(idnan), idnorm=find(~isnan(Cfwt)); Cfwt(idnan)=interp1(idnorm,Cfwt(idnorm),idnan,'spline','extrap'); end
                            Ctwf=ifft((CL/CT)*Cfwt([CL-CNq+1:CL,1:CL-CNq])); Ctwf=Ctwf([CNq+1:CL,1:CNq]);
                            Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                            Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                            Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                            Eest=(CT/CL)*sum(Etwf);
                        end
                        CL=16*CL; CT=CT*2;
                        CNq=ceil((CL+1)/2); cxi=(2*pi/CT)*(CNq-CL:CNq-1)'; idm=find(cxi<=max([wp.xi1,0])); idc=find(cxi>max([wp.xi1,0]) & cxi<wp.xi2); idp=find(cxi>=wp.xi2);
                        Cfwt=[zeros(length(idm),1);fwt(cxi(idc));zeros(length(idp),1)]; idnan=find(isnan(Cfwt));
                        if ~isempty(idnan), idnorm=find(~isnan(Cfwt)); Cfwt(idnan)=interp1(idnorm,Cfwt(idnorm),idnan,'spline','extrap'); end
                        Ctwf=ifft((CL/CT)*Cfwt([CL-CNq+1:CL,1:CL-CNq])); Ctwf=Ctwf([CNq+1:CL,1:CNq]);
                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(CT/CL)*sum(Etwf);

                        if (abs(Etot-Eest)+Iest1+Iest2)/Etot>0.01 && ~strcmpi(DispMode,'off') %�������������������������������������������������
                            fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                            fprintf(['Cannot accurately invert the specified frequency-domain form of the wavelet function to find its\n',...
                                'time domain form and corresponding characteristics (e.g. cone-of-influence borders).\n',...
                                'This might be because the wavelet function decays too slowly in time or frequency.\n']);
                            fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                        end

                        Ctwf=Ctwf(1:2*CNq-3); ct=(CT/CL)*(-(CNq-2):CNq-2)'; %make symmetric
                        wp.twf={Ctwf,ct};
                        Ctwf=Ctwf.*exp(-1i*wp.ompeak*ct); %demodulate (wavelet only) %���������������

                        %Estimate general parameters
                        if isempty(wp.tpeak) %peak time
                            ipeak=find(abs(Ctwf)==max(abs(Ctwf)));
                            if length(ipeak)==1
                                a1=abs(Ctwf(ipeak-1)); a2=abs(Ctwf(ipeak)); a3=abs(Ctwf(ipeak+1));
                                wp.tpeak=ct(ipeak);
                                if abs(a1-2*a2+a3)>2*eps, %use queadratic interpolation to find exact peak location
                                    wp.tpeak=wp.tpeak+(1/2)*(a1-a3)/(a1-2*a2+a3)*(CT/CL);
                                end
                            else
                                wp.tpeak=mean(ct(ipeak));
                            end
                        end
                        if isempty(wp.twfmax)
                            [~,ipeak]=min(abs(ct-wp.tpeak));
                            wp.twfmax=interp1(ct(ipeak-1:ipeak+1),abs(Ctwf(ipeak-1:ipeak+1)),wp.tpeak,'spline');
                        end

                        %Calculate the cumulative integrals
                        ct=[ct-CT/CL/2;ct(end)+CT/CL/2]; %to use midpoint rule
                        CS=(CT/CL)*cumsum(Ctwf); CS=[0;CS(:)]/CS(end); CS=abs(CS);
                        ICS=(CT/CL)*cumsum(Ctwf(end:-1:1)); ICS=ICS(end:-1:1); ICS=[ICS(:);0]/ICS(1); ICS=abs(ICS);

                        %Estimate epsilon-supports
                        xid=find(CS(1:end-1)<racc/2 & CS(2:end)>=racc/2,1,'first');
                        if isempty(xid), wp.t1e=ct(1);
                        else
                            a1=CS(xid)-racc/2; a2=CS(xid+1)-racc/2;
                            wp.t1e=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end
                        xid=find(ICS(1:end-1)>=racc/2 & ICS(2:end)<racc/2,1,'last');
                        if isempty(xid), wp.t2e=ct(end);
                        else
                            a1=ICS(xid)-racc/2; a2=ICS(xid+1)-racc/2;
                            wp.t2e=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end
                        xid=find(CS(1:end-1)<0.25 & CS(2:end)>=0.25,1,'first');
                        if isempty(xid), wp.t1h=ct(1);
                        else
                            a1=CS(xid)-0.25; a2=CS(xid+1)-0.25;
                            wp.t1h=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end
                        xid=find(ICS(1:end-1)>=0.25 & ICS(2:end)<0.25,1,'last');
                        if isempty(xid), wp.t2h=ct(end);
                        else
                            a1=ICS(xid)-0.25; a2=ICS(xid+1)-0.25;
                            wp.t2h=ct(xid)-a1*(ct(xid+1)-ct(xid))/(a2-a1);
                        end

                    end

                end
                %------------------------------------------------------------------
                if ~isempty(twf) %if the time-domain form is known
                    wp.twf=twf;
                    if isempty(wp.tpeak) %peak time
                        wp.tpeak=max([min([0,wp.t2-abs(wp.t2)/2]),wp.t1+abs(wp.t1)/2]);
                        if isfinite(wp.t1) && isfinite(wp.t2), wp.tpeak=(wp.t1+wp.t2)/2; end
                        if twf(wp.tpeak)==0 || isnan(twf(wp.tpeak)) || ~isfinite(twf(wp.tpeak))
                            cp1=wp.tpeak-10^(-14); cp2=wp.tpeak+10^(-14); kk=1;
                            while kk<10^(28)
                                cv1=abs(twf(cp1)); cv2=abs(twf(cp2)); kk=kk*2;
                                if isfinite(cv1) && cv1>0, wp.tpeak=cp1; break; end
                                if isfinite(cv2) && cv2>0, wp.tpeak=cp2; break; end
                                cp1=cp1-kk*10^(-14); if cp1<=wp.t1, cp1=(cp1+kk*10^(-14)+wp.t1)/2; end
                                cp2=cp2+kk*10^(-14); if cp2>=wp.t2, cp2=(cp2-kk*10^(-14)+wp.t2)/2; end
                            end
                            cv=abs(twf(wp.tpeak));
                            while isnan(cv) || cv==0 %if search failed
                                if isfinite(wp.t1) && isfinite(wp.t2), pp=wp.t1+(wp.t2-wp.t1)*rand(MIC,1);
                                elseif isfinite(wp.t1), pp=wp.t1+atan((pi/2)*rand(MIC,1));
                                elseif isfinite(wp.t2), pp=wp.t2-atan((pi/2)*rand(MIC,1));
                                else pp=atan(pi*(rand(MIC,1)-1/2));
                                end
                                [cv,ipeak]=max(abs(twf(pp))); wp.tpeak=pp(ipeak);
                            end
                        end
                        wp.tpeak=fminsearch(@(x)-abs(twf(x)),wp.tpeak,optimset('TolX',10^(-14),'Display','off'));
                    end
                    if isempty(wp.twfmax)
                        wp.twfmax=twf(wp.tpeak);
                        if isnan(wp.twfmax), wp.twfmax=twf(wp.tpeak+10^(-14)); end
                    end

                    %Test admissibility %������������������������������������������
                    wstate=warning('off','all');
                    AC=quadgk(@(u)-twf(u),wp.tpeak,xx(1,1),'MaxIntervalCount',MIC,'AbsTol',10^(-16),'RelTol',0)+...
                        quadgk(@(u)-twf(u),xx(1,1),xx(4,1),'MaxIntervalCount',MIC,'AbsTol',10^(-16),'RelTol',0)+...
                        quadgk(@(u)twf(u),wp.tpeak,xx(1,2),'MaxIntervalCount',MIC,'AbsTol',10^(-16),'RelTol',0)+...
                        quadgk(@(u)twf(u),xx(1,2),xx(4,2),'MaxIntervalCount',MIC,'AbsTol',10^(-16),'RelTol',0);
                    if AC>10^(-8) && ~strcmpi(DispMode,'off') %����������������������
                        fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                        fprintf(2,'Wavelet does not seem to be admissible (its Fourier transform does not vanish at zero frequency)!\n');
                        fprintf(2,'Parameters estimated from its frequency domain form, e.g. integration constant Cpsi (which is \n');
                        fprintf(2,'infinite for non-admissible wavelets), cannot be estimated appropriately (the same concerns the \n');
                        fprintf(2,'number-of-voices ''nv'', when set to ''auto'', so frequency discretization might be also not appropriate).\n');
                        fprintf(2,'It is recommended to use only admissible wavelets.\n');
                        fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                    end
                    warning(wstate);

                    %Calculate the frequency domain characteristics first, if not
                    %known (will be needed afterwards, mainly [wp.ompeak])
                    if isempty(fwt) %if frequency domain form is not known
                        compeak=wp.ompeak;
                        if isempty(compeak) %if not known, roughly estimate the peak frequency
                            [~,~,~,bss]=sqeps(app,@(u)abs(twf(u)).^2,wp.tpeak,[wp.t1,wp.t2],0.01,MIC,[wp.t1,wp.t2]);
                            BL=2^(nextpow2(MIC)); BNq=ceil((BL+1)/2); BT=bss(1,2)-bss(1,1);
                            bt=linspace(bss(1,1),bss(1,2),BL)'; bxi=(2*pi/BT)*[0:BNq-1,BNq-BL:-1]';
                            Bfwt=fft(twf(bt)); ix=find(bxi>max([wp.xi1,0]) & bxi<wp.xi2);
                            [~,imax]=max(abs(Bfwt(ix))); compeak=bxi(ix(imax));
                        end
                        [PP,wflag,xx,ss]=sqeps(app,@(x)abs(twf(x)).^2,wp.tpeak,[wp.t1,wp.t2],racc,MIC,...
                            [-8*(2*pi*fmax/compeak)*L/fs,8*(2*pi*fmax/compeak)*L/fs]); %�����������������������������������
                        Etot=sum(PP(1,:));

                        CL=2^nextpow2(MIC/8); CT=2*abs(ss(1,2)-ss(1,1));
                        CNq=ceil((CL+1)/2); ct=(CT/CL)*(CNq-CL:CNq-1)'; idm=find(ct<=wp.t1); idc=find(ct>wp.t1 & ct<wp.t2); idp=find(ct>=wp.t2);
                        Ctwf=[zeros(length(idm),1);twf(ct(idc));zeros(length(idp),1)]; idnan=find(isnan(Ctwf));
                        if ~isempty(idnan), idnorm=find(~isnan(Ctwf)); Ctwf(idnan)=interp1(idnorm,Ctwf(idnorm),idnan,'spline','extrap'); end
                        Cfwt=(CT/CL)*fft(Ctwf([CL-CNq+1:CL,1:CL-CNq])); Cfwt=Cfwt([CNq+1:CL,1:CNq]);
                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(1/CT)*sum(Efwt); perr=Inf;
                        while (abs(Etot-Eest)+Iest1+Iest2)/Etot<=perr
                            CT=CT*2; perr=(abs(Etot-Eest)+Iest1+Iest2)/Etot;
                            CNq=ceil((CL+1)/2); ct=(CT/CL)*(CNq-CL:CNq-1)'; idm=find(ct<=wp.t1); idc=find(ct>wp.t1 & ct<wp.t2); idp=find(ct>=wp.t2);
                            Ctwf=[zeros(length(idm),1);twf(ct(idc));zeros(length(idp),1)]; idnan=find(isnan(Ctwf));
                            if ~isempty(idnan), idnorm=find(~isnan(Ctwf)); Ctwf(idnan)=interp1(idnorm,Ctwf(idnorm),idnan,'spline','extrap'); end
                            Cfwt=(CT/CL)*fft(Ctwf([CL-CNq+1:CL,1:CL-CNq])); Cfwt=Cfwt([CNq+1:CL,1:CNq]);
                            Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt).^2;
                            Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                            Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                            Eest=(1/CT)*sum(Efwt);
                        end
                        CL=16*CL; CT=CT*2;
                        CNq=ceil((CL+1)/2); ct=(CT/CL)*(CNq-CL:CNq-1)'; idm=find(ct<=wp.t1); idc=find(ct>wp.t1 & ct<wp.t2); idp=find(ct>=wp.t2);
                        Ctwf=[zeros(length(idm),1);twf(ct(idc));zeros(length(idp),1)]; idnan=find(isnan(Ctwf));
                        if ~isempty(idnan), idnorm=find(~isnan(Ctwf)); Ctwf(idnan)=interp1(idnorm,Ctwf(idnorm),idnan,'spline','extrap'); end
                        Cfwt=(CT/CL)*fft(Ctwf([CL-CNq+1:CL,1:CL-CNq]));
                        Etwf=abs(Ctwf).^2; Efwt=abs(Cfwt([CNq+1:CL,1:CNq])).^2;
                        Iest1=(CT/CL)*sum(abs(Etwf(3:end)-2*Etwf(2:end-1)+Etwf(1:end-2)))/24; %error of integration in time
                        Iest2=(1/CT)*sum(abs(Efwt(3:end)-2*Efwt(2:end-1)+Efwt(1:end-2)))/24; %error of integration in frequency
                        Eest=(1/CT)*sum(Efwt);

                        if (abs(Etot-Eest)+Iest1+Iest2)/Etot>0.01 && ~strcmpi(DispMode,'off') %�������������������������������������������������
                            fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                            fprintf(['Cannot accurately invert the specified time-domain form of the wavelet function to find its\n',...
                                'frequency-domain form and corresponding characteristics (e.g. optimal number-of-voices ''nv'').\n',...
                                'This might be because the wavelet function decays too slowly in time or frequency.\n']);
                            fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                        end

                        Cfwt=Cfwt(2:CNq); cxi=(2*pi/CT)*(1:CNq-1)'; %take only positive frequencies
                        if 2*abs((CT/CL)*sum(Ctwf))<ctol %represent low-frequency structure
                            Atot=abs((2*pi/CT)*sum(Cfwt./cxi));
                            cxi0=cxi(1); Cfwt0=(CT/CL)*sum(Ctwf.*exp(-1i*cxi0*ct));
                            axi=NaN*zeros(CL,1); Afwt=NaN*zeros(CL,1); kn=1;
                            while 2*abs(Cfwt0)/Atot>ctol
                                cxi0=cxi0/2; Cfwt0=(CT/CL)*sum(Ctwf.*exp(-1i*cxi0*ct));
                                axi(kn)=cxi0; Afwt(kn)=Cfwt0; kn=kn+1;
                            end
                            axi=axi(1:kn-1); Afwt=Afwt(1:kn-1);
                        else
                            ix=min([1+find(diff(abs(Cfwt(2:end)))<=0,1,'first'),length(Cfwt)]);
                            cxi0=interp1([0;abs(Cfwt(1:ix))],[0;cxi(1:ix)],ctol/2,'spline');
                            axi=[]; Afwt=[];
                        end
                        CS0=interp1([0;cxi],[0;Cfwt],cxi0/4,'spline'); CS0=2*CS0; %initial cumulative sum

                        %Move to the logarithmic frequency scale
                        [~,imxi]=max(abs(Cfwt)); %peak position
                        bxi1=linspace(log(cxi0),log(cxi(1)),ceil(2*CL/3))'; zxi1=(bxi1(1:end-1)+bxi1(2:end))/2;
                        bxi2=linspace(log(cxi(1)),log(cxi(imxi)),ceil(2*CL/3))'; zxi2=(bxi2(1:end-1)+bxi2(2:end))/2;
                        bxi3=linspace(log(cxi(imxi)),log(cxi(end)),ceil(2*CL/3))'; zxi3=(bxi3(1:end-1)+bxi3(2:end))/2;
                        zxi=[zxi1;zxi2;zxi3]; bxi=[bxi1(1:end-1);bxi2(1:end-1);bxi3]; dbxi=diff(bxi);
                        Zfwt=interp1([0;axi;cxi],[0;Afwt;Cfwt],exp(zxi),'spline');
                        wp.fwt={Zfwt,exp(zxi)};

                        %Estimate general parameters
                        if isempty(wp.ompeak) %peak frequency
                            ipeak=find(abs(Zfwt)==max(abs(Zfwt)));
                            if length(ipeak)==1
                                a1=abs(Zfwt(ipeak-1)); a2=abs(Zfwt(ipeak)); a3=abs(Zfwt(ipeak+1));
                                wp.ompeak=zxi(ipeak);
                                if abs(a1-2*a2+a3)>2*eps, %use queadratic interpolation to find exact peak location
                                    wp.ompeak=wp.ompeak+(1/2)*(a1-a3)/(a1-2*a2+a3)*dbxi(ipeak);
                                end
                            else
                                wp.ompeak=mean(zxi(ipeak));
                            end
                            wp.ompeak=exp(wp.ompeak);
                        end
                        if isempty(wp.fwtmax)
                            [~,ipeak]=min(abs(cxi-wp.ompeak));
                            wp.fwtmax=interp1(cxi(ipeak-1:ipeak+1),abs(Cfwt(ipeak-1:ipeak+1)),wp.ompeak,'spline');
                        end
                        if isempty(wp.C), wp.C=(1/2)*sum(conj(Zfwt).*dbxi); end %���������������������������������
                        if isempty(wp.D) %�����������������������������������������
                            wp.D=Inf;
                            if abs(Zfwt(2)/Zfwt(1))>exp(zxi(2)-zxi(1)) %determine if Dpsi is finite, i.e. fwt\sim\xi^(1+a>0) when xi->0
                                wp.D=(wp.ompeak/2)*sum(exp(-zxi).*conj(Zfwt).*dbxi);
                            end
                        end %������������������������������������������������������

                        %Calculate the cumulative integrals
                        CS=CS0+cumsum(Zfwt.*dbxi); CS=[CS0;CS(:)]/CS(end); CS=abs(CS);
                        ICS=cumsum(flipud(Zfwt.*dbxi)); ICS=ICS(end:-1:1); ICS=[ICS(:);0]/(ICS(1)+CS0); ICS=abs(ICS);

                        %Estimate epsilon-supports
                        xid=find(CS(1:end-1)<racc/2 & CS(2:end)>=racc/2,1,'first');
                        if isempty(xid), wp.xi1e=exp(bxi(1));
                        else
                            a1=CS(xid)-racc/2; a2=CS(xid+1)-racc/2;
                            wp.xi1e=exp(bxi(xid)-a1*(bxi(xid+1)-bxi(xid))/(a2-a1));
                        end
                        xid=find(ICS(1:end-1)>=racc/2 & ICS(2:end)<racc/2,1,'last');
                        if isempty(xid), wp.xi2e=exp(bxi(end));
                        else
                            a1=ICS(xid)-racc/2; a2=ICS(xid+1)-racc/2;
                            wp.xi2e=exp(bxi(xid)-a1*(bxi(xid+1)-bxi(xid))/(a2-a1));
                        end
                        xid=find(CS(1:end-1)<0.25 & CS(2:end)>=0.25,1,'first');
                        if isempty(xid), wp.xi1h=exp(bxi(1));
                        else
                            a1=CS(xid)-0.25; a2=CS(xid+1)-0.25;
                            wp.xi1h=exp(bxi(xid)-a1*(bxi(xid+1)-bxi(xid))/(a2-a1));
                        end
                        xid=find(ICS(1:end-1)>=0.25 & ICS(2:end)<0.25,1,'last');
                        if isempty(xid), wp.xi2h=exp(bxi(end));
                        else
                            a1=ICS(xid)-0.25; a2=ICS(xid+1)-0.25;
                            wp.xi2h=exp(bxi(xid)-a1*(bxi(xid+1)-bxi(xid))/(a2-a1));
                        end

                    end

                    %Return to the time-domain form (we needed to estimate [wp.ompeak] if not known)
                    vfun=@(u)conj(twf(u).*exp(-1i*wp.ompeak*u)); %demodulated wavelet in the time domain
                    xp=wp.tpeak; lim1=wp.t1; lim2=wp.t2;

                    [QQ,wflag,xx,ss]=sqeps(app,vfun,xp,[lim1,lim2],racc,MIC,...
                        [-8*(2*pi*fmax/wp.ompeak)*L/fs,8*(2*pi*fmax/wp.ompeak)*L/fs]); %���������������������������������������
                    wp.t1e=ss(1,1); wp.t2e=ss(1,2); wp.t1h=ss(2,1); wp.t2h=ss(2,2);
                    if wflag==1 && ~strcmpi(DispMode,'off') %����������������������
                        fprintf(2,'--------------------------------------------- Warning! ---------------------------------------------\n');
                        fprintf('The time-domain wavelet function is not well-behaved (e.g. decays very slowly as time tends to +/- infinity).\n');
                        fprintf('The integration might be not accurate (and therefore e.g. cone-of-influence borders).\n');
                        fprintf(2,'----------------------------------------------------------------------------------------------------\n');
                    end

                end

            end
            %======================================================================================================================


        end


        %============ Function for accurate integration and estimation ============
        %================= of epsilon-supports (needed in parcalc) ================
        %INPUT:
        %[vfun] - function @(x) to be integrated
        %[xp] - position of the peak
        %[lims]=[lim1,lim2] - limits where [vfun] is defined
        %[racc] - accuracy (i.e. epsilon)
        %[MIC] - maximum number of intervals in quadgk(...)
        %[nlims] - the maximum limits dictated by the signal
        %OUTPUT:
        %[QQ]=[Q1,Q2;q1m,q2m] - integrals of the function:
        %     [Q1,Q2] - over [lim1,xp] and [xp,lim2]
        %     [q1m,q2m] - over [x1m,xp] and [xp,x2m]
        %[wflag]=0|1 - was integration fully successful (0) or not (1)
        %[xx]=[x1h,x2h;x1e,x2e;x1m,x2m;x1n,x2n] - different important points:
        %     [x1e,x2e] - interval where [vfun] is larger than 10^(-8) of maximum
        %     [x1h,x2h] - where [vfun] first drops below 0.5 of its maximum
        %     [x1m,x2m] - interval over which integration with quadgk is precise
        %                 (just =[x1e,x2e] if all is precise, i.e. wflag=0)
        %     [x1n,x2n] - limits of overflow (i.e. when function returns NaN due to
        %                 numerics, as for exp(-x^2)*exp(x) for large x); if no
        %                 overflow, then [x1n,x2n]=[lim1,lim2]
        %optional:
        %[ss]=[s1h,s2h;s1e,s2e] - supports of the function over its variable:
        %     [s1e,s2e] - epsilon-support
        %     [s1h,s2h] - 0.5-support
        % function [QQ,wflag,xx,ss]=sqeps(vfun,xp,lims,racc,MIC,nlims)
        %
        % wflag=0; %indicates are there any problems with integration
        % ctol=max([racc/1000,10^(-12)]); %numerical accuracy
        % lim1=lims(1); lim2=lims(2);
        % nlim1=nlims(1); nlim2=nlims(2);
        %
        % kk=1; shp=0; %check for function to be well-defined on the peak, otherwise introduce some shift [shp]
        % while (~isfinite(vfun(xp+shp)) || isnan(vfun(xp+shp))), shp=kk*10^(-14); kk=kk*(-2); end
        % vmax=vfun(xp+shp);
        %
        % %determine x after for which function first crosses 0.5 of its maximal value (if such point exists)
        % if isfinite(lim1), tx1=lim1-0.01*(lim1-xp); qv1=abs(vfun(tx1)/vmax); else qv1=NaN; end
        % if qv1<0.5, x1h=fzero(@(u)abs(vfun(u)/vmax)-0.5,[xp+shp,tx1],optimset('Display','off'));
        % elseif isnan(qv1), x1h=fzero(@(u)abs(vfun(xp-abs(u))/vmax)-0.5,shp,optimset('Display','off')); x1h=xp-abs(x1h);
        % else x1h=xp+(lim1-xp)/100;
        % end
        % if isfinite(lim2), tx2=lim2-0.01*(lim2-xp); qv2=abs(vfun(tx2)/vmax); else qv2=NaN; end
        % if qv2<0.5, x2h=fzero(@(u)abs(vfun(u)/vmax)-0.5,[xp+shp,tx2],optimset('Display','off'));
        % elseif isnan(qv2), x2h=fzero(@(u)abs(vfun(xp+abs(u))/vmax)-0.5,shp,optimset('Display','off')); x2h=xp+abs(x2h);
        % else x2h=xp+(lim2-xp)/100;
        % end
        % if isnan(x1h)
        %     x1h=fminsearch(@(u)abs(abs(vfun(xp-abs(u))/vmax)-0.5),shp,optimset('TolFun',10^(-12)));
        %     x1h=xp-abs(x1h)/100;
        % end
        % if isnan(x2h)
        %     x2h=fminsearch(@(u)abs(abs(vfun(xp+abs(u))/vmax)-0.5),shp,optimset('TolFun',10^(-12)));
        %     x2h=xp+abs(x2h)/100;
        % end
        %
        %
        % %determine x after which function is always below 10^(-8) of its maximal value (if such point exists), and not only the first crossing
        % if isfinite(lim1), tx1=lim1-0.01*(lim1-xp); qv1=(abs(vfun(tx1))+abs(vfun((tx1+lim1)/2))+abs(vfun((tx1+3*lim1)/4)))/abs(vmax); else qv1=NaN; end
        % if qv1<10^(-8)/3
        %     x1e=fzero(@(u)abs(vfun(u)/vmax)+abs(vfun((u+lim1)/2)/vmax)+abs(vfun((u+3*lim1)/4)/vmax)-10^(-8),[xp+shp,tx1],optimset('Display','off'));
        % elseif isnan(qv1)
        %     x1e=fzero(@(u)abs(vfun(xp-abs(u))/vmax)+abs(vfun(xp-sqrt(3)*abs(u))/vmax)+...
        %         abs(vfun(xp-sqrt(5)*abs(u))/vmax)-10^(-8),shp,optimset('Display','off')); %to avoid selecting first minima for oscillating functions
        %     x1e=xp-abs(x1e);
        % else x1e=xp+(lim1-xp)/2;
        % end
        % if isfinite(lim2), tx2=lim2-0.01*(lim2-xp); qv2=(abs(vfun(tx2))+abs(vfun((tx2+lim2)/2))+abs(vfun((tx2+3*lim2)/4)))/abs(vmax); else qv2=NaN; end
        % if qv2<10^(-8)
        %     x2e=fzero(@(u)abs(vfun(u)/vmax)+abs(vfun((u+lim2)/2)/vmax)+abs(vfun((u+3*lim2)/4)/vmax)-10^(-8),[xp+shp,tx2],optimset('Display','off'));
        % elseif isnan(qv2)
        %     x2e=fzero(@(u)abs(vfun(xp+abs(u))/vmax)+abs(vfun(xp+sqrt(3)*abs(u))/vmax)+...
        %         abs(vfun(xp+sqrt(5)*abs(u))/vmax)-10^(-8),shp,optimset('Display','off'));
        %     x2e=xp+abs(x2e);
        % else x2e=xp+(lim2-xp)/2;
        % end
        % if isnan(x1e)
        %     x1e=fminsearch(@(u)abs(abs(vfun(x1h-abs(u))/vmax)-10^(-8)),0,optimset('TolFun',10^(-12)));
        %     x1e=x1h-abs(x1e); lim1=x1e; wflag=1;
        % end
        % if isnan(x2e)
        %     x2e=fminsearch(@(u)abs(abs(vfun(x2h+abs(u))/vmax)-10^(-8)),0,optimset('TolFun',10^(-12)));
        %     x2e=x2h+abs(x2e); lim2=x2e; wflag=1;
        % end
        %
        %
        % wstate=warning('off','all'); %disable warnings
        %
        %
        % %=============== Integrate given function to find Q1 and Q2 ===============
        % Q1=0; Q2=0;
        % %--------------------------------------------------------------------------
        % [qv,eb]=quadgk(@(u)vfun(u),xp,x1e,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        % [qv,eb]=quadgk(@(u)vfun(u),xp,x1e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
        % x1m=x1e; q1m=qv;
        % if abs(eb/(Q1+qv))>ctol
        %     wflag=1;
        %     [qv,eb]=quadgk(@(u)vfun(u),xp,x1h,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        %     [qv,eb]=quadgk(@(u)vfun(u),xp,x1h,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
        %     x1m=x1h; q1m=qv;
        %     if abs(eb/(Q1+qv))<=ctol
        %         while 1==1
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,max([xp+(x1m-xp)*2,x1e]),'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,max([xp+(x1m-xp)*2,x1e]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
        %             if abs(eb/(Q1+qv))>ctol, break; end
        %             x1m=max([xp+(x1m-xp)*2,x1e]); q1m=qv;
        %         end
        %     else
        %         while abs(eb/(Q1+qv))>ctol
        %             x1m=xp+(x1m-xp)/2;
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,x1m,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,x1m,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
        %             q1m=qv;
        %         end
        %     end
        %     [qv,eb]=quadgk(@(u)vfun(u),x1m,x1e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q1+qv)),'RelTol',0);
        %     if abs(eb)<0.5*abs(qv), Q1=Q1+qv; end
        % end
        % Q1=Q1+q1m;
        % if wflag==0
        %     [qv,eb]=quadgk(@(u)vfun(u),x1e,lim1,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q1),'RelTol',0);
        %     if ~isfinite(lim1) && isnan(qv) %to avoid overflow
        %         lim1=x1e; while ~isnan(vfun(2*lim1)), lim1=2*lim1; end
        %         [qv,eb]=quadgk(@(u)vfun(u),x1e,lim1,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q1),'RelTol',0);
        %     end
        %     if abs(eb/Q1)>ctol
        %         wflag=1;
        %         [qv,eb]=quadgk(@(u)vfun(u),x1e,max([min([8*x1e,nlim1]),lim1]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q1),'RelTol',0);
        %     end
        %     if abs(eb)<0.5*abs(qv), Q1=Q1+qv; end
        % end
        % Q1=-Q1;
        % %--------------------------------------------------------------------------
        % [qv,eb]=quadgk(@(u)vfun(u),xp,x2e,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        % [qv,eb]=quadgk(@(u)vfun(u),xp,x2e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
        % x2m=x2e; q2m=qv;
        % if abs(eb/(Q2+qv))>ctol
        %     wflag=1;
        %     [qv,eb]=quadgk(@(u)vfun(u),xp,x2h,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        %     [qv,eb]=quadgk(@(u)vfun(u),xp,x2h,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
        %     x2m=x2h; q2m=qv;
        %     if abs(eb/(Q2+qv))<=ctol
        %         while 1==1
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,min([xp+(x2m-xp)*2,x2e]),'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,min([xp+(x2m-xp)*2,x2e]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
        %             if abs(eb/(Q2+qv))>ctol, break; end
        %             x2m=min([xp+(x2m-xp)*2,x2e]); q2m=qv;
        %         end
        %     else
        %         while abs(eb/(Q2+qv))>ctol
        %             x2m=xp+(x2m-xp)/2;
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,x2m,'MaxIntervalCount',MIC,'AbsTol',0,'RelTol',0.1*ctol);
        %             [qv,eb]=quadgk(@(u)vfun(u),xp,x2m,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
        %             q2m=qv;
        %         end
        %     end
        %     [qv,eb]=quadgk(@(u)vfun(u),x2m,x2e,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*(Q2+qv)),'RelTol',0);
        %     if abs(eb)<0.5*abs(qv), Q2=Q2+qv; end
        % end
        % Q2=Q2+q2m;
        % if wflag==0
        %     [qv,eb]=quadgk(@(u)vfun(u),x2e,lim2,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q2),'RelTol',0);
        %     if ~isfinite(lim2) && isnan(qv) %to avoid overflow
        %         lim2=x2e; while ~isnan(vfun(2*lim2)), lim2=2*lim2; end
        %         [qv,eb]=quadgk(@(u)vfun(u),x2e,lim2,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q2),'RelTol',0);
        %     end
        %     if abs(eb/Q2)>ctol
        %         wflag=1;
        %         [qv,eb]=quadgk(@(u)vfun(u),x2e,min([max([8*x2e,nlim2]),lim2]),'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q2),'RelTol',0);
        %     end
        %     if abs(eb)<0.5*abs(qv), Q2=Q2+qv; end
        % end
        %
        % QQ=[Q1,Q2;-q1m,q2m]; xx=[x1e,x2e;x1h,x2h;x1m,x2m;lim1,lim2];
        % %--------------------------------------------------------------------------
        %
        % %======== Find 0.5- and \epsilon-supports of the specified function =======
        % if nargout>3
        %     Q=Q1+Q2;
        %     s1e=fz(racc/2); s2e=fz(1-racc/2); s1h=fz(0.5/2); s2h=fz(1-0.5/2);
        %     ss=[s1e,s2e;s1h,s2h];
        % end
        % %--------------------------------------------------------------------------
        %
        % warning(wstate); %restore the warning settings
        %
        %
        % %function for finding the epsilon-supports
        %     function x0=fz(zv)
        %         if zv<abs(Q1/Q), cx1=x1m; cq1=Q1+q1m; ra=exp(-1/2); rb=exp(1/2);
        %         else cx1=x2m; cq1=Q1+q2m; ra=exp(1/2); rb=exp(-1/2); end
        %         if abs(1-abs((Q-cq1)/Q))<zv
        %             while 1==1
        %                 nx=xp+ra*(cx1-xp);
        %                 if nx<lim1, nx=(cx1+lim1)/2; end
        %                 if nx>lim2, nx=(cx1+lim2)/2; end
        %                 [pv,~]=quadgk(@(u)vfun(u),cx1,nx,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q),'RelTol',0);
        %                 if abs(1-abs((Q-cq1-pv)/Q))>=zv, cx2=nx; cq2=cq1+pv; break; end
        %                 cx1=nx; cq1=cq1+pv;
        %             end
        %         else
        %             while 1==1
        %                 nx=xp+rb*(cx1-xp);
        %                 if nx<lim1, nx=(cx1+lim1)/2; end
        %                 if nx>lim2, nx=(cx1+lim2)/2; end
        %                 [pv,~]=quadgk(@(u)vfun(u),cx1,nx,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q),'RelTol',0);
        %                 if abs(1-abs((Q-cq1-pv)/Q))<zv, cx2=nx; cq2=cq1+pv; break; end
        %                 cx1=nx; cq1=cq1+pv;
        %             end
        %         end
        %         [pv,~]=quadgk(@(u)vfun(u),cx1,(cx1+cx2)/2,'MaxIntervalCount',MIC,'AbsTol',0.1*abs(ctol*Q),'RelTol',0);
        %         qfun=@(x)1-abs((Q-(cq1+pv+quadgk(@(u)vfun(u),(cx1+cx2)/2,x,'MaxIntervalCount',round(MIC/10),'AbsTol',0.5*abs(ctol*Q),'RelTol',0)))/Q);
        %         x0=fzero(@(x)abs(qfun(x))-zv,[cx1,cx2],optimset('TolX',10^(-14)));
        %     end
        %
        %
        % end


        %================== Predictive padding function ===========================

        % fsig=fcast(sig,fs,NP,fint,Optional:MaxOrder,w)
        % - given signal [sig] sampled at [fs] Hz, uses DFT and weighted least
        % squares (with weights specified by [w], which should be of the same
        % length as [sig]), finds main sinusoidal components present in the signal
        % and uses them to predict signal for NP consequtive time-steps, which is
        % returned in [fsig]; [fint]=[fmin,fmax] specifies the allowable frequency
        % range for sinusoids: tones with frequencies out of it are not continued
        % for prediction. The number of sinusoids is determined using Bayesian
        % (Schwarz) information criterion, but it cannot exceed [MaxOrder].
        %
        % function fsig = fcast(sig,fs,NP,fint,varargin)
        %
        % MaxOrder=length(sig); if nargin>3 && ~isempty(varargin{1}), MaxOrder=varargin{1}; end
        % w=[]; if nargin>4 && ~isempty(varargin{2}), w=varargin{2}(:); end, rw=sqrt(w);
        % DispMode='off'; if nargin>5 && ~isempty(varargin{3}), DispMode=varargin{3}; end
        %
        % WTol=10^(-8); %tolerance for cutting the weighting
        % Y=sig(:); if ~isempty(rw), Y=rw.*Y; end
        % L=find(flipud(rw)/max(rw)>=WTol,1,'last');
        % T=L/fs;
        % t=(0:(L-1))/fs;
        % w=w(end-L+1:end); rw=rw(end-L+1:end); Y=Y(end-L+1:end);
        % MaxOrder=min([MaxOrder,floor(L/3)]);
        %
        % FTol=1/T/100; %accuracy of frequencies determination
        % rr=(1+sqrt(5))/2; %multiplier to easily use golden section search afterwards
        % Nq=ceil((L+1)/2); ftfr=[(0:Nq-1),-fliplr(1:L-Nq)]*fs/L; %FT frequencies
        % orstd=std(Y); %original signal's standard deviation
        %
        % v=zeros(L,1); ic=v; frq=v; amp=v; phi=v; itn=0;
        % if strcmpi(DispMode,'on'), pos=2; fprintf('0%%'); end
        % while itn<MaxOrder
        %     itn=itn+1;
        %     aftsig=abs(fft(Y)); [~,imax]=max(aftsig(2:Nq-1)); imax=imax+1;
        %
        %     %========================= Forward search =============================
        %     %Find two points between which the minimum occurs
        %     nf=ftfr(imax);
        %     FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
        %     if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %     nb=FM\Y; nerr=std(Y-FM*nb);
        %
        %     df=FTol; perr=Inf; %starting frequency step and 'previous error'
        %     while nerr<perr
        %         if abs(nf-fs/2+FTol)<eps, break; end
        %         pf=nf; perr=nerr; pb=nb;
        %         nf=min([pf+df,fs/2-FTol]);
        %         FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
        %         if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %         nb=FM\Y; nerr=std(Y-FM*nb);
        %         df=df*rr;
        %     end
        %
        %     %Use golden section search to find exact minimum
        %     if nerr<perr
        %         cf=[nf,nf,nf]; cerr=[nerr,nerr,nerr]; cb=[nb(:),nb(:),nb(:)];
        %     elseif abs(nf-pf-FTol)<eps
        %         cf=[pf,pf,pf]; cerr=[perr,perr,perr]; cb=[pb(:),pb(:),pb(:)];
        %     else
        %         cf=[0,pf,nf]; cerr=[0,perr,nerr]; cb=[zeros(length(pb),1),pb(:),nb(:)];
        %         cf(1)=pf-df/rr/rr;
        %         FM=[ones(L,1),cos(2*pi*cf(1)*t(:)),sin(2*pi*cf(1)*t(:))];
        %         if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %         cb(:,1)=FM\Y; cerr(1)=std(Y-FM*cb(:,1));
        %     end
        %     while (cf(2)-cf(1)>FTol && cf(3)-cf(2)>FTol)
        %         tf=cf(1)+cf(3)-cf(2);
        %         FM=[ones(L,1),cos(2*pi*tf*t(:)),sin(2*pi*tf*t(:))];
        %         if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %         tb=FM\Y; terr=std(Y-FM*tb);
        %         if terr<cerr(2) && tf<cf(2), cf=[cf(1),tf,cf(2)]; cerr=[cerr(1),terr,cerr(2)]; cb=[cb(:,1),tb(:),cb(:,2)]; end
        %         if terr<cerr(2) && tf>cf(2), cf=[cf(2),tf,cf(3)]; cerr=[cerr(2),terr,cerr(3)]; cb=[cb(:,2),tb(:),cb(:,3)]; end
        %         if terr>cerr(2) && tf<cf(2), cf=[tf,cf(2),cf(3)]; cerr=[terr,cerr(2),cerr(3)]; cb=[tb(:),cb(:,2),cb(:,3)]; end
        %         if terr>cerr(2) && tf>cf(2), cf=[cf(1),cf(2),tf]; cerr=[cerr(1),cerr(2),terr]; cb=[cb(:,1),cb(:,2),tb(:)]; end
        %     end
        %     fcf=cf(2); fcb=cb(:,2); fcerr=cerr(2); %forward values
        %
        %     %========================= Backward search ============================
        %     %Find two points between which minima occurs
        %     nf=ftfr(imax);
        %     FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
        %     if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %     nb=FM\Y; nerr=std(Y-FM*nb);
        %
        %     df=FTol; perr=Inf; %starting frequency step and 'previous error'
        %     while nerr<perr
        %         if abs(nf-FTol)<eps, break; end
        %         pf=nf; perr=nerr; pb=nb;
        %         nf=max([pf-df,FTol]);
        %         FM=[ones(L,1),cos(2*pi*nf*t(:)),sin(2*pi*nf*t(:))];
        %         if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %         nb=FM\Y; nerr=std(Y-FM*nb);
        %         df=df*rr;
        %     end
        %
        %     %Use golden section search to find exact minimum
        %     if nerr<perr
        %         cf=[nf,nf,nf]; cerr=[nerr,nerr,nerr]; cb=[nb(:),nb(:),nb(:)];
        %     elseif abs(nf-pf-FTol)<eps
        %         cf=[pf,pf,pf]; cerr=[perr,perr,perr]; cb=[pb(:),pb(:),pb(:)];
        %     else
        %         cf=[nf,pf,0]; cerr=[nerr,perr,0]; cb=[nb(:),pb(:),zeros(length(pb),1)];
        %         cf(3)=pf+df/rr/rr;
        %         FM=[ones(L,1),cos(2*pi*cf(1)*t(:)),sin(2*pi*cf(1)*t(:))];
        %         if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %         cb(:,3)=FM\Y; cerr(3)=std(Y-FM*cb(:,3));
        %     end
        %     while (cf(2)-cf(1)>FTol && cf(3)-cf(2)>FTol)
        %         tf=cf(1)+cf(3)-cf(2);
        %         FM=[ones(L,1),cos(2*pi*tf*t(:)),sin(2*pi*tf*t(:))];
        %         if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %         tb=FM\Y; terr=std(Y-FM*tb);
        %         if terr<cerr(2) && tf<cf(2), cf=[cf(1),tf,cf(2)]; cerr=[cerr(1),terr,cerr(2)]; cb=[cb(:,1),tb(:),cb(:,2)]; end
        %         if terr<cerr(2) && tf>cf(2), cf=[cf(2),tf,cf(3)]; cerr=[cerr(2),terr,cerr(3)]; cb=[cb(:,2),tb(:),cb(:,3)]; end
        %         if terr>cerr(2) && tf<cf(2), cf=[tf,cf(2),cf(3)]; cerr=[terr,cerr(2),cerr(3)]; cb=[tb(:),cb(:,2),cb(:,3)]; end
        %         if terr>cerr(2) && tf>cf(2), cf=[cf(1),cf(2),tf]; cerr=[cerr(1),cerr(2),terr]; cb=[cb(:,1),cb(:,2),tb(:)]; end
        %     end
        %     bcf=cf(2); bcb=cb(:,2); bcerr=cerr(2); %backward values
        %
        %     %================ Assigning values and subtracting ====================
        %     if fcerr<bcerr, cf=fcf; cb=fcb; cerr=fcerr;
        %     else cf=bcf; cb=bcb; cerr=bcerr; end
        %
        %     frq(itn+1)=cf; amp(itn+1)=sqrt(cb(2)^2+cb(3)^2); phi(itn+1)=atan2(-cb(3),cb(2));
        %     amp(1)=amp(1)+cb(1); v(itn)=cerr;
        %
        %     FM=[ones(L,1),cos(2*pi*cf*t(:)),sin(2*pi*cf*t(:))];
        %     if ~isempty(rw), FM=FM.*(rw*ones(1,3)); end
        %     Y=Y-FM*cb;
        %
        %     %===================== Information criterion ==========================
        %     CK=3*itn+1;
        %     cic=L*log(cerr)+CK*log(L); %Bayesian (Schwarz) IC (the best)
        %     %cic=L*log(cerr)+2*CK*L/(L-CK-1); %Akaike IC (corrected)
        %     %cic=cerr*(L+CK)/(L-CK); %Forward Prediction Error (FPE)
        %
        %     ic(itn)=cic;
        %     if v(itn)/orstd<2*eps, break; end
        %     if itn>2 && cic>ic(itn-1) && ic(itn-1)>ic(itn-2), break; end
        %
        %     if strcmpi(DispMode,'on') && floor(100*itn/MaxOrder)>floor(100*(itn-1)/MaxOrder)
        %         cstr=num2str(floor(100*itn/MaxOrder)); fprintf([repmat('\b',1,pos),cstr,'%%']); pos=length(cstr)+1;
        %     end
        % end
        % if strcmpi(DispMode,'on'), fprintf([repmat('\b',1,pos),'100%%']); end
        %
        % frq=frq(1:itn+1); amp=amp(1:itn+1); phi=phi(1:itn+1); v=v(1:itn); ic=ic(1:itn);
        % fsig=zeros(NP,1); nt=(T:1/fs:T+(NP-1)/fs)';
        % if size(sig,2)>size(sig,1), fsig=fsig'; nt=nt'; end
        % for k=1:length(frq)
        %     if frq(k)>fint(1) && frq(k)<fint(2)
        %         fsig=fsig+amp(k)*cos(2*pi*frq(k)*nt+phi(k));
        %     else
        %         fsig=fsig+amp(k)*cos(2*pi*frq(k)*(T-1/fs)+phi(k));
        %     end
        % end
        %
        % end


        %=============== Interpolation function for plotting ======================

        % ZI=aminterp(X,Y,Z,XI,YI,method)
        % - the same as interp2, but uses different interpolation types,
        % maximum-based ([method]='max') or average-based ([method]='avg'), where
        % [ZI] at each point [XI,YI] represents maximum or average among values of
        % [Z] corresponding to the respective quadrant in [X,Y]-space which
        % includes point [XI,YI];
        % X,Y,XI,YI are all linearly spaced vectors, and the lengths of XI,YI
        % should be the same or smaller than that of X,Y; Z should be real,
        % ideally positive; X and Y correspond to the 2 and 1 dimension of Z, as
        % always.
        %
        % function ZI = aminterp(X,Y,Z,XI,YI,method)
        %
        % %Interpolation over X
        % ZI=zeros(size(Z,1),length(XI))*NaN;
        % xstep=mean(diff(XI));
        % xind=1+floor((1/2)+(X-XI(1))/xstep); xind=xind(:);
        % xpnt=[0;find(xind(2:end)>xind(1:end-1));length(xind)];
        % if strcmpi(method,'max')
        %     for xn=1:length(xpnt)-1
        %         xid1=xpnt(xn)+1; xid2=xpnt(xn+1);
        %         ZI(:,xind(xid1))=max(Z(:,xid1:xid2),[],2);
        %     end
        % else
        %     for xn=1:length(xpnt)-1
        %         xid1=xpnt(xn)+1; xid2=xpnt(xn+1);
        %         ZI(:,xind(xid1))=mean(Z(:,xid1:xid2),2);
        %     end
        % end
        %
        % Z=ZI;
        %
        % %Interpolation over Y
        % ZI=zeros(length(YI),size(Z,2))*NaN;
        % ystep=mean(diff(YI));
        % yind=1+floor((1/2)+(Y-YI(1))/ystep); yind=yind(:);
        % ypnt=[0;find(yind(2:end)>yind(1:end-1));length(yind)];
        % if strcmpi(method,'max')
        %     for yn=1:length(ypnt)-1
        %         yid1=ypnt(yn)+1; yid2=ypnt(yn+1);
        %         ZI(yind(yid1),:)=max(Z(yid1:yid2,:),[],1);
        %     end
        % else
        %     for yn=1:length(ypnt)-1
        %         yid1=ypnt(yn)+1; yid2=ypnt(yn+1);
        %         ZI(yind(yid1),:)=mean(Z(yid1:yid2,:),1);
        %     end
        % end
        %
        % end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('Functions')
        end

        % Menu selected function: LoadtimeseriesMenu
        function LoadtimeseriesMenuSelected(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%Setting default values when data is loaded in%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if isempty(app.Group1)
                app.Group1 = 1:2;

            end

            if isempty(app.Group2)
                app.Group2 = 3:4;

            end

            if isempty(app.Allpressed)
                app.Allpressed = 0;

            end


            app.ColVal = app.FigurecolorschemeDropDown.Value;


            app.FreqRes = app.FrequencyresolutionEditField.Value;


            if isempty(app.signal_number)
                app.signal_number = 1;

            end

            app.WaveletType = app.WavelettypeDropDown.Value;


            app.CE = app.CutedgesDropDown.Value;


            app.AvgType = app.avgtype.Value;

            app.TestType = app.testtype.Value;

            if isempty(app.TestInput)
                app.TestInput = "Amplitude";

            end

            app.P = str2double(app.alpha.Value);

            app.PlotType = app.PlottypeSwitch.Value;

            app.TransformType = app.TransformtypeSwitch.Value;


            %Load in the correct datafile
            [app.file,app.path] = uigetfile();
            addpath(app.path);
            figure(app.UIFigure)
            app.data = importdata(app.file);


            %Makes it so that you can have rowwise or collumwise

            if size(app.data, 1) > size(app.data, 2)
                app.data = transpose(app.data);
            else
            end

            app.SignalListBox.Items = {};

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%To test if there are NaNs in the time series%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            resultnan = (sum(isnan(app.data)));
            resultinf = (sum(isinf(app.data)));


            while resultnan ~=0 | resultinf ~= 0

                answer = questdlg('MODA has detected either NaNs or inf values in your time series.', ...
                    'NaN/inf detected', ...
                    'Interpolate over these entries','Reupload data','Continue','No thank you');
                % Handle response
                switch answer
                    case 'Interpolate over these entries'

                        %Interpolate over code (linear)
                        [app.data,~] = fillmissing(app.data,'linear');

                        resultnan = (sum(isnan(app.data)));
                        resultinf = (sum(isinf(app.data)));
                    case 'Reupload data'

                        %Option to put a clean time series in
                        [app.file,app.path] = uigetfile();
                        app.data = importdata(app.file);

                        if size(app.data, 1) > size(app.data, 2)
                            app.data = transpose(app.data);
                        else
                        end

                        resultnan = (sum(isnan(app.data)));
                        resultinf = (sum(isinf(app.data)));

                        disp('Please reupload a clean time series ')


                end

            end

            figure(app.UIFigure)

            if size(app.data, 1) > size(app.data, 2)
                app.data = transpose(app.data);
            else
            end


            figure(app.UIFigure)


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%To test if the time series is not a double%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            abba = isa(app.data,'double');


            while abba == 0

                answer = questdlg('MODA has detected that the time series is not a double. This may impact performance.', ...
                    'NaN/inf detected', ...
                    'Convert to double','Reupload data','Continue','No thank you');
                % Handle response
                switch answer
                    case 'Convert to double'

                        %convert it to double format

                        app.data = double(app.data );
                        abba = isa(app.data,'double');


                    case 'Reupload data'

                        %Option to put a clean time series in
                        [app.file,app.path] = uigetfile();
                        app.data = importdata(app.file);
                        addpath(app.path);
                        abba = isa(app.data,'double');


                        disp('Please reupload a clean time series ')


                    case 'Continue'

                        abba = 1;


                end

            end

            figure(app.UIFigure)


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Plotting%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            if size(app.data, 1) > size(app.data, 2)
                app.data = transpose(app.data);
            else
            end


            app.data1 = app.data;                                   %Unspecified so other functions can still take place like looping over multiple ones

            %Brings up the text box for you to enter the sampling frequency
            %of the signal
            prompt   = {'Sampling frequency (Fs) Hz'};
            dlgtitle = 'Input';
            dims     = [1 35];
            definput = {'10'};
            app.Fs   = inputdlg(prompt,dlgtitle,dims,definput);
            app.Fs   = cell2mat( app.Fs ) ;
            app.Fs = str2double(app.Fs);


            %Fmin and Fmax
            app.MaxfrequencyEditField.Value = app.Fs/2;
            app.Fmax = app.MaxfrequencyEditField.Value;
            
            app.MinfrequencyEditField.Value = app.Fs/20;
            app.Fmin = app.MinfrequencyEditField.Value;

            app.IntervalsEditField.Value  = app.Fs/10;
            app.LowInt = app.IntervalsEditField.Value;


            app.EditField_3.Value         = app.Fs/5;
            app.HighInt = app.EditField_3.Value ;

            
%Plots the TS in the box

            app.time = 0 : 1./app.Fs : (1./app.Fs)*size(app.data(app.signal_number, :),2)-(1./app.Fs);
            plot(app.UIAxes, app.time , app.data(app.signal_number, :));
            xlim(app.UIAxes, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])

            %            hold on
            %Plot the Initial and preproscessed signals
            plot(app.UIAxes2, app.time , app.data(app.signal_number, :));
            app.PreprocessingDropDown.Value = 'off';
            xlim(app.UIAxes2, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
            linkaxes([app.UIAxes app.UIAxes2],'x');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%Now considering how many signals and using the list box%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            length = size(app.data,1);

            for i = 1:length

                entry = ['Signal ' num2str(i) ];
                %     C = 'C'
                app.SignalListBox.Items(i)  = {entry};

            end
            figure(app.UIFigure)


        end

        % Value changed function: PreprocessingDropDown
        function PreprocessingDropDownValueChanged(app, event)


            app.Preprocess = app.PreprocessingDropDown.Value;
            if strcmp(app.Preprocess , 'on')


                for i = 1:size(app.data1, 1)
                    signal = app.data1(i, :);

                    %SJKB - taken and adapted from 'TimeFrequencyAnalysis' In the moda codes.

                    % Performing the prepro
                    L = size(signal,2);
                    X=(1:length(signal))'./app.Fs;
                    XM=ones(length(X),4);

                    for pn=1:3
                        CX=X.^pn;
                        XM(:,pn+1)=(CX-mean(CX))/std(CX);
                    end
                    signal = signal(:);
                    w=warning('off','all');
                    new_signal=signal-XM*(pinv(XM)*signal);
                    warning(w);

                    % Filtering
                    fx=fft(new_signal,L);

                    multiplied = (app.Fs/L);
                    Nq=ceil((L+1)/2);
                    ff=[(0:Nq-1),-fliplr(1:L-Nq)]*multiplied;
                    ff=ff(:);

                    %             fx(abs(ff)<=max([app.Fmin,app.Fs/L]) | abs(ff)>=(app.Fmax))=0;
                    fx(abs(ff)<=max([app.Fmin,app.Fs/L]) | (abs(ff))>=app.Fmax)=0;
                    %       fx(abs(ff)<=max([app.Fmin,Fs/L]) | (abs(ff))>=min([app.Fmax,Fs/L]))=0;

                    result = ifft(fx)';
                    app.preprocessed(i, :) = result;
                end

                plot(app.UIAxes2,app.time, app.preprocessed(app.signal_number, :), 'color', [0.9290 0.6940 0.1250])
                xlim(app.UIAxes2, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
            else
                app.prepro = 0;
                plot(app.UIAxes2,app.time, app.data(app.signal_number, :))
                xlim(app.UIAxes2, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
            end


        end

        % Value changed function: WavelettypeDropDown
        function WavelettypeDropDownValueChanged(app, event)
            app.WaveletType = app.WavelettypeDropDown.Value;


        end

        % Value changed function: CutedgesDropDown
        function CutedgesDropDownValueChanged(app, event)
            app.CE = app.CutedgesDropDown.Value;


        end

        % Value changed function: FrequencyresolutionEditField
        function FrequencyresolutionEditFieldValueChanged(app, event)
            app.FreqRes = app.FrequencyresolutionEditField.Value;

        end

        % Value changed function: MinfrequencyEditField
        function MinfrequencyEditFieldValueChanged(app, event)
            app.Fmin = app.MinfrequencyEditField.Value;


        end

        % Value changed function: MaxfrequencyEditField
        function MaxfrequencyEditFieldValueChanged(app, event)
            app.Fmax = app.MaxfrequencyEditField.Value;

        end

        % Value changed function: TransformsingleButton
        function TransformsingleButtonValueChanged(app, event)
       set(app.CalculatingtransfomsLabel, 'Visible', 'on'); drawnow;

            app.Allpressed = 0;
            app.Allpressedlast = 0;
            app.IntervallsPushed = 0;     %stops them from appearing in seperate figures

            signal = app.data1(app.signal_number, :);


%Assign the colmap
            if strcmp(app.ColVal, 'MODA style')

                load('cmap.mat')
                cmap;
                colormap(app.UIAxes3,cmap);
            else
                colormap(app.UIAxes3, 'parula');
            end

            if app.PlotType == "Amplitude"

                if app.TransformType == "WT"

                    [app.wavelet, app.f]   = wt(app,signal,app.Fs, 'plot', 'off', 'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Wavelet', app.WaveletType, 'Preprocess',  app.Preprocess, 'CutEdges', app.CE );

                    app.A1 = mean(abs(app.wavelet), 2,'omitnan' );


                else
                end


                if app.TransformType == "WFT"
                    [app.forier, app.f]   = wft(app, signal,app.Fs, 'plot', 'off', 'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Window', app.WindowType, 'Preprocess',  app.Preprocess,  'CutEdges', app.CE );

                    app.A1 = mean(abs(app.forier), 2,'omitnan' );

                else
                end
            else

                if app.TransformType == "WT"
                    [app.wavelet, app.f]   = wt(app, signal,app.Fs, 'plot', 'off', 'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Wavelet', app.WaveletType, 'Preprocess',  app.Preprocess, 'CutEdges', app.CE );

                    app.A1 = mean(abs(app.wavelet).^2, 2,'omitnan' );

                else
                end


                if app.TransformType == "WFT"
                    [app.forier, app.f]   = wft(app, signal,app.Fs, 'plot', 'off', 'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Window', app.WindowType, 'Preprocess',  app.Preprocess,  'CutEdges', app.CE );

                    app.A1 = mean(abs(app.forier).^2, 2,'omitnan' );

                else
                end
            end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
                %Plotting
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                if app.PlotType == "Amplitude"

                    if app.TransformType == "WT"

                        %WT amplitude plot

                        plot(app.UIAxes4,app.A1 ,app.f, '-');
                        ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                        pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.wavelet));

                        xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                        linkaxes([app.UIAxes app.UIAxes3],'x');
                        linkaxes([app.UIAxes3 app.UIAxes4],'y');

                        set(pc,'EdgeColor','none');


                    else

                        %WFT amplitude plot

                        plot(app.UIAxes4,app.A1 ,app.f, '-');
                        ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                        pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.forier));
                        xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                        linkaxes([app.UIAxes app.UIAxes3],'x');
                        linkaxes([app.UIAxes3 app.UIAxes4],'y');
                        set(pc,'EdgeColor','none');

                    end

                else        %Its power

                    if app.TransformType == "WT"
                        %POWER WT plot

                        plot(app.UIAxes4,app.A1 ,app.f, '-');
                        ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                        pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.wavelet).^2);
                        xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                        linkaxes([app.UIAxes app.UIAxes3],'x');
                        linkaxes([app.UIAxes3 app.UIAxes4],'y');
                        set(pc,'EdgeColor','none');


                    else

                        %POWER WFT plot
                        plot(app.UIAxes4,app.A1 ,app.f, '-');
                        ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                        pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.forier).^2);
                        xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                        linkaxes([app.UIAxes app.UIAxes3],'x');
                        linkaxes([app.UIAxes3 app.UIAxes4],'y');
                        set(pc,'EdgeColor','none');


                    end

                end


       set(app.CalculatingtransfomsLabel, 'Visible', 'off'); drawnow;

app.AverageofalltransformsMenu.Visible = 'off';


        end

        % Value changed function: TransformtypeSwitch
        function TransformtypeSwitchValueChanged(app, event)
            app.TransformType = app.TransformtypeSwitch.Value;

        end

        % Value changed function: PlottypeSwitch
        function PlottypeSwitchValueChanged(app, event)
            app.PlotType = app.PlottypeSwitch.Value;
            app.IntervallsPushed = 0;     %stops them from appearing in seperate figures


            %Plotting the pow/amp if this is changed ONLY if the transform buttons have
            %been clicked


            if app.Allpressed == 1
                if ~isempty(app.WT) || ~isempty(app.WFT)

                    signal = app.data1;


                    if app.PlotType == "Amplitude"
                        %Plots
                        if app.TransformType == "WT"
                            %Amplitude WT plot

                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');

                            plot(app.UIAxes4,mean(abs(app.WT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');

                        else
                            %Amplitude WFT plot

                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number)));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');

                            plot(app.UIAxes4,mean(abs(app.WFT(:,:,app.signal_number)), 2,'omitnan' ),app.freq(:,:,app.signal_number), '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');

                        end

                    else

                        if app.TransformType == "WT"
                            %Power WT Plot

                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number).^2));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');


                            plot(app.UIAxes4,mean(abs(app.WT(:,:,app.signal_number)).^2, 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');

                        else
                            %Power WFT Plot

                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number).^2));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');

                            plot(app.UIAxes4,mean(abs(app.WFT(:,:,app.signal_number)).^2, 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');

                        end
                    end
                end


            else
                if ~isempty(app.forier) || ~isempty(app.wavelet)


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Plotting
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    if app.PlotType == "Amplitude"

                        if app.TransformType == "WT"

                            %WT amplitude plot

                            plot(app.UIAxes4,mean(abs(app.wavelet), 2,'omitnan') ,app.f, '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.wavelet));

                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');

                            set(pc,'EdgeColor','none');


                        else

                            %WFT amplitude plot

                            plot(app.UIAxes4,mean(abs(app.forier), 2,'omitnan')  ,app.f, '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.forier));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');
                            set(pc,'EdgeColor','none');

                        end

                    else   %power

                        if app.TransformType == "WT"
                            %POWER WT plot

                            fresize = size(app.f)
                            wavSize = size(mean(abs(app.wavelet).^2, 2,'omitnan'))

                            plot(app.UIAxes4,mean(abs(app.wavelet).^2, 2,'omitnan') ,app.f, '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.wavelet).^2);
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');
                            set(pc,'EdgeColor','none');


                        else

                            %POWER WFT plot
                            plot(app.UIAxes4, mean(abs(app.forier).^2, 2,'omitnan'),app.f, '-');
                            ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.forier).^2);
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                            linkaxes([app.UIAxes app.UIAxes3],'x');
                            linkaxes([app.UIAxes3 app.UIAxes4],'y');
                            set(pc,'EdgeColor','none');


                        end


                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(app.UIAxes3,cmap);
                        else
                            colormap(app.UIAxes3, 'parula');
                        end

                    end

                end

            end


        end

        % Value changed function: WindowtypeDropDown
        function WindowtypeDropDownValueChanged(app, event)
            app.WindowType = app.WindowtypeDropDown.Value;

        end

        % Menu selected function: TFrepresentationonlyMenu
        function TFrepresentationonlyMenuSelected(app, event)
            figure('Name','Time/frequency representation ', 'Position', [100, 100, 1200, 600]);

            if strcmp(app.TransformType, 'WT')

                if app.Allpressed == 1


                    if app.PlotType == "Amplitude"
                        
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)))
                        xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        ylim([app.Fmin, app.Fmax])

                        set(gca,'yscale','log')
                        title( 'Time Frequency representation')
                        shading interp; view(2)
                        colorbar
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end
                    else
                    end

                    if app.PlotType == "Power"
                        
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number).^2))
                        xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
%                         ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                        set(gca,'yscale','log')
                        title( ' Time/Frequency representation')
                        shading interp; view(2)
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        colorbar
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end

                    else
                    end

                else


                    if app.PlotType == "Amplitude"
                        
                        pcolor(app.time,app.f,abs(app.wavelet))
                        xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
%                         ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                        set(gca,'yscale','log')
                        title( 'Time Frequency representation')
                        shading interp; view(2)
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        colorbar
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end
                    else
                    end

                    if app.PlotType == "Power"
                        
                        pcolor(app.time,app.f,abs(app.wavelet.^2))
                        xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
%                         ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                        set(gca,'yscale','log')
                        title( ' Time/Frequency representation')
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        shading interp; view(2)
                        colorbar
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end

                    else
                    end
                end


            else


                if app.Allpressed == 1


                    if app.PlotType == "Amplitude"
                        
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number)))
                        xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
%                         ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                        set(gca,'yscale','log')
                        title( 'Time Frequency representation')
                        shading interp; view(2)
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        colorbar
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end
                    else
                    end

                    if app.PlotType == "Power"
                        
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number).^2))
                        xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
%                         ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                        set(gca,'yscale','log')
                        title( ' Time/Frequency representation')
                        shading interp; view(2)
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        colorbar
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end

                    else
                    end

                else


                    if app.PlotType == "Amplitude"

                        pcolor(app.time,app.f,abs(app.forier))
                        xlim(  [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
%                         ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                        set(gca,'yscale','log')
                        title( 'Time Frequency representation')
                        shading interp; view(2)
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        colorbar
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end
                    else
                    end

                    if app.PlotType == "Power"

                        pcolor(app.time,app.f,abs(app.forier.^2))
%                         xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
%                         ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                        set(gca,'yscale','log')
                        title( ' Time/Frequency representation')
                        ylabel('Frequency (Hz)')
                        xlabel('Time(s)')
                        shading interp; view(2)
                        colorbar
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(gca,cmap);
                            colorbar
                        else
                        end

                    else
                    end


                end


            end


                    if app.IntervallsPushed ==1         %Plots the intervals selected

                        yline(app.LowInt,'--k');
                        yline(app.HighInt,'--k');

%                         yline(app.LowInt,'--k');
%                         yline(app.HighInt,'--k');


                    end

        end

        % Menu selected function: TFrepresentationandtimeaverageMenu
        function TFrepresentationandtimeaverageMenuSelected(app, event)
            figure('Name','Time/frequency representation and average ', 'Position', [100, 100, 1800, 600]);


            if app.Allpressedlast == 1

                if app.TransformType == "WT"


                    tiledlayout(1,2);
                    ax1 = nexttile;
                    if app.PlotType == "Amplitude"

                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)))

                    else%Pow so

                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number).^2))

                    end

                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    %                               ylim( [app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')

                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end

                    ax2 = nexttile;

                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.WT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    else

                        plot(mean(abs(app.WT(:,:,app.signal_number).^2), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');

                else
                end


                %    [app.WFT, app.freq] = wft(app, app.data,200, 'Plot', 'pow');

                if app.TransformType == "WFT"


                    tiledlayout(1,2);
                    ax1 = nexttile;

                    if app.PlotType == "Amplitude"

                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number)))

                    else

                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number).^2))


                    end


                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    %                               ylim( [app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')
                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end

                    ax2 = nexttile;
                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.WFT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    else
                        plot(mean(abs(app.WFT(:,:,app.signal_number).^2), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');

                else
                end


            else                %The figure is from transform single

                if app.TransformType == "WT"


                    tiledlayout(1,2);
                    ax1 = nexttile;
                    if app.PlotType == "Amplitude"

                        pcolor(app.time,app.f,abs(app.wavelet))
                    else
                        pcolor(app.time,app.f,abs(app.wavelet).^2)


                    end
                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    %                               ylim( [app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')
                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end

                    ax2 = nexttile;
                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.wavelet), 2,'omitnan') ,app.f, '-');
                    else
                        plot(mean(abs(app.wavelet).^2, 2,'omitnan') ,app.f, '-');


                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');


                else
                end


                %    [app.WFT, app.freq] = wft(app, app.data,200, 'Plot', 'pow');

                if app.TransformType == "WFT"


                    tiledlayout(1,2);
                    ax1 = nexttile;
                    if app.PlotType == "Amplitude"

                        pcolor(app.time,app.f,abs(app.forier))

                    else
                        pcolor(app.time,app.f,abs(app.forier).^2)


                    end
                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    %                               ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')
                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end

                    ax2 = nexttile;

                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.forier), 2,'omitnan') ,app.f, '-');
                    else
                        plot(mean(abs(app.forier).^2, 2,'omitnan') ,app.f, '-');


                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');
                else
                end

            end

            if app.IntervallsPushed ==1         %Plots the intervals selected

                yline(ax1, app.LowInt,'--k');
                yline(ax1, app.HighInt,'--k');

                yline(ax2, app.LowInt,'--k');
                yline(ax2, app.HighInt,'--k');


            end


        end

        % Menu selected function: TimeaverageonlyMenu
        function TimeaverageonlyMenuSelected(app, event)
            figure('Name','Time average ', 'Position', [100, 100, 600, 800]);


            if app.Allpressedlast == 1

                if app.TransformType == "WT"


                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.WT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    else

                        plot(mean(abs(app.WT(:,:,app.signal_number).^2), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')

                else
                end


                %    [app.WFT, app.freq] = wft(app, app.data,200, 'Plot', 'pow');

                if app.TransformType == "WFT"


                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.WFT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    else
                        plot(mean(abs(app.WFT(:,:,app.signal_number).^2), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');

                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')

                else
                end


            else                %The figure is from transform single

                if app.TransformType == "WT"


                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.wavelet), 2,'omitnan') ,app.f, '-');
                    else
                        plot(mean(abs(app.wavelet).^2, 2,'omitnan') ,app.f, '-');


                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')


                else
                end


                %    [app.WFT, app.freq] = wft(app, app.data,200, 'Plot', 'pow');

                if app.TransformType == "WFT"


                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.forier), 2,'omitnan') ,app.f, '-');
                    else
                        plot(mean(abs(app.forier).^2, 2,'omitnan') ,app.f, '-');


                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                else
                end

            end


                    if app.IntervallsPushed ==1         %Plots the intervals selected


                         yline(app.LowInt,'--k');
                         yline(app.HighInt,'--k');


                    end

        end

        % Value changed function: FigurecolorschemeDropDown
        function FigurecolorschemeDropDownValueChanged(app, event)
            app.ColVal = app.FigurecolorschemeDropDown.Value;
            app.IntervallsPushed = 0;     %stops them from appearing in seperate figures


            %Replot if the programe has run


            if ~isempty(app.WT) || ~isempty(app.WFT) || ~isempty(app.wavelet) || ~isempty(app.forier)


                if strcmp(app.TransformType , 'WT')

                    if ~isempty(app.WT)


                        if strcmp(app.PlotType, 'Amplitude')
                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])

                            set(pc,'EdgeColor','none');

                        else
                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number).^2));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');
                        end

                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(app.UIAxes3,cmap);
                        else
                            colormap(app.UIAxes3, 'parula');
                        end


                    else


                        if strcmp(app.PlotType, 'Amplitude')

                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.wavelet));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');
                        else

                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.wavelet).^2);
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');
                        end
                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(app.UIAxes3,cmap);
                        else
                            colormap(app.UIAxes3, 'parula');
                        end
                    end

                else

                    %Same again but for WFT

                    if ~isempty(app.WFT)

                        if strcmp(app.PlotType, 'Amplitude')
                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number)));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');
                        else
                            pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number).^2));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');
                        end


                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(app.UIAxes3,cmap);
                        else
                            colormap(app.UIAxes3, 'parula');
                        end


                    else


                        if strcmp(app.PlotType, 'Amplitude')
                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.forier));
                            xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                            ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                            set(pc,'EdgeColor','none');
                        else
                            pc=pcolor(app.UIAxes3,app.time,app.f,abs(app.forier).^2);
                            set(pc,'EdgeColor','none');
                        end

                        if strcmp(app.ColVal, 'MODA style')

                            load('cmap.mat')
                            cmap;
                            colormap(app.UIAxes3,cmap);
                        else
                            colormap(app.UIAxes3, 'parula');
                        end
                    end


                end

            else
            end


        end

        % Value changed function: avgtype
        function avgtypeValueChanged(app, event)
            app.AvgType = app.avgtype.Value;

        end

        % Value changed function: testtype
        function testtypeValueChanged(app, event)
            app.TestType = app.testtype.Value;

        end

        % Button pushed function: replot
        function replotButtonPushed(app, event)
            %Here we will find the average (which one depends on the type
            %specified before) of the groups, and then we will use this
            %data to perform a rank sum test, plot them and compre the
            %average values with the area between them highlighted if it is
            %significant to the pvalue level specified.

app.StatTestOn = 1;


            app.G11 = app.EditField.Value;

            app.G12 = app.ToEditField.Value;

            app.G21 = app.EditField_2.Value;

            app.G22 = app.ToEditField_2.Value;


            if isempty(app.Apow)
                first_group = app.A(:,:,(app.G11: app.G12));
                second_group = app.A(:,:,(app.G21: app.G22));
            else
                first_group  = app.Apow(:,:,(app.G11: app.G12));
                second_group = app.Apow(:,:,(app.G21: app.G22));

            end


%%%Plot the averages depending on which was selected
            if strcmp(app.AvgType, 'Median' )
                firstAvg = median(first_group, 3);
                secondAvg = median(second_group, 3);
            end

            if strcmp(app.AvgType, 'Mean' )
                firstAvg = mean(first_group, 3);
                secondAvg = mean(second_group, 3);
            end


for l = 1:size(first_group, 3)
    FGtog(:, l) =  first_group(:,:,l);
end

for l = 1:size(second_group, 3)
    SGtog(:, l) =  second_group(:,:,l);
end


firstgr = FGtog
Secgr   = SGtog


            fr = app.freq(:,:,app.signal_number);

alp = app.P

%Running the T tests properly by looking at the differences across the
%frequency bands

for frequency = 1:size(fr)

            if strcmp(app.TestType, 'Paired' )

                [h(frequency),p(frequency)] = ttest2(first_group(frequency, :) ,second_group(frequency, :),'Vartype','unequal', 'Alpha',app.P);

            end

            if strcmp(app.TestType, 'Unpaired' )

                [h(frequency),p(frequency)] = ttest2(first_group(frequency, :),second_group(frequency, :), 'Alpha',app.P);

            end

end


pwas = p
hwas = h

F(:,1) = app.freq(:,:,app.signal_number)


            %Convert doble to numeric array
            firstAvg = reshape(firstAvg, [], 1);
            secondAvg = reshape(secondAvg, [], 1);

            firstAvg = firstAvg(:);
            secondAvg = secondAvg(:);

y0 = max(firstAvg)
y1 = max(secondAvg)

if y0>y1
    y = y0;
else
    y = y1;
end

frsize = size(fr)

            figure
            semilogx(app.freq(:,:,app.signal_number), firstAvg, 'b');
            hold on
            semilogx(app.freq(:,:,app.signal_number), secondAvg, 'r');
            xlabel( 'Frequency (Hz)')
            ylabel('Group average and time average WT (A.U.)')
            xlim([app.Fmin, app.Fmax])
            
hold on
            for frequency = 1:size(fr)-1
                if h(frequency) == 1
                    fill([F(frequency), F(frequency), F(frequency+1), F(frequency+1)], [0, y y, 0], 'black', 'FaceAlpha', 0.2, 'EdgeColor', 'none')
                end
            end
hold on
lgd = legend('Group 1','Group 2','Location', 'Best');

hold on
            if strcmp(app.AvgType, 'Mean' )
                title('Mean averaged wavelet transform for two groups');
            end

            if strcmp(app.AvgType, 'Median' )
                title('Median averaged wavelet transform for two groups');
            end

            if mean(h) > 0
                hold on

                title(lgd,"Significant at P = " + app.P +"" ,'FontSize',8)


            end


app.FirstGroup=first_group;
app.SeconsGroup=second_group;
app.PVals = p;


            %Any vars you need for the save structure later

        end

        % Value changed function: alpha
        function alphaValueChanged(app, event)
            app.P = str2double(app.alpha.Value);

        end

        % Button pushed function: TransformallButton
        function TransformallButtonPushed(app, event)
            set(app.CalculatingtransfomsLabel, 'Visible', 'on'); drawnow;

                        app.IntervallsPushed = 0;     %stops them from appearing in seperate figures

            app.Allpressedlast = 1;
            if strcmp(app.ColVal, 'MODA style')

                load('cmap.mat')
                cmap;
                colormap(app.UIAxes3,cmap);
            else
                colormap(app.UIAxes3, 'parula');
            end


            app.WT    = [];
            app.freq  = [];
            app.A     = [];
            app.Apow  = [];
            app.WFT   = [];

            app.AllEverPressed = 1;
            app.Allpressed = 1;

            signal = app.data1;


%Old one

if app.PlotType == "Amplitude"
            if app.TransformType == "WT"
                for z = 1:size(app.data1,1)


                    [app.WT(:,:,z), app.freq(:,:,z)]   = wt(app,signal(z,:),app.Fs,  'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Wavelet', app.WaveletType, 'Preprocess',  app.Preprocess, 'CutEdges', app.CE );
                    app.A(:,:,z) = mean(abs(app.WT(:,:,z)), 2,'omitnan' );
                end


            else
            end


            if app.TransformType == "WFT"
                for z = 1:size(app.data1,1)

                    [app.WFT(:,:,z), app.freq(:,:,z)]   = wft(app, signal(z,:),app.Fs, 'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Window', app.WindowType, 'Preprocess', app.Preprocess, 'CutEdges', app.CE );
                    app.A(:,:,z) = mean(abs(app.WFT(:,:,z)), 2,'omitnan' );

                end


            else
            end

else  %Power is chosen


            if app.TransformType == "WT"
                for z = 1:size(app.data1,1)


                    [app.WT(:,:,z), app.freq(:,:,z)]   = wt(app,signal(z,:),app.Fs,  'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Wavelet', app.WaveletType, 'Preprocess',  app.Preprocess, 'CutEdges', app.CE );
                    app.Apow(:,:,z) = mean(abs(app.WT(:,:,z)).^2, 2,'omitnan' );
                end


            else
            end


            if app.TransformType == "WFT"
                for z = 1:size(app.data1,1)

                    [app.WFT(:,:,z), app.freq(:,:,z)]   = wft(app, signal(z,:),app.Fs, 'f0', app.FreqRes, 'fmin', app.Fmin, 'fmax', app.Fmax, 'Window', app.WindowType, 'Preprocess', app.Preprocess, 'CutEdges', app.CE );
                    app.Apow(:,:,z) = mean(abs(app.WFT(:,:,z)).^2, 2,'omitnan' );

                end


            else
            end
end


            if app.PlotType == "Amplitude"
                %Plots
                if app.TransformType == "WT"
                    %Amplitude WT plot

                    pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)));
                    xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                    set(pc,'EdgeColor','none');

                    plot(app.UIAxes4,app.A(:,:,app.signal_number) ,app.freq(:,:,app.signal_number), '-');
                    ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                    linkaxes([app.UIAxes app.UIAxes3],'x');
                    linkaxes([app.UIAxes3 app.UIAxes4],'y');

                else
                    %Amplitude WFT plot

                    pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number)));
                    xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                    set(pc,'EdgeColor','none');

                    plot(app.UIAxes4,app.A(:,:,app.signal_number) ,app.freq(:,:,app.signal_number), '-');
                    ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                    linkaxes([app.UIAxes app.UIAxes3],'x');
                    linkaxes([app.UIAxes3 app.UIAxes4],'y');

                end

            else     %Power plots

                if app.TransformType == "WT"
                    %Power WT Plot

                    pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number).^2));
                    xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                    set(pc,'EdgeColor','none');

                    plot(app.UIAxes4,app.Apow(:,:,app.signal_number) ,app.freq(:,:,app.signal_number), '-');
                    ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                    linkaxes([app.UIAxes app.UIAxes3],'x');
                    linkaxes([app.UIAxes3 app.UIAxes4],'y');

                else
                    %Power WFT Plot

                    pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number).^2));
                    xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                    set(pc,'EdgeColor','none');

                    plot(app.UIAxes4,app.Apow(:,:,app.signal_number) ,app.freq(:,:,app.signal_number), '-');
                    ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                    linkaxes([app.UIAxes app.UIAxes3],'x');
                    linkaxes([app.UIAxes3 app.UIAxes4],'y');


                end


            end


            %Putting the average plot all optio in the Signal list
            length = size(app.data,1);


            entry = ['Average plot'];
            app.SignalListBox.Items(length + 1)  = {entry};

            set(app.CalculatingtransfomsLabel, 'Visible', 'off'); drawnow;


        end

        % Button pushed function: FouriertransformButton
        function FouriertransformButtonPushed(app, event)

            X  = app.data(app.signal_number, :) ;              %The input data

            if size(X,1) > size(X, 2)
                L  = size(X,1);
            else
                L = size(X,2);
            end


            Y = fft(X);

            %Compute double sided spectrum (P2) before computing single
            %sided based upon it
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);


            f1 = app.Fs*(0:(L/2))/L;
            [~,idxmin]=min(abs(f1-app.Fmin));
            [~,idxmax]=min(abs(f1-app.Fmax));

            f1(1, idxmin:idxmax);
            P1 = transpose(P1);

            figure
            plot(f1(1,idxmin:idxmax),P1(idxmin:idxmax,1)) ;
            title("Single-Sided Amplitude Spectrum of X(t)");
            xlabel("f (Hz)");
            ylabel("|P1(f)|")   ;

        end

        % Menu selected function: WTMenu
        function WTMenuSelected(app, event)
            WT = app.WT(:,:,app.signal_number);
            save('Wavelet transform','WT')
        end

        % Menu selected function: AllWTMenu
        function AllWTMenuSelected(app, event)
            WTs = app.WT;
            save('Wavelet transforms','WTs')
        end

        % Menu selected function: TimeSeriesMenu
        function TimeSeriesMenuSelected(app, event)
            TS = app.data(app.signal_number, :);
            save('Time Series','TS')
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)

            Params{1,1} = 'Minimum frequency';
            Params{2,1} = app.Fmin;

            Params{1,2} = 'Maximum frequency';
            Params{2,2} =  app.Fmax;

            Params{1,3} = 'Sampling frequency';
            Params{2,3} = app.Fs;

            Params{1,4} = 'Frequency resolution';
            Params{2,4} = app.FreqRes;

            Params{1,5} = 'Preprocess';
            Params{2,5} = app.Preprocess;

            Params{1,6} = 'Frequencies';
            Params{2,6} = app.freq;


            save('Parameters','Params')


        end

        % Menu selected function: TimeaveragedMenu
        function TimeaveragedMenuSelected(app, event)
            av = app.A;
            save('Time averaged','av')
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.G11 = app.EditField.Value;

        end

        % Value changed function: ToEditField
        function ToEditFieldValueChanged(app, event)
            app.G12 = app.ToEditField.Value;

        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.G21 = app.EditField_2.Value;

        end

        % Value changed function: ToEditField_2
        function ToEditField_2ValueChanged(app, event)
            app.G22 = app.ToEditField_2.Value;

        end

        % Menu selected function: NewwindowMenu
        function NewwindowMenuSelected(app, event)
            TimeFrequencyAnalysis_App1;
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)

        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end

        % Value changed function: SignalListBox
        function SignalListBoxValueChanged2(app, event)
           app.IntervallsPushed = 0;
            app.ListBoxClicked = 1;
            value = app.SignalListBox.Value;

            newStr = split(value);
            First = newStr{1,1};

            if strcmp(First, 'Signal')
                numstr1 = str2double(newStr{2,1});
                app.signal_number = numstr1;
            end


            if strcmp(First, 'Signal')
                if app.AverageplotsPanel.Visible == 'on'

                    app.AverageplotsPanel.Visible = 'off';

                end


                %Redoing the input plots


                %For the two signals upload
                if ~isempty (app.data)              %The first load in scenario

                    plot(app.UIAxes, app.time , app.data(app.signal_number, :));

                    xlim(app.UIAxes, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                    %            hold on
                    %Plot the Initial and preproscessed signals

                    app.PreprocessingDropDown

                    if strcmp(app.PreprocessingDropDown.Value, 'off')

                        plot(app.UIAxes2, app.time , app.data1(app.signal_number, :));

                    elseif strcmp(app.PreprocessingDropDown.Value, 'on')


                        plot(app.UIAxes2,app.time, app.preprocessed(app.signal_number, :), 'color', [0.9290 0.6940 0.1250])

                    end
                    xlim(app.UIAxes2, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])


                    %Allows one to evaluate multiple signals loaded in together if
                    %one requires, if not, hten also works for a single signal
                    %             app.signal_number = str2num(app.signal_number);


                    %Plot different WTs based on selected numbers
                    if app.PlotType == "Amplitude"

                        if app.AllEverPressed == 1
                            if strcmp(app.TransformType, 'WT')

                                pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)));
                                xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                                ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                                set(pc,'EdgeColor','none');

                                plot(app.UIAxes4,mean(abs(app.WT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                                ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                                set(app.UIAxes4,'YScale','log');
                            else

                                bc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number)));
                                xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                                ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                                set(bc,'EdgeColor','none');
                                plot(app.UIAxes4,mean(abs(app.WFT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                                ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                            end
                        end

                    else    %Its power

                        if app.AllEverPressed == 1
                            if strcmp(app.TransformType, 'WT')

                                pc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number).^2));
                                xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                                ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                                set(pc,'EdgeColor','none');


                                plot(app.UIAxes4,mean(abs(app.WT(:,:,app.signal_number)).^2, 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                                ylim(app.UIAxes4,[app.Fmin, app.Fmax])
                                set(app.UIAxes4,'YScale','log');
                            else

                                bc=pcolor(app.UIAxes3,app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number).^2));
                                xlim(app.UIAxes3, [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                                ylim(app.UIAxes3,[app.Fmin, app.Fmax])
                                set(bc,'EdgeColor','none');

                                plot(app.UIAxes4,mean(abs(app.WFT(:,:,app.signal_number)).^2, 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                                ylim(app.UIAxes4,[app.Fmin, app.Fmax])

                            end
                        end
                    end
                end


            elseif strcmp(First, 'Average')

                app.AverageofalltransformsMenu.Visible = 'on';


                cla(app.AvTransform);
                %                 axes(app.AvTransform); % Make averSpec the current axes.
                %                 cla reset; % Do a complete and total reset of the axes.


                if app.PlotType == "Amplitude"

                    app.AverageplotsPanel.Visible = 'on';
                    app.AvTransform.XScale = 'log';

                    meanav   = mean  (app.A(:,1,:),3);
                    medianav = median(app.A(:,1,:),3);

                    %Plotting
                    hold(app.AvTransform,'on')
                    plot(app.AvTransform, app.freq(:,:,1), meanav)
                    plot(app.AvTransform, app.freq(:,:,1), medianav)
                    hold(app.AvTransform,'off')
                    legend(app.AvTransform, 'Mean', 'Median')

                    length = size(value,2);

                    if length > 1  %We put average on and all other selected signals


                        %Plotting
                        hold(app.AvTransform,'on')
                        for i = 2:length

                            SignalNumber0 = newStr(:,:,2);
                            SignalNumber = SignalNumber0{1,i};
                            SignalNumber = str2double(SignalNumber);
                            plot(app.AvTransform, app.freq(:,:,1), app.A(:,1,SignalNumber),'DisplayName',sprintf('Signal number %d',SignalNumber))

                        end
                        hold(app.AvTransform,'off')

                        if strcmp(app.PlottypeSwitch.Value, 'Amplitude')

                            app.AvTransform.YLabel.String = 'Amplitude';

                        else

                            app.AvTransform.YLabel.String = 'Power';

                        end
                    end


                        legend(app.AvTransform)


                    elseif app.PlotType == "Power"


                        app.AverageplotsPanel.Visible = 'on';
                        app.AvTransform.XScale = 'log';

                        meanav   = mean  (app.Apow(:,1,:).^2,3);
                        medianav = median(app.Apow(:,1,:).^2,3);

                        %Plotting
                        hold(app.AvTransform,'on')
                        plot(app.AvTransform, app.freq(:,:,1), meanav)
                        plot(app.AvTransform, app.freq(:,:,1), medianav)
                        hold(app.AvTransform,'off')
                        legend(app.AvTransform, 'Mean', 'Median')

                        length = size(value,2);

                        if length > 1  %We put average on and all other selected signals


                            %Plotting
                            hold(app.AvTransform,'on')
                            for i = 2:length

                                SignalNumber0 = newStr(:,:,2);
                                SignalNumber = SignalNumber0{1,i};
                                SignalNumber = str2double(SignalNumber);
                                plot(app.AvTransform, app.freq(:,:,1), app.Apow(:,1,SignalNumber).^2,'DisplayName',sprintf('Signal number %d',SignalNumber))

                            end
                            hold(app.AvTransform,'off')

                            if strcmp(app.PlottypeSwitch.Value, 'Amplitude')

                                app.AvTransform.YLabel.String = 'Amplitude';

                            else

                                app.AvTransform.YLabel.String = 'Power';

                            end


                            legend(app.AvTransform)

                        end


                end
            end

        end

        % Menu selected function: TSTFRAvMenu
        function TSTFRAvMenuSelected(app, event)
                        figure('Name','Time series, TFR and average ', 'Position', [100, 100, 1800, 600]);
            
            pos = [1 3 4];

            if app.Allpressed == 1

                if app.TransformType == "WT"

                    tiledlayout(2,4);

                    ax0 = nexttile;

                    if strcmp(app.PreprocessingDropDown.Value, 'on')
                        plot(app.time, app.preprocessed(app.signal_number, :), 'color', [0.9290 0.6940 0.1250])
                        xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Preprocessed time series')
                        xlabel('Time (s)')
                    elseif strcmp(app.PreprocessingDropDown.Value, 'off')
                        app.prepro = 0;
                        plot(app.time, app.data(app.signal_number, :))
                        xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Input time series')
                        xlabel('Time (s)')
                    end

                    ax1 = nexttile(3);
                    if app.PlotType == "Amplitude"
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)))
                    else                                   %Pow so
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number).^2))
                    end
                    pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WT(:,:,app.signal_number)))
                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    %                               ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')

                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end
                    linkaxes([ax1 ax0],'x');
                    ax2 = nexttile(4);
                    if app.PlotType == "Amplitude"
                        plot(mean(abs(app.WT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                    else
                        plot(mean(abs(app.WT(:,:,app.signal_number).^2), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');

                else
                end

                %    [app.WFT, app.freq] = wft(app, app.data,200, 'Plot', 'pow');

                if app.TransformType == "WFT"

                    tiledlayout(2,2);

                    ax0 = nexttile(1);

                    if strcmp(app.PreprocessingDropDown.Value, 'on')
                        plot(app.time, app.preprocessed(app.signal_number, :), 'color', [0.9290 0.6940 0.1250])
                        xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Preprocessed time series')
                        xlabel('Time (s)')
                    elseif strcmp(app.PreprocessingDropDown.Value, 'off')
                        app.prepro = 0;
                        plot(app.time, app.data(app.signal_number, :))
                        xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Input time series')
                        xlabel('Time (s)')
                    end

                    ax1 = nexttile(3);
                    if app.PlotType == "Amplitude"
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number)))
                    else                                   %Pow so
                        pcolor(app.time,app.freq(:,:,app.signal_number),abs(app.WFT(:,:,app.signal_number).^2))
                    end
                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    %                               ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')
                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end
                    linkaxes([ax1 ax0],'x');

                    ax2 = nexttile(4);
                    if app.PlotType == "Amplitude"
                        plot(mean(abs(app.WFT(:,:,app.signal_number)), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                    else
                        plot(mean(abs(app.WFT(:,:,app.signal_number).^2), 2,'omitnan' ) ,app.freq(:,:,app.signal_number), '-');
                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');
                else
                end


            else                %The figure is from transform single

                if app.TransformType == "WT"

                    tiledlayout(2,2);

                    ax0 = nexttile(1);

                    if strcmp(app.PreprocessingDropDown.Value, 'on')
                        plot(app.time, app.preprocessed(app.signal_number, :), 'color', [0.9290 0.6940 0.1250])
                        xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Preprocessed time series')
                        xlabel('Time (s)')
                    elseif strcmp(app.PreprocessingDropDown.Value, 'off')
                        app.prepro = 0;
                        plot(app.time, app.data(app.signal_number, :))
                        xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Input time series')
                        xlabel('Time (s)')
                    end


                    ax1 = nexttile(3);

                    if app.PlotType == "Amplitude"
                        pcolor(app.time,app.f,abs(app.wavelet))
                    else
                        pcolor(app.time,app.f,abs(app.wavelet).^2)
                    end

                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    %                               ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')
                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end
                    linkaxes([ax1 ax0],'x');

                    ax2 = nexttile(4);

                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.wavelet), 2,'omitnan') ,app.f, '-');
                    else
                        plot(mean(abs(app.wavelet).^2, 2,'omitnan') ,app.f, '-');


                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');


                else
                end


                %    [app.WFT, app.freq] = wft(app, app.data,200, 'Plot', 'pow');

                if app.TransformType == "WFT"

                    tiledlayout(2,2);

                    ax0 = nexttile(1);


                    if strcmp(app.PreprocessingDropDown.Value, 'on')
                        plot(app.time, app.preprocessed(app.signal_number, :), 'color', [0.9290 0.6940 0.1250])
                        xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Preprocessed time series')
                        xlabel('Time (s)')
                    elseif strcmp(app.PreprocessingDropDown.Value, 'off')
                        app.prepro = 0;
                        plot(app.time, app.data(app.signal_number, :))
                        xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                        title('Input time series')
                        xlabel('Time (s)')
                    end


                    ax1 = nexttile(3);
                    if app.PlotType == "Amplitude"
                        pcolor(app.time,app.f,abs(app.forier))
                    else
                        pcolor(app.time,app.f,abs(app.forier).^2)
                    end                 
                    
                    xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                    ylim([app.Fmin, app.Fmax])
                    set(gca,'yscale','log')
                    title( 'Time Frequency representation and time average')
                    ylabel('Frequency (Hz)')
                    xlabel('Time(s)')
                    shading interp; view(2)
                    colorbar
                    if strcmp(app.ColVal, 'MODA style')
                        load('cmap.mat')
                        cmap;
                        colormap(gca,cmap);
                        colorbar
                    else
                    end

                    linkaxes([ax1 ax0],'x');

                    ax2 = nexttile(4);
                    if app.PlotType == "Amplitude"

                        plot(mean(abs(app.forier), 2,'omitnan') ,app.f, '-');
                    else
                        plot(mean(abs(app.forier).^2, 2,'omitnan') ,app.f, '-');


                    end
                    ylabel('Frequency (Hz)')
                    xlabel('Pow/amp')
                    set(gca,'yscale','log')
                    linkaxes([ax1 ax2],'y');
                else
                end
            end


                    if app.IntervallsPushed ==1         %Plots the intervals selected

                        yline(ax1, app.LowInt,'--k');
                        yline(ax1, app.HighInt,'--k');

                         yline(ax2, app.LowInt,'--k');
                         yline(ax2, app.HighInt,'--k');
                    end
        end

        % Menu selected function: TimeseriesMenu
        function TimeseriesMenuSelected(app, event)
            figure('Name','Time series ', 'Position', [100, 100, 1200, 400]);

            if strcmp(app.PreprocessingDropDown.Value, 'on')
                plot(app.time, app.preprocessed(app.signal_number, :), 'color', [0.9290 0.6940 0.1250])
                xlim( [0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                title('Preprocessed time series')
                xlabel('Time (s)')
            elseif strcmp(app.PreprocessingDropDown.Value, 'off')
                app.prepro = 0;
                plot(app.time, app.data(app.signal_number, :))
                xlim([0, ((1/app.Fs )*size(app.data(app.signal_number, :),2)-(1/app.Fs ))])
                title('Input time series')
                xlabel('Time (s)')
            end
        end

        % Value changed function: IntervalsEditField
        function IntervalsEditFieldValueChanged(app, event)
           app.LowInt = app.IntervalsEditField.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.HighInt = app.EditField_3.Value;
            
        end

        % Button pushed function: SelectintervalsButton
        function SelectintervalsButtonPushed(app, event)
        
            app.IntervallsPushed = 1;
            
            
            app.H1 = yline(app.UIAxes3,app.LowInt,'--k');
            app.H2 = yline(app.UIAxes3,app.HighInt,'--k');

            app.H3 = yline(app.UIAxes4,app.LowInt,'--k');
            app.H4 = yline(app.UIAxes4,app.HighInt,'--k');


if app.ListBoxClicked == 1

            value = app.SignalListBox.Value;

            newStr = split(value);
            First = newStr{1,1};


            if strcmp(First, 'Average')

                if app.lines == 1
                    delete(app.line1);
                    delete(app.line2);
                end
                app.lines = 1;

                app.line1 = xline(app.AvTransform,app.LowInt,'--k','HandleVisibility','off');
                app.line2 = xline(app.AvTransform,app.HighInt,'--k','HandleVisibility','off');

            end

end
        end

        % Menu selected function: AverageofalltransformsMenu
        function AverageofalltransformsMenuSelected(app, event)
            
           figure('Name','Transform average', 'Position', [100, 100, 1200, 600]);

            
            value = app.SignalListBox.Value;

            newStr = split(value);
            First = newStr{1,1};

            if strcmp(First, 'Signal')
                numstr1 = str2double(newStr{2,1});
                app.signal_number = numstr1;
            end


            if strcmp(First, 'Signal')


            elseif strcmp(First, 'Average')

                app.AverageofalltransformsMenu.Visible = 'on';

                if app.PlotType == "Amplitude"
                    hold('on')

                    meanav   = mean  (app.A(:,1,:),3);
                    medianav = median(app.A(:,1,:),3);

                    %Plotting
                    
                    plot(app.freq(:,:,1), meanav)
                    plot( app.freq(:,:,1), medianav)
                    
                    legend( 'Mean', 'Median')

                    length = size(value,2);

                    if length > 1  %We put average on and all other selected signals


                        %Plotting
                        
                        for i = 2:length

                            SignalNumber0 = newStr(:,:,2);
                            SignalNumber = SignalNumber0{1,i};
                            SignalNumber = str2double(SignalNumber);
                            plot(app.freq(:,:,1), app.A(:,1,SignalNumber),'DisplayName',sprintf('Signal number %d',SignalNumber))

                        end

                        if strcmp(app.PlottypeSwitch.Value, 'Amplitude')

                            ylabel('Amplitude')

                        else

                            ylabel('Power')

                        end
                        hold('off')
                        set(gca, 'XScale', 'log')

                    end


                    elseif app.PlotType == "Power"
                        hold('on')


                        app.AverageplotsPanel.Visible = 'on';
                        app.AvTransform.XScale = 'log';

                        meanav   = mean  (app.Apow(:,1,:).^2,3);
                        medianav = median(app.Apow(:,1,:).^2,3);

                        %Plotting
                        
                        plot( app.freq(:,:,1), meanav)
                        plot( app.freq(:,:,1), medianav)
                        
                        legend('Mean', 'Median')

                        length = size(value,2);

                        if length > 1  %We put average on and all other selected signals


                            %Plotting
                            hold(app.AvTransform,'on')
                            for i = 2:length

                                SignalNumber0 = newStr(:,:,2);
                                SignalNumber = SignalNumber0{1,i};
                                SignalNumber = str2double(SignalNumber);
                                plot( app.freq(:,:,1), app.Apow(:,1,SignalNumber).^2,'DisplayName',sprintf('Signal number %d',SignalNumber))

                            end

                            if strcmp(app.PlottypeSwitch.Value, 'Amplitude')
                                ylabel('Amplitude')

                            else

                                ylabel('Power')

                            end
                        end
                        hold('off')
                        set(gca, 'XScale', 'log')


                end
                xlabel('Frequency (Hz)')
                title('Transform average for all signals')


            end
        

            if strcmp(First, 'Average')

                if app.lines == 1
                    delete(app.line1);
                    delete(app.line2);
                end
                app.lines = 1;

                app.line1 = xline(app.LowInt,'--k','HandleVisibility','off');
                app.line2 = xline(app.HighInt,'--k','HandleVisibility','off');

            end
        end

        % Callback function
        function testMenuSelected(app, event)

            axesHandle = getappdata(app.AvTransform, 'axesHandle');
            data = get(axesHandle, 'Data')

            figure;
            plot(data);


        end

        % Menu selected function: SaveallmatMenu
        function SaveallmatMenuSelected(app, event)
            %Sae all the parameters to a structure

if ~isempty(app.preprocessed)
    TFR_data.Preprocessed = app.preprocessed;
end

TFR_data.Analysis_Type = app.TransformtypeSwitch.Value
 
if app.TransformtypeSwitch.Value == 'WT'
    TFR_data.Wavelet_Type = app.WavelettypeDropDown.Value
else
    TFR_data.Wavelet_Type = app.WindowtypeDropDown.Value
end

if ~isempty(app.WT)
    TFR_data.WaveletTransforms = app.WT;
end

if ~isempty(app.wavelet)
    TFR_data.WaveletTransform = app.wavelet;
end

if ~isempty(app.WFT)
    TFR_data.WFTransforms = app.WFT;
end


if ~isempty(app.forier)
    TFR_data.WFTransform = app.forier;
end


if ~isempty(app.A)
    TFR_data.Amplitude = app.A;
end

if ~isempty(app.Apow)
    TFR_data.Power = app.Apow;
end


if app.Allpressedlast == 1;
    TFR_data.Frequency = app.freq;

else
    TFR_data.Frequency = app.f
end


TFR_data.Sampling_frequency = app.Fs;

TFR_data.Fmax = app.Fmax;
TFR_data.Fmin = app.Fmin;
TFR_data.Freq_res = app.FreqRes;
TFR_data.Preprocessing = app.PreprocessingDropDown.Value;
TFR_data.Cut_Edges = app.CutedgesDropDown.Value;


if app.StatTestOn == 1
    TFR_data.Group_One = [app.G11, app.G12];
    TFR_data.Group_Two = [app.G21, app.G22];
    TFR_data.FirstGroup = app.FirstGroup;
    TFR_data.SecondGroup = app.SeconsGroup;
    TFR_data.PValues = app.PVals;
    TFR_data.testtype = app.testtype.Value;
    TFR_data.avgtype = app.avgtype.Value;


end


[file,path,indx] = uiputfile('*.mat','TFR_data')

BigPath = append(path, file)

save(BigPath,'TFR_data')


        end

        % Button pushed function: DeleteintervalsButton
        function DeleteintervalsButtonPushed(app, event)
                    
            %Trying to remove the interval lines
            

            app.IntervallsPushed = 0;     %stops them from appearing in seperate figures

delete(app.H1);
delete(app.H2);
delete(app.H3);
delete(app.H4);


           delete(app.line1);
           delete(app.line2);
        end

        % Button pushed function: TotalRelativepowerButton
        function TotalRelativepowerButtonPushed(app, event)
            %Here we will calculate the total power present by looking at
            %the area under the entire curve, and if an interval has been
            %selected, then the relative power in that given interval will
            %also be output.


            if app.Allpressedlast == 0           %Then trans single was pressed
                if app.TransformType == "WT"
                    wav = abs(app.wavelet);
                else        %It's fourier
                    wav = abs(app.forier);
                end
                frequency = app.f
            else                                                 %Then trans all was pressed
                if app.TransformType == "WT"
                    wav = abs(app.WT(:,:,app.signal_number));
                else   %ITs WFT
                    wav = abs(app.WFT(:,:,app.signal_number));
                end
                frequency = app.freq(:,:,app.signal_number)             %Sets the frequency for there
            end


scales = flip(1./frequency);
% scales = 1./app.f


wav = flip(wav);              %As phils scaling procedure below was based upon his interpretation of the wt


        for n=2:length(scales)

            W_amp = abs(wav(n,:));
            W_power(n,:) = (W_amp.^2)*(1./scales(n-1)-1./(scales(n)));

        end


        %Calculating the time

        TimeInt = size(app.data, 2)/app.Fs;
        t = 0:1/app.Fs:TimeInt-1/app.Fs;


        W_power(isnan(W_power)) = 0;                                        %Removes nans and replaces them with zeros


        W_power(isnan(W_power)) = 0;                                        %Removes nans and replaces them with zeros


        total_powerr = cumtrapz(t, cumtrapz( W_power ,1), 2) ;         %Area under the rescaled surface as a matrix adding up areas


        TotalPower = total_powerr(end)/TimeInt ;


            if app.IntervallsPushed == 1


                %First find the corresponding index for the two
                %intervals in terms of our freq vects
                [~, index1 ] = min(abs(scales - 1/app.LowInt));
                [~, index2 ] = min(abs(scales - 1/app.HighInt));

%         index1 = size(scales, 1) - index1
%         index2 = size(scales, 1) - index2

                if index1 > index2      %Redundancy incase they are enterd in the wrong order by the user
                    indexhigh = index1;
                    indexlow  = index2;
                else
                    indexhigh = index2;
                    indexlow  = index1;

                end

                %Inverting again because we are in scales rather than freq


                band_powerr = cumtrapz( t, cumtrapz( W_power(indexlow:indexhigh,:), 1 ),2  );           %Area under the rescaled surface as a matrix adding up areas

                band_power = band_powerr(end)/TimeInt ;                           %And we normalise by the total time over which we calculated, as is done in the initial paper


% %                  bandpow   = trapz( app.f(indexlow:indexhigh, 1), A(indexlow:indexhigh, 1) )


% 
                 relpow    = band_power / TotalPower
% 
                 relpowStr = num2str(relpow *100)

                txt = "The total power is " + num2str(TotalPower) + ". The percentage within the intervals is " + relpowStr + "%"

            else
                txt = "The total power is " + num2str(TotalPower) + "."
            end

message = msgbox(txt);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataentryMenu = uimenu(app.UIFigure);
            app.DataentryMenu.Text = 'Data entry';
            app.LoadtimeseriesMenu = uimenu(app.DataentryMenu);
            app.LoadtimeseriesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadtimeseriesMenuSelected, true);
            app.LoadtimeseriesMenu.Text = 'Load time series';
            app.NewwindowMenu = uimenu(app.DataentryMenu);
            app.NewwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @NewwindowMenuSelected, true);
            app.NewwindowMenu.Text = 'New window';
            app.PlottingMenu = uimenu(app.UIFigure);
            app.PlottingMenu.Text = 'Plotting';
            app.TimeseriesMenu = uimenu(app.PlottingMenu);
            app.TimeseriesMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeseriesMenuSelected, true);
            app.TimeseriesMenu.Text = 'Time series';
            app.TFrepresentationonlyMenu = uimenu(app.PlottingMenu);
            app.TFrepresentationonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @TFrepresentationonlyMenuSelected, true);
            app.TFrepresentationonlyMenu.Text = 'TF representation only';
            app.TimeaverageonlyMenu = uimenu(app.PlottingMenu);
            app.TimeaverageonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeaverageonlyMenuSelected, true);
            app.TimeaverageonlyMenu.Text = 'Time average only';
            app.TFrepresentationandtimeaverageMenu = uimenu(app.PlottingMenu);
            app.TFrepresentationandtimeaverageMenu.MenuSelectedFcn = createCallbackFcn(app, @TFrepresentationandtimeaverageMenuSelected, true);
            app.TFrepresentationandtimeaverageMenu.Text = 'TF representation and time average ';
            app.TSTFRAvMenu = uimenu(app.PlottingMenu);
            app.TSTFRAvMenu.MenuSelectedFcn = createCallbackFcn(app, @TSTFRAvMenuSelected, true);
            app.TSTFRAvMenu.Text = 'TS + TFR + Av';
            app.AverageofalltransformsMenu = uimenu(app.PlottingMenu);
            app.AverageofalltransformsMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageofalltransformsMenuSelected, true);
            app.AverageofalltransformsMenu.Visible = 'off';
            app.AverageofalltransformsMenu.Text = 'Average of all transforms';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.Text = 'Save';
            app.WTMenu = uimenu(app.SaveMenu);
            app.WTMenu.MenuSelectedFcn = createCallbackFcn(app, @WTMenuSelected, true);
            app.WTMenu.Text = 'WT';
            app.AllWTMenu = uimenu(app.SaveMenu);
            app.AllWTMenu.MenuSelectedFcn = createCallbackFcn(app, @AllWTMenuSelected, true);
            app.AllWTMenu.Text = 'All WT';
            app.TimeSeriesMenu = uimenu(app.SaveMenu);
            app.TimeSeriesMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeSeriesMenuSelected, true);
            app.TimeSeriesMenu.Text = 'Time Series';
            app.ParametersMenu = uimenu(app.SaveMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.TimeaveragedMenu = uimenu(app.SaveMenu);
            app.TimeaveragedMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeaveragedMenuSelected, true);
            app.TimeaveragedMenu.Text = 'Time averaged';
            app.SaveallmatMenu = uimenu(app.SaveMenu);
            app.SaveallmatMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveallmatMenuSelected, true);
            app.SaveallmatMenu.Text = 'Save all (.mat)';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Time/frequency representation')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Frequency (Hz)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YScale = 'log';
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imported time series')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude (A.U.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Time averaged')
            xlabel(app.UIAxes4, 'pow/amp')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.YAxisLocation = 'right';
            app.UIAxes4.YScale = 'log';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Input time series')
            xlabel(app.UIAxes2, 'Time (s)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTickLabel = '';
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.FigurecolorschemeDropDownLabel = uilabel(app.UIFigure);
            app.FigurecolorschemeDropDownLabel.Text = 'Figure color scheme';
            app.FigurecolorschemeDropDown = uidropdown(app.UIFigure);
            app.FigurecolorschemeDropDown.Items = {'Matlab default', 'MODA style'};
            app.FigurecolorschemeDropDown.ValueChangedFcn = createCallbackFcn(app, @FigurecolorschemeDropDownValueChanged, true);
            app.FigurecolorschemeDropDown.Value = 'MODA style';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'frontbanner1.png';
            app.text55 = uilabel(app.UIFigure);
            app.text55.Tag = 'text55';
            app.text55.Text = '';
            app.SignalListBoxLabel = uilabel(app.UIFigure);
            app.SignalListBoxLabel.Text = {'Signal'; ''};
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.Multiselect = 'on';
            app.SignalListBox.ValueChangedFcn = createCallbackFcn(app, @SignalListBoxValueChanged2, true);
            app.SignalListBox.Value = {};
            app.SignalLabel = uilabel(app.UIFigure);
            app.SignalLabel.Text = 'Signal';
            app.StatisticaltestPanel = uipanel(app.UIFigure);
            app.StatisticaltestPanel.Title = 'Statistical test';
            app.text57 = uilabel(app.StatisticaltestPanel);
            app.text57.Tag = 'text57';
            app.text57.Text = 'Avg';
            app.avgtype = uidropdown(app.StatisticaltestPanel);
            app.avgtype.Items = {'Median', 'Mean'};
            app.avgtype.ValueChangedFcn = createCallbackFcn(app, @avgtypeValueChanged, true);
            app.avgtype.Tag = 'avgtype';
            app.avgtype.Value = 'Median';
            app.replot = uibutton(app.StatisticaltestPanel, 'push');
            app.replot.ButtonPushedFcn = createCallbackFcn(app, @replotButtonPushed, true);
            app.replot.Tag = 'replot';
            app.replot.Text = 'Calculate';
            app.testtype = uidropdown(app.StatisticaltestPanel);
            app.testtype.Items = {'Unpaired', 'Paired', ''};
            app.testtype.ValueChangedFcn = createCallbackFcn(app, @testtypeValueChanged, true);
            app.testtype.Tag = 'testtype';
            app.testtype.Value = 'Unpaired';
            app.text56 = uilabel(app.StatisticaltestPanel);
            app.text56.Tag = 'text56';
            app.text56.Text = 'P-value';
            app.alpha = uieditfield(app.StatisticaltestPanel, 'text');
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @alphaValueChanged, true);
            app.alpha.Tag = 'alpha';
            app.alpha.Value = '0.05';
            app.EditField = uieditfield(app.StatisticaltestPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.StatisticaltestPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 5;
            app.text53 = uilabel(app.StatisticaltestPanel);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Group 1 from';
            app.ToEditFieldLabel = uilabel(app.StatisticaltestPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.StatisticaltestPanel, 'numeric');
            app.ToEditField.ValueChangedFcn = createCallbackFcn(app, @ToEditFieldValueChanged, true);
            app.ToEditField.Value = 2;
            app.ToEditField_2Label = uilabel(app.StatisticaltestPanel);
            app.ToEditField_2Label.Text = 'To';
            app.ToEditField_2 = uieditfield(app.StatisticaltestPanel, 'numeric');
            app.ToEditField_2.ValueChangedFcn = createCallbackFcn(app, @ToEditField_2ValueChanged, true);
            app.ToEditField_2.Value = 6;
            app.text54 = uilabel(app.StatisticaltestPanel);
            app.text54.Tag = 'text54';
            app.text54.Text = 'Group 2 from';
            app.FouriertransformButton = uibutton(app.StatisticaltestPanel, 'push');
            app.FouriertransformButton.ButtonPushedFcn = createCallbackFcn(app, @FouriertransformButtonPushed, true);
            app.FouriertransformButton.Text = 'Fourier transform';
            app.WavelettransformparametersPanel = uipanel(app.UIFigure);
            app.WavelettransformparametersPanel.Title = 'Wavelet transform parameters';
            app.FrequencyresolutionEditFieldLabel = uilabel(app.WavelettransformparametersPanel);
            app.FrequencyresolutionEditFieldLabel.Text = 'Frequency resolution';
            app.FrequencyresolutionEditField = uieditfield(app.WavelettransformparametersPanel, 'numeric');
            app.FrequencyresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyresolutionEditFieldValueChanged, true);
            app.FrequencyresolutionEditField.Value = 1;
            app.MaxfrequencyEditFieldLabel = uilabel(app.WavelettransformparametersPanel);
            app.MaxfrequencyEditFieldLabel.Text = 'Max frequency';
            app.MaxfrequencyEditField = uieditfield(app.WavelettransformparametersPanel, 'numeric');
            app.MaxfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MaxfrequencyEditFieldValueChanged, true);
            app.MaxfrequencyEditField.Value = 4;
            app.MinfrequencyEditFieldLabel = uilabel(app.WavelettransformparametersPanel);
            app.MinfrequencyEditFieldLabel.Text = 'Min frequency';
            app.MinfrequencyEditField = uieditfield(app.WavelettransformparametersPanel, 'numeric');
            app.MinfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MinfrequencyEditFieldValueChanged, true);
            app.MinfrequencyEditField.Value = 0.1;
            app.PlottypeSwitchLabel = uilabel(app.WavelettransformparametersPanel);
            app.PlottypeSwitchLabel.Text = 'Plot type';
            app.PlottypeSwitch = uiswitch(app.WavelettransformparametersPanel, 'slider');
            app.PlottypeSwitch.Items = {'Amplitude', 'Power'};
            app.PlottypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlottypeSwitchValueChanged, true);
            app.PlottypeSwitch.Value = 'Amplitude';
            app.TransformtypeSwitchLabel = uilabel(app.WavelettransformparametersPanel);
            app.TransformtypeSwitchLabel.Text = 'Transform type';
            app.TransformtypeSwitch = uiswitch(app.WavelettransformparametersPanel, 'slider');
            app.TransformtypeSwitch.Items = {'WT', 'WFT'};
            app.TransformtypeSwitch.ValueChangedFcn = createCallbackFcn(app, @TransformtypeSwitchValueChanged, true);
            app.TransformtypeSwitch.Value = 'WT';
            app.TransformsingleButton = uibutton(app.WavelettransformparametersPanel, 'state');
            app.TransformsingleButton.ValueChangedFcn = createCallbackFcn(app, @TransformsingleButtonValueChanged, true);
            app.TransformsingleButton.Text = 'Transform single';
            app.TransformallButton = uibutton(app.WavelettransformparametersPanel, 'push');
            app.TransformallButton.ButtonPushedFcn = createCallbackFcn(app, @TransformallButtonPushed, true);
            app.TransformallButton.Text = 'Transform all';
            app.CutedgesDropDownLabel = uilabel(app.WavelettransformparametersPanel);
            app.CutedgesDropDownLabel.Text = 'Cut edges?';
            app.CutedgesDropDown = uidropdown(app.WavelettransformparametersPanel);
            app.CutedgesDropDown.Items = {'on', 'off'};
            app.CutedgesDropDown.ValueChangedFcn = createCallbackFcn(app, @CutedgesDropDownValueChanged, true);
            app.CutedgesDropDown.Value = 'on';
            app.WindowtypeDropDownLabel = uilabel(app.WavelettransformparametersPanel);
            app.WindowtypeDropDownLabel.Text = 'Window type';
            app.WindowtypeDropDown = uidropdown(app.WavelettransformparametersPanel);
            app.WindowtypeDropDown.Items = {'Gaussian', 'Hann', 'Blackman', 'Exp', 'Rect', 'Kaiser-a'};
            app.WindowtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowtypeDropDownValueChanged, true);
            app.WindowtypeDropDown.Value = 'Gaussian';
            app.WavelettypeDropDownLabel = uilabel(app.WavelettransformparametersPanel);
            app.WavelettypeDropDownLabel.Text = 'Wavelet type';
            app.WavelettypeDropDown = uidropdown(app.WavelettransformparametersPanel);
            app.WavelettypeDropDown.Items = {'Lognorm', 'Morlet', 'Bump', ''};
            app.WavelettypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WavelettypeDropDownValueChanged, true);
            app.WavelettypeDropDown.Value = 'Lognorm';
            app.PreprocessingDropDownLabel = uilabel(app.WavelettransformparametersPanel);
            app.PreprocessingDropDownLabel.Text = 'Preprocessing';
            app.PreprocessingDropDown = uidropdown(app.WavelettransformparametersPanel);
            app.PreprocessingDropDown.Items = {'on', 'off', ''};
            app.PreprocessingDropDown.ValueChangedFcn = createCallbackFcn(app, @PreprocessingDropDownValueChanged, true);
            app.PreprocessingDropDown.Value = 'off';
            app.CalculatingtransfomsLabel = uilabel(app.WavelettransformparametersPanel);
            app.CalculatingtransfomsLabel.Visible = 'off';
            app.CalculatingtransfomsLabel.Text = {'Calculating'; 'transfoms...'};
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://github.com/luphysics/MODA/blob/main/docs/user-guide.md';
            app.Hyperlink.Text = 'Guide';
            app.IntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalsEditFieldLabel.Text = 'Intervals';
            app.IntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalsEditFieldValueChanged, true);
            app.SelectintervalsButton = uibutton(app.UIFigure, 'push');
            app.SelectintervalsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectintervalsButtonPushed, true);
            app.SelectintervalsButton.Text = 'Select intervals';
            app.AverageplotsPanel = uipanel(app.UIFigure);
            app.AverageplotsPanel.Title = 'Average plots';
            app.AverageplotsPanel.Visible = 'off';
            app.AvTransform = uiaxes(app.AverageplotsPanel);
            title(app.AvTransform, 'Transform average for all signals')
            xlabel(app.AvTransform, 'Frequency (Hz)')
            zlabel(app.AvTransform, 'Z')
            app.AvTransform.XScale = 'log';
            app.DeleteintervalsButton = uibutton(app.UIFigure, 'push');
            app.DeleteintervalsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteintervalsButtonPushed, true);
            app.DeleteintervalsButton.Text = 'Delete intervals';
            app.TotalRelativepowerButton = uibutton(app.UIFigure, 'push');
            app.TotalRelativepowerButton.ButtonPushedFcn = createCallbackFcn(app, @TotalRelativepowerButtonPushed, true);
            app.TotalRelativepowerButton.Text = 'Total/Relative power';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimeFrequencyAnalysis_App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end